classdef AppTest < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        DiTAAToolboxv10UIFigure        matlab.ui.Figure
        FileMenu                       matlab.ui.container.Menu
        OpendesignMenu                 matlab.ui.container.Menu
        SavedesignMenu                 matlab.ui.container.Menu
        CloseMenu                      matlab.ui.container.Menu
        HelpMenu                       matlab.ui.container.Menu
        GettingstartedMenu             matlab.ui.container.Menu
        RecommendedconfigurationsMenu  matlab.ui.container.Menu
        Pestana                        matlab.ui.container.TabGroup
        Setup                          matlab.ui.container.Tab
        StimExtra                      matlab.ui.control.EditField
        ExtrastimulifornumequalizationLabel  matlab.ui.control.Label
        Lamp                           matlab.ui.control.Lamp
        Browse                         matlab.ui.control.Button
        PerformButton                  matlab.ui.control.Button
        ProtocolspecificsPanel         matlab.ui.container.Panel
        sequencesSwitch                matlab.ui.control.Switch
        sequencesSwitchLabel           matlab.ui.control.Label
        Family                         matlab.ui.control.Switch
        ProtocolfamilyLabel            matlab.ui.control.Label
        TotalstimuliLabel              matlab.ui.control.Label
        Balanceado                     matlab.ui.control.Switch
        BalancingLabel                 matlab.ui.control.Label
        StimTot                        matlab.ui.control.EditField
        Vueltasposibles                matlab.ui.control.EditField
        ReplicationsLabel              matlab.ui.control.Label
        Estimulosvuelta                matlab.ui.control.EditField
        StimulireplicationLabel        matlab.ui.control.Label
        Combposibles                   matlab.ui.control.EditField
        CombinationsLabel              matlab.ui.control.Label
        Estimulostest                  matlab.ui.control.EditField
        StimulitestLabel               matlab.ui.control.Label
        Tree                           matlab.ui.container.Tree
        Nodo1                          matlab.ui.container.TreeNode
        Pregunta                       matlab.ui.control.EditField
        TestquestionLabel              matlab.ui.control.Label
        TestprotocolListBox            matlab.ui.control.ListBox
        TestprotocolListBoxLabel       matlab.ui.control.Label
        Doble                          matlab.ui.container.Tab
        NextButton2                    matlab.ui.control.Button
        AnswerButtonGroup              matlab.ui.container.ButtonGroup
        Button3                        matlab.ui.control.ToggleButton
        B2_B                           matlab.ui.control.ToggleButton
        B2_A                           matlab.ui.control.ToggleButton
        PlaybackPanel                  matlab.ui.container.Panel
        A2                             matlab.ui.control.Button
        B2                             matlab.ui.control.Button
        Title2                         matlab.ui.control.Label
        Triple                         matlab.ui.container.Tab
        NextButton3                    matlab.ui.control.Button
        Title3                         matlab.ui.control.Label
        AnswerButtonGroup_4            matlab.ui.container.ButtonGroup
        Button4                        matlab.ui.control.ToggleButton
        B3_C                           matlab.ui.control.ToggleButton
        B3_B                           matlab.ui.control.ToggleButton
        B3_A                           matlab.ui.control.ToggleButton
        PlaybackPanel_4                matlab.ui.container.Panel
        C3                             matlab.ui.control.Button
        A3                             matlab.ui.control.Button
        B3                             matlab.ui.control.Button
        Quad                           matlab.ui.container.Tab
        NextButton4                    matlab.ui.control.Button
        Title4                         matlab.ui.control.Label
        AnswerButtonGroup_3            matlab.ui.container.ButtonGroup
        Button3_2                      matlab.ui.control.ToggleButton
        B4_B                           matlab.ui.control.ToggleButton
        B4_A                           matlab.ui.control.ToggleButton
        PlaybackPanel_3                matlab.ui.container.Panel
        D4                             matlab.ui.control.Button
        B4                             matlab.ui.control.Button
        A4                             matlab.ui.control.Button
        C4                             matlab.ui.control.Button
    end


    properties (Access = public)
        vueltas % Description
        comb_TRIANG
        comb_ABX
        comb_DTF
        comb_DTFM
        comb_ANOTAR
        comb_SAMEDIFF
        comb_CRSAMEDIFF
        comb_2AC
        comb_random
        comb_random_rep
        contador
        temp_start;
        temp_tot;
        stimuli;
        fs;
        Subnodes;
        Answer;
        stim_names;
        test;
        num_wavs;
    end


    methods (Access = public)

        function [] = configurador(app)
            %indice = find(contains(app.TestprotocolListBox.Items,app.TestprotocolListBox.Value))
            app.Answer={};
            app.comb_TRIANG=combinations(app,1);
            app.comb_ABX=combinations(app,2);
            app.comb_DTF=combinations(app,3);
            app.comb_DTFM=combinations(app,4);
            app.comb_SAMEDIFF=combinations(app,6);
            app.comb_ANOTAR=combinations(app,5);
            app.comb_2AC=combinations(app,8);
            [~, indice]=find(strcmp(app.TestprotocolListBox.Items, app.TestprotocolListBox.Value));
            switch indice
                case 1
                    app.test='Triang';
                    app.Pregunta.Value="Which of the three sounds is different?";
                    app.Estimulostest.Value='3';
                    app.Balanceado.Value='Balanced';
                    app.Family.Value='Overall';
                    app.Title3.Text=app.Pregunta.Value;
                    app.A3.Text='A';
                    app.B3.Text='B';
                    app.C3.Text='C';
                    app.B3_A.Text='A';
                    app.B3_C.Text='B';
                    app.B3_B.Text='C';
                    if(isempty(app.comb_TRIANG))
                        app.Combposibles.Value=num2str([]);
                    else
                        app.Combposibles.Value=num2str(size(app.comb_TRIANG,1));
                    end
                    if(isempty(app.StimTot.Value) | isnan(app.StimTot.Value))
                        app.StimTot.Value=num2str(str2double(app.Combposibles.Value)*str2double((app.Estimulostest.Value)));
                    end
                    app.Vueltasposibles.Value=num2str(floor(str2double(app.StimTot.Value)/(str2double(app.Estimulostest.Value)*str2double(app.Combposibles.Value))));
                    app.Estimulosvuelta.Value=num2str(str2double(app.Estimulostest.Value)*str2double(app.Combposibles.Value));
                    app.StimExtra.Value=num2str(str2double(app.StimTot.Value)-(str2double(app.Estimulosvuelta.Value)*str2double(app.Vueltasposibles.Value)));
                case 2
                    app.test='ABX';
                    app.Pregunta.Value="It is sound X the same as sound A or sound B?";
                    app.Estimulostest.Value='3';
                    app.Balanceado.Value='Balanced';
                    app.Family.Value='Overall';
                    app.Title3.Text=app.Pregunta.Value;
                    app.A3.Text='A';
                    app.B3.Text='B';
                    app.C3.Text='X';
                    app.B3_A.Text='A';
                    app.B3_B.Text='B';
                    app.B3_C.Visible='off';
                    if(isempty(app.comb_ABX))
                        app.Combposibles.Value=num2str([]);
                    else
                        app.Combposibles.Value=num2str(size(app.comb_ABX,1));
                    end
                    if(isempty(app.StimTot.Value) | isnan(app.StimTot.Value))
                        app.StimTot.Value=num2str(str2double(app.Combposibles.Value)*str2double((app.Estimulostest.Value)));
                    end
                    app.Vueltasposibles.Value=num2str(floor(str2double(app.StimTot.Value)/(str2double(app.Estimulostest.Value)*str2double(app.Combposibles.Value))));
                    app.Estimulosvuelta.Value=num2str(str2double(app.Estimulostest.Value)*str2double(app.Combposibles.Value));
                    app.StimExtra.Value=num2str(str2double(app.StimTot.Value)-(str2double(app.Estimulosvuelta.Value)*str2double(app.Vueltasposibles.Value)));
                case 3
                    app.test='CR_DTF';
                    app.Pregunta.Value="Which sound is the same as the reference, A or B?";
                    app.Estimulostest.Value='3';
                    app.Balanceado.Value='CR';
                    app.Family.Value='Overall';
                    app.Title3.Text=app.Pregunta.Value;
                    app.A3.Text='Ref.';
                    app.B3.Text='A';
                    app.C3.Text='B';
                    app.B3_A.Text='A';
                    app.B3_B.Text='B';
                    app.B3_C.Visible='off';
                    if(isempty(app.comb_DTF))
                        app.Combposibles.Value=num2str([]);
                    else
                        app.Combposibles.Value=num2str(size(app.comb_DTF,1));
                    end
                    app.StimTot.Value=num2str(str2double(app.Combposibles.Value)*str2double((app.Estimulostest.Value)));
                    app.Vueltasposibles.Value=num2str(floor(str2double(app.StimTot.Value)/(str2double(app.Estimulostest.Value)*str2double(app.Combposibles.Value))));
                    app.Estimulosvuelta.Value=num2str(str2double(app.Estimulostest.Value)*str2double(app.Combposibles.Value));
                    app.StimExtra.Value=num2str(str2double(app.StimTot.Value)-(str2double(app.Estimulosvuelta.Value)*str2double(app.Vueltasposibles.Value)));
                case 4
                    app.test='CR_DTFM';
                    app.Pregunta.Value="Which sound is the same as the references, A o B?";
                    app.Estimulostest.Value='4';
                    app.Balanceado.Value='CR';
                    app.Family.Value='Overall';
                    app.Title4.Text=app.Pregunta.Value;
                    app.A4.Text='Ref.';
                    app.B4.Text='A';
                    app.C4.Text='Ref.';
                    app.D4.Text='B';
                    app.B4_A.Text='A';
                    app.B4_B.Text='B';
                    if(isempty(app.comb_DTFM))
                        app.Combposibles.Value=num2str([]);
                    else
                        app.Combposibles.Value=num2str(size(app.comb_DTFM,1));
                    end
                    app.StimTot.Value=num2str(str2double(app.Combposibles.Value)*str2double((app.Estimulostest.Value)));
                    app.Vueltasposibles.Value=num2str(floor(str2double(app.StimTot.Value)/(str2double(app.Estimulostest.Value)*str2double(app.Combposibles.Value))));
                    app.Estimulosvuelta.Value=num2str(str2double(app.Estimulostest.Value)*str2double(app.Combposibles.Value));
                    app.StimExtra.Value=num2str(str2double(app.StimTot.Value)-(str2double(app.Estimulosvuelta.Value)*str2double(app.Vueltasposibles.Value)));
                case 5
                    app.test='ANotA_R';
                    app.Pregunta.Value="Is sound B the same as the reference (Ref.)?";
                    app.Estimulostest.Value='2';
                    app.Balanceado.Value='CR';
                    app.Family.Value='Overall';
                    app.Title2.Text=app.Pregunta.Value;
                    app.A2.Text='Ref.';
                    app.B2.Text='B';
                    app.B2_A.Text='Ref.';
                    app.B2_B.Text='No Ref.';
                    if(isempty(app.comb_ANOTAR))
                        app.Combposibles.Value=num2str([]);
                    else
                        app.Combposibles.Value=num2str(size(app.comb_ANOTAR,1));
                    end

                    app.StimTot.Value=num2str(str2double(app.Combposibles.Value)*str2double((app.Estimulostest.Value)));
                    app.Vueltasposibles.Value=num2str(floor(str2double(app.StimTot.Value)/(str2double(app.Estimulostest.Value)*str2double(app.Combposibles.Value))));
                    app.Estimulosvuelta.Value=num2str(str2double(app.Estimulostest.Value)*str2double(app.Combposibles.Value));
                    app.StimExtra.Value=num2str(str2double(app.StimTot.Value)-(str2double(app.Estimulosvuelta.Value)*str2double(app.Vueltasposibles.Value)));
                case 6
                    app.test='SD';
                    app.Pregunta.Value="Are the two sounds the same or different?";
                    app.Estimulostest.Value='2';
                    app.Balanceado.Value='Balanced';
                    app.Family.Value='Overall';
                    app.Title2.Text=app.Pregunta.Value;
                    app.A2.Text='A';
                    app.B2.Text='B';
                    app.B2_A.Text='Same';
                    app.B2_B.Text='Different';
                    if(isempty(app.comb_SAMEDIFF))
                        app.Combposibles.Value=num2str([]);
                    else
                        app.Combposibles.Value=num2str(size(app.comb_SAMEDIFF,1));
                    end
                    app.StimTot.Value=num2str(str2double(app.Combposibles.Value)*str2double((app.Estimulostest.Value)));
                    app.Vueltasposibles.Value=num2str(floor(str2double(app.StimTot.Value)/(str2double(app.Estimulostest.Value)*str2double(app.Combposibles.Value))));
                    app.Estimulosvuelta.Value=num2str(str2double(app.Estimulostest.Value)*str2double(app.Combposibles.Value));
                    app.StimExtra.Value=num2str(str2double(app.StimTot.Value)-(str2double(app.Estimulosvuelta.Value)*str2double(app.Vueltasposibles.Value)));
                case 7
                    app.test='CR_SD';
                    app.Pregunta.Value="Are the two sounds the same or different?";
                    app.Estimulostest.Value='2';
                    app.Balanceado.Value='CR';
                    app.Family.Value='Overall';
                    app.Title2.Text=app.Pregunta.Value;
                    app.A2.Text='A';
                    app.B2.Text='B';
                    app.B2_A.Text='Same';
                    app.B2_B.Text='Different';
                    if(isempty(app.comb_ANOTAR))
                        app.Combposibles.Value=num2str([]);
                    else
                        app.Combposibles.Value=num2str(size(app.comb_ANOTAR,1));
                    end
                    app.StimTot.Value=num2str(str2double(app.Combposibles.Value)*str2double((app.Estimulostest.Value)));
                    app.Vueltasposibles.Value=num2str(floor(str2double(app.StimTot.Value)/(str2double(app.Estimulostest.Value)*str2double(app.Combposibles.Value))));
                    app.Estimulosvuelta.Value=num2str(str2double(app.Estimulostest.Value)*str2double(app.Combposibles.Value));
                    app.StimExtra.Value=num2str(str2double(app.StimTot.Value)-(str2double(app.Estimulosvuelta.Value)*str2double(app.Vueltasposibles.Value)));
                case 8
                    app.test='2AC';
                    app.Pregunta.Value="Which of the two sounds is more annoying?";
                    app.Estimulostest.Value='2';
                    app.Balanceado.Value='Balanced';
                    app.Family.Value='Attribute';
                    app.Title3.Text=app.Pregunta.Value;
                    app.A3.Text='A';
                    app.B3.Text='B';
                    app.C3.Text='B';
                    app.B3_A.Text='A';
                    app.B3_B.Text='No difference';
                    app.B3_C.Text='B';
                    if(isempty(app.comb_2AC))
                        app.Combposibles.Value=num2str([]);
                    else
                        app.Combposibles.Value=num2str(size(app.comb_2AC,1));
                    end
                    app.StimTot.Value=num2str(str2double(app.Combposibles.Value)*str2double((app.Estimulostest.Value)));
                    app.Vueltasposibles.Value=num2str(floor(str2double(app.StimTot.Value)/(str2double(app.Estimulostest.Value)*str2double(app.Combposibles.Value))));
                    app.Estimulosvuelta.Value=num2str(str2double(app.Estimulostest.Value)*str2double(app.Combposibles.Value));
                    app.StimExtra.Value=num2str(str2double(app.StimTot.Value)-(str2double(app.Estimulosvuelta.Value)*str2double(app.Vueltasposibles.Value)));

            end
        end

        function combs = combinations(app,num)
            combs=[];
            sequencing=app.sequencesSwitch.Value;
            if(~isempty(app.num_wavs))
                num_stim=app.num_wavs;
                switch num
                    case 1
                        for i=2:num_stim
                            if(strcmp(sequencing,'All'))
                                combs=vertcat(combs, [1 1 i], [1 i 1], [1 i i], [i 1 1], [i 1 i], [i i 1]);
                            else
                                aleat_case=randi([1 6]);
                                switch aleat_case
                                    case 1
                                        combs=vertcat(combs, [1 1 i]);
                                    case 2
                                        combs=vertcat(combs, [1 i 1]);
                                    case 3
                                        combs=vertcat(combs, [1 i i]);
                                    case 4
                                        combs=vertcat(combs, [i 1 1]);
                                    case 5
                                        combs=vertcat(combs, [i 1 i]);
                                    case 6
                                        combs=vertcat(combs, [i i 1]);
                                end
                            end
                        end
                    case 2
                        for i=2:num_stim
                            if(strcmp(sequencing,'All'))
                                combs=vertcat(combs, [1 i 1], [i 1 1], [1 i i], [i 1 i]);
                            else
                                aleat_case=randi([1 4]);
                                switch aleat_case
                                    case 1
                                        combs=vertcat(combs, [1 i 1]);
                                    case 2
                                        combs=vertcat(combs, [i 1 1]);
                                    case 3
                                        combs=vertcat(combs, [1 i i]);
                                    case 4
                                        combs=vertcat(combs, [i 1 i]);
                                end
                            end
                        end
                    case 3
                        for i=2:num_stim
                            if(strcmp(sequencing,'All'))
                                combs=vertcat(combs, [1 1 i], [1 i 1]);
                            else
                                aleat_case=randi([1 2]);
                                switch aleat_case
                                    case 1
                                        combs=vertcat(combs, [1 1 i]);
                                    case 2
                                        combs=vertcat(combs, [1 i 1]);
                                end
                            end
                        end
                    case 4
                        for i=2:num_stim
                            if(strcmp(sequencing,'All'))
                                combs=vertcat(combs, [1 1 1 i], [1 i 1 1]);
                            else
                                aleat_case=randi([1 2]);
                                switch aleat_case
                                    case 1
                                        combs=vertcat(combs, [1 1 1 i]);
                                    case 2
                                        combs=vertcat(combs, [1 i 1 1]);
                                end
                            end
                        end
                    case 5
                        for i=1:num_stim
                            combs=vertcat(combs, [1 i]);
                        end
                    case 6
                        for i=1:num_stim
                            if(strcmp(sequencing,'All'))
                                combs=vertcat(combs, [1 i], [i 1]);
                            else
                                aleat_case=randi([1 2]);
                                switch aleat_case
                                    case 1
                                        combs=vertcat(combs, [1 i]);
                                    case 2
                                        combs=vertcat(combs, [i 1]);
                                end
                            end
                        end
                    case 7
                        for i=1:num_stim
                            combs=vertcat(combs, [1 i]);
                        end
                    case 8
                        comb_tempi = nchoosek(1:num_stim, 2);
                        if(strcmp(sequencing,'All'))
                            comb_tempi_perm(:,1) = comb_tempi(:,2);
                            comb_tempi_perm(:,2) = comb_tempi(:,1);
                            combs=vertcat(comb_tempi,comb_tempi_perm);
                        else
                            combs = comb_tempi;
                        end
                end
            else
                combs=[];
            end
        end

        function [] = waiting_dialog(app)
            d = uiprogressdlg(app.DiTAAToolboxv10UIFigure,'Title','Break',...
                'Message','Please, make a brake before continuing...');
            %waitfor(d);
            for i=1:30
                d.Value=i/30;
                pause(1)
            end
            close(d)
        end
    end


    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app)
            app.StimTot.Value=num2str([]);
            app.Combposibles.Value=num2str([]);
            configurador(app);
            app.PerformButton.Enable='off';
        end

        % Button pushed function: Browse
        function BrowsePushed(app, event)
            [file,path] = uigetfile('*.wav','MultiSelect',"on");
            if(ischar(file))
                file=cellstr(file);
            end
            app.num_wavs=size(file,2);
            if(isempty(path) || app.num_wavs<2)
                errordlg('Please, select a proper number of stimuli to compare (i.e., at least 2)','Error retrieving stimuli');
            else
                app.Nodo1.Text=path;
                if(isempty(app.Subnodes)==0)
                    delete(app.Nodo1.Children);
                end

                for i=1:app.num_wavs
                    app.Subnodes(i) = uitreenode(app.Nodo1,'Text',file{1,i},'NodeData',[] );
                end
                app.stim_names=file;
                app.PerformButton.Enable='on';
                app.Lamp.Color=[0.00,1.00,0.00];
            end
            configurador(app)

        end

        % Value changed function: TestprotocolListBox
        function TestprotocolListBoxValueChanged(app, event)
            %value = app.TestprotocolListBox.Value;
            app.StimTot.Value=num2str([]);
            configurador(app)
        end

        % Button pushed function: PerformButton
        function PerformButtonPushed(app, event)
            [~, indice]=find(strcmp(app.TestprotocolListBox.Items, app.TestprotocolListBox.Value));
            app.contador=1;
            for i=1:size(app.Nodo1.Children)
                [app.stimuli{i} app.fs{i}]=audioread(strcat(app.Nodo1.Text,app.Nodo1.Children(i).Text));
            end
            %             app.stimuli{1}=audio;
            %             app.stimuli{2}=audio2;
            %             app.stimuli{3}=audio3;
            %             app.fs{1}=fs;
            %             app.fs{2}=fs2;
            %             app.fs{3}=fs3;
            switch indice
                case 1

                    app.B3.Enable='off';
                    app.C3.Enable='off';
                    app.B3_A.Enable='off';
                    app.B3_B.Enable='off';
                    app.B3_C.Enable='off';
                    app.Pestana.SelectedTab = app.Triple;

                    load('Bucket.mat');
                    tam=max(size(bucketlist));
                    sel_repetitions=randperm(tam,4);
                    app.comb_random_rep=app.comb_TRIANG(bucketlist(sel_repetitions),:);
                    bucketlist(sel_repetitions)=[];
                    if(isempty(bucketlist))
                        bucketlist=1:1:12;
                        save('Bucket.mat','bucketlist');
                    else
                        save('Bucket.mat','bucketlist');
                    end

                    app.comb_TRIANG=repmat(app.comb_TRIANG,1,str2double(app.Vueltasposibles.Value));
                    app.comb_TRIANG=[app.comb_TRIANG; app.comb_random_rep];
                    app.comb_random=app.comb_TRIANG(randperm(max(size(app.comb_TRIANG))),:);
                    app.NextButton3.Enable='off';

                case 2
                    app.B3.Enable='off';
                    app.C3.Enable='off';
                    app.B3_A.Enable='off';
                    app.B3_B.Enable='off';
                    app.B3_C.Enable='off';
                    app.Pestana.SelectedTab = app.Triple;
                    for i=1:str2double(app.Vueltasposibles.Value)
                        comb_temp(:,:,i)=app.comb_ABX;
                        comb_random_temp(:,:,i)=comb_temp(randperm(max(size(comb_temp(:,:,i)))),:,i);
                        app.comb_random=vertcat(app.comb_random,comb_random_temp(:,:,i));
                    end
                    app.NextButton3.Enable='off';
                case 3
                    app.B3.Enable='off';
                    app.C3.Enable='off';
                    app.B3_A.Enable='off';
                    app.B3_B.Enable='off';
                    app.B3_C.Enable='off';
                    app.Pestana.SelectedTab = app.Triple;
                    for i=1:str2double(app.Vueltasposibles.Value)
                        comb_temp(:,:,i)=app.comb_DTF;
                        comb_random_temp(:,:,i)=comb_temp(randperm(size(comb_temp(:,:,i),1)),:,i);
                        app.comb_random=vertcat(app.comb_random,comb_random_temp(:,:,i));
                    end
                    app.NextButton3.Enable='off';
                case 4
                    app.B4.Enable='off';
                    app.C4.Enable='off';
                    app.D4.Enable='off';
                    app.B4_A.Enable='off';
                    app.B4_B.Enable='off';
                    app.Pestana.SelectedTab = app.Quad;
                    for i=1:str2double(app.Vueltasposibles.Value)
                        comb_temp(:,:,i)=app.comb_DTFM;
                        comb_random_temp(:,:,i)=comb_temp(randperm(size(comb_temp(:,:,i),1)),:,i);
                        app.comb_random=vertcat(app.comb_random,comb_random_temp(:,:,i));
                    end
                    app.NextButton4.Enable='off';
                case 5
                    app.B2.Enable='off';
                    %app.C2.Enable='off';
                    app.B2_A.Enable='off';
                    app.B2_B.Enable='off';
                    app.Pestana.SelectedTab = app.Doble;
                    for i=1:str2double(app.Vueltasposibles.Value)
                        comb_temp(:,:,i)=app.comb_ANOTAR;
                        comb_random_temp(:,:,i)=comb_temp(randperm(size(comb_temp(:,:,i),1)),:,i);
                        app.comb_random=vertcat(app.comb_random,comb_random_temp(:,:,i));
                    end
                    app.NextButton2.Enable='off';
                case 6
                    app.B2.Enable='off';
                    %app.C2.Enable='off';
                    app.B2_A.Enable='off';
                    app.B2_B.Enable='off';
                    app.Pestana.SelectedTab = app.Doble;
                    for i=1:str2double(app.Vueltasposibles.Value)
                        comb_temp(:,:,i)=app.comb_SAMEDIFF;
                        comb_random_temp(:,:,i)=comb_temp(randperm(size(comb_temp(:,:,i),1)),:,i);
                        app.comb_random=vertcat(app.comb_random,comb_random_temp(:,:,i));
                    end
                    app.NextButton2.Enable='off';
                case 7
                    app.B2.Enable='off';
                    %app.C2.Enable='off';
                    app.B2_A.Enable='off';
                    app.B2_B.Enable='off';
                    app.Pestana.SelectedTab = app.Doble;
                    for i=1:str2double(app.Vueltasposibles.Value)
                        comb_temp(:,:,i)=app.comb_ANOTAR;
                        comb_random_temp(:,:,i)=comb_temp(randperm(size(comb_temp(:,:,i),1)),:,i);
                        app.comb_random=vertcat(app.comb_random,comb_random_temp(:,:,i));
                    end
                    app.NextButton2.Enable='off';

                case 8
                    app.B3.Enable='off';
                    app.B3.Visible='off';
                    app.C3.Enable='off';
                    app.B3_A.Enable='off';
                    app.B3_B.Enable='off';
                    app.B3_C.Enable='off';
                    app.Pestana.SelectedTab = app.Triple;
                    for i=1:str2double(app.Vueltasposibles.Value)
                        comb_temp(:,:,i)=app.comb_2AC;
                        comb_random_temp(:,:,i)=comb_temp(randperm(max(size(comb_temp(:,:,i)))),:,i);
                        app.comb_random=vertcat(app.comb_random,comb_random_temp(:,:,i));
                    end
                    app.NextButton3.Enable='off';
            end
            app.temp_start=tic();

        end

        % Value changing function: Pregunta
        function PreguntaValueChanging(app, event)
            changingValue = event.Value;
            [~, indice]=find(strcmp(app.TestprotocolListBox.Items, app.TestprotocolListBox.Value));
            if(indice==1 || indice==2 || indice==3)
                app.Title3.Text=changingValue;
            elseif(indice==4)
                app.Title4.Text=changingValue;
            elseif(indice==5 || indice==6 || indice==7)
                app.Title2.Text=changingValue;
            elseif(indice==8)
                app.Title3.Text=changingValue;
            end
        end

        % Value changed function: Pregunta
        function PreguntaValueChanged(app, event)
            value = app.Pregunta.Value;
            [~, indice]=find(strcmp(app.TestprotocolListBox.Items, app.TestprotocolListBox.Value));
            if(indice==1 || indice==2 || indice==3)
                app.Title3.Text=value;
            elseif(indice==4)
                app.Title4.Text=value;
            elseif(indice==5 || indice==6 || indice==7)
                app.Title2.Text=value;
            end
        end

        % Selection change function: Pestana
        function PestanaSelectionChanged(app, event)
            %selectedTab = app.Pestana.SelectedTab;
            app.Pestana.SelectedTab=app.Setup;
        end

        % Button pushed function: NextButton2
        function NextButton2Pushed(app, event)
            if(app.contador<str2double(app.StimTot.Value)/2)
                if(mod((app.contador/str2double(app.Combposibles.Value)),1)==0)
                    waiting_dialog(app);
                end
                app.A2.Enable='on';
                app.B2.Enable='off';
                app.B2_A.Enable='off';
                app.B2_B.Enable='off';
                app.NextButton2.Enable='off';
                app.Answer{app.contador}=app.AnswerButtonGroup.SelectedObject.Text;
                app.Button3.Value=1;
                if(app.contador==str2double(app.StimTot.Value)/2-1)
                    app.NextButton2.Text='Terminar';
                end
                app.contador=(app.contador)+1;
            else
                app.A2.Enable='on';
                app.B2.Enable='off';
                app.B2_A.Enable='off';
                app.B2_B.Enable='off';
                app.NextButton2.Enable='off';
                %app.NextButton2.Text='Terminar';
                app.Answer{app.contador}=app.AnswerButtonGroup.SelectedObject.Text;
                app.Button3.Value=1;
                app.temp_tot=toc(app.temp_start);
                num_combs=str2double(app.Combposibles.Value);
                num_replicates=str2double(app.Vueltasposibles.Value);
                num_wavs=app.num_wavs;
                test_name=app.test;
                stim_name=app.stim_names;
                temp=app.temp_tot;
                res=app.Answer;
                comb=app.comb_random;
                date=clock();
                save(strcat(test_name,'_',num2str(date(1)),'_',num2str(date(2)),'_',num2str(date(3)),'_',num2str(date(4)),'_',num2str(date(5)),'.mat'),'temp','res','comb','test_name','stim_name','num_combs','num_replicates','num_wavs');
                closereq
            end
        end

        % Button pushed function: NextButton3
        function NextButton3ButtonPushed(app, event)
            [~, indice]=find(strcmp(app.TestprotocolListBox.Items, app.TestprotocolListBox.Value));
            if(indice==1 || indice==2 || indice==3)
                pantallas=str2double(app.StimTot.Value)/3;
            else
                pantallas=str2double(app.StimTot.Value)/2;
            end

            if(app.contador<pantallas)
                if(mod((app.contador/str2double(app.Combposibles.Value)),1)==0)
                    waiting_dialog(app);
                end
                app.A3.Enable='on';
                app.B3.Enable='off';
                app.C3.Enable='off';
                app.B3_A.Enable='off';
                app.B3_B.Enable='off';
                app.B3_C.Enable='off';
                app.NextButton3.Enable='off';
                app.Answer{app.contador}=app.AnswerButtonGroup_4.SelectedObject.Text;
                app.Button4.Value=1;
                if(app.contador==pantallas-1)
                    app.NextButton3.Text='Terminar';
                end
                app.contador=(app.contador)+1;
            else
                app.A3.Enable='on';
                app.B3.Enable='off';
                app.C3.Enable='off';
                app.B3_A.Enable='off';
                app.B3_B.Enable='off';
                app.B3_C.Enable='off';
                app.NextButton3.Enable='off';
                app.Answer{app.contador}=app.AnswerButtonGroup_4.SelectedObject.Text;
                app.Button4.Value=1;
                app.temp_tot=toc(app.temp_start);
                num_combs=str2double(app.Combposibles.Value);
                num_replicates=str2double(app.Vueltasposibles.Value);
                num_wavs=app.num_wavs;
                temp=app.temp_tot;
                res=app.Answer;
                comb=app.comb_random;
                date=clock();
                test_name=app.test;
                stim_name=app.stim_names;
                save(strcat(test_name,'_',num2str(date(1)),'_',num2str(date(2)),'_',num2str(date(3)),'_',num2str(date(4)),'_',num2str(date(5)),'.mat'),'temp','res','comb','test_name','stim_name','num_combs','num_replicates','num_wavs');
                closereq
            end
        end

        % Button pushed function: NextButton4
        function NextButton4Pushed(app, event)
            if(app.contador<str2double(app.StimTot.Value)/4)
                if(mod((app.contador/str2double(app.Combposibles.Value)),1)==0)
                    waiting_dialog(app)
                end
                app.A4.Enable='on';
                app.B4.Enable='off';
                app.C4.Enable='off';
                app.D4.Enable='off';
                app.B4_A.Enable='off';
                app.B4_B.Enable='off';
                app.NextButton4.Enable='off';
                app.Answer{app.contador}=app.AnswerButtonGroup_3.SelectedObject.Text;
                app.Button3_2.Value=1;
                if(app.contador==str2double(app.StimTot.Value)/4-1)
                    app.NextButton4.Text='Terminar';
                end
                app.contador=(app.contador)+1;

            else
                app.A4.Enable='on';
                app.B4.Enable='off';
                app.C4.Enable='off';
                app.D4.Enable='off';
                app.B4_A.Enable='off';
                app.B4_B.Enable='off';
                app.NextButton4.Enable='off';
                %app.NextButton3.Text='Terminar';
                app.Answer{app.contador}=app.AnswerButtonGroup_3.SelectedObject.Text;
                app.Button3_2.Value=1;
                app.temp_tot=toc(app.temp_start);
                num_combs=str2double(app.Combposibles.Value);
                num_replicates=str2double(app.Vueltasposibles.Value);
                num_wavs=app.num_wavs;
                temp=app.temp_tot;
                res=app.Answer;
                comb=app.comb_random;
                test_name=app.test;
                stim_name=app.stim_names;
                date=clock();
                save(strcat(test_name,'_',num2str(date(1)),'_',num2str(date(2)),'_',num2str(date(3)),'_',num2str(date(4)),'_',num2str(date(5)),'.mat'),'temp','res','comb','test_name','stim_name','num_combs','num_replicates','num_wavs');
                closereq
            end
        end

        % Button pushed function: A2
        function A2ButtonPushed(app, event)
            app.A2.Enable='off';
            player=audioplayer(app.stimuli{app.comb_random(app.contador,1)},app.fs{app.comb_random(app.contador,1)});
            playblocking(player);
            app.B2.Enable='on';
        end

        % Button pushed function: B2
        function B2ButtonPushed(app, event)
            app.B2.Enable='off';
            player=audioplayer(app.stimuli{app.comb_random(app.contador,2)},app.fs{app.comb_random(app.contador,2)});
            playblocking(player);
            app.B2_A.Enable='on';
            app.B2_B.Enable='on';
        end

        % Button pushed function: B3
        function B3ButtonPushed(app, event)
            app.B3.Enable='off';
            player=audioplayer(app.stimuli{app.comb_random(app.contador,2)},app.fs{app.comb_random(app.contador,2)});
            playblocking(player);
            app.C3.Enable='on';
        end

        % Button pushed function: A3
        function A3ButtonPushed(app, event)
            [~, indice]=find(strcmp(app.TestprotocolListBox.Items, app.TestprotocolListBox.Value));
            app.A3.Enable='off';
            player=audioplayer(app.stimuli{app.comb_random(app.contador,1)},app.fs{app.comb_random(app.contador,1)});
            playblocking(player);
            if(indice==1 || indice==2 || indice==3)
                app.B3.Enable='on';
            else
                app.C3.Enable='on';
            end
        end

        % Button pushed function: C3
        function C3ButtonPushed(app, event)
            [~, indice]=find(strcmp(app.TestprotocolListBox.Items, app.TestprotocolListBox.Value));
            app.C3.Enable='off';
            if(indice==1 || indice==2 || indice==3)
                player=audioplayer(app.stimuli{app.comb_random(app.contador,3)},app.fs{app.comb_random(app.contador,3)});
            else
                player=audioplayer(app.stimuli{app.comb_random(app.contador,2)},app.fs{app.comb_random(app.contador,2)});
            end
            playblocking(player);
            app.B3_A.Enable='on';
            app.B3_B.Enable='on';
            app.B3_C.Enable='on';
        end

        % Button pushed function: C4
        function C4ButtonPushed(app, event)
            app.C4.Enable='off';
            player=audioplayer(app.stimuli{app.comb_random(app.contador,3)},app.fs{app.comb_random(app.contador,3)});
            playblocking(player);
            app.D4.Enable='on';
        end

        % Button pushed function: A4
        function A4ButtonPushed(app, event)
            app.A4.Enable='off';
            player=audioplayer(app.stimuli{app.comb_random(app.contador,1)},app.fs{app.comb_random(app.contador,1)});
            playblocking(player);
            app.B4.Enable='on';
        end

        % Button pushed function: B4
        function B4ButtonPushed(app, event)
            app.B4.Enable='off';
            player=audioplayer(app.stimuli{app.comb_random(app.contador,2)},app.fs{app.comb_random(app.contador,2)});
            playblocking(player);
            app.C4.Enable='on';
        end

        % Button pushed function: D4
        function D4ButtonPushed(app, event)
            app.D4.Enable='off';
            player=audioplayer(app.stimuli{app.comb_random(app.contador,4)},app.fs{app.comb_random(app.contador,1)});
            playblocking(player);
            app.B4_A.Enable='on';
            app.B4_B.Enable='on';
        end

        % Selection changed function: AnswerButtonGroup
        function AnswerButtonGroupSelectionChanged(app, event)
            %selectedButton = app.AnswerButtonGroup.SelectedObject;
            app.NextButton2.Enable='on';
        end

        % Selection changed function: AnswerButtonGroup_4
        function AnswerButtonGroup_4SelectionChanged(app, event)
            %selectedButton = app.AnswerButtonGroup_4.SelectedObject;
            app.NextButton3.Enable='on';
        end

        % Selection changed function: AnswerButtonGroup_3
        function AnswerButtonGroup_3SelectionChanged(app, event)
            %selectedButton = app.AnswerButtonGroup_3.SelectedObject;
            app.NextButton4.Enable='on';
        end

        % Value changed function: StimTot
        function StimTotValueChanged(app, event)
            value = app.StimTot.Value;
            configurador(app);
        end

        % Menu selected function: CloseMenu
        function CloseMenuSelected(app, event)
            closereq
        end

        % Value changed function: sequencesSwitch
        function sequencesSwitchValueChanged(app, event)
            value = app.sequencesSwitch.Value;
            configurador(app);
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.DiTAAToolboxv10UIFigure = uifigure('Visible', 'off');
            app.DiTAAToolboxv10UIFigure.Name = 'DiTAA Toolbox v 1.0';
            app.FileMenu = uimenu(app.DiTAAToolboxv10UIFigure);
            app.FileMenu.Text = 'File';
            app.OpendesignMenu = uimenu(app.FileMenu);
            app.OpendesignMenu.Text = 'Open design';
            app.SavedesignMenu = uimenu(app.FileMenu);
            app.SavedesignMenu.Text = 'Save design';
            app.CloseMenu = uimenu(app.FileMenu);
            app.CloseMenu.MenuSelectedFcn = createCallbackFcn(app, @CloseMenuSelected, true);
            app.CloseMenu.Text = 'Close';
            app.HelpMenu = uimenu(app.DiTAAToolboxv10UIFigure);
            app.HelpMenu.Text = 'Help';
            app.GettingstartedMenu = uimenu(app.HelpMenu);
            app.GettingstartedMenu.Text = 'Getting started';
            app.RecommendedconfigurationsMenu = uimenu(app.HelpMenu);
            app.RecommendedconfigurationsMenu.Text = 'Recommended configurations';
            app.Pestana = uitabgroup(app.DiTAAToolboxv10UIFigure);
            app.Pestana.SelectionChangedFcn = createCallbackFcn(app, @PestanaSelectionChanged, true);
            app.Setup = uitab(app.Pestana);
            app.TestprotocolListBoxLabel = uilabel(app.Setup);
            app.TestprotocolListBoxLabel.Text = {'Test'; 'protocol'};
            app.TestprotocolListBox = uilistbox(app.Setup);
            app.TestprotocolListBox.Items = {'Triangular', 'ABX', 'CR DTF', 'CR DTFM', 'A-Not AR', 'Same-Diff', 'CR Same-Diff', '2-AC'};
            app.TestprotocolListBox.ValueChangedFcn = createCallbackFcn(app, @TestprotocolListBoxValueChanged, true);
            app.TestprotocolListBox.Value = 'Triangular';
            app.TestquestionLabel = uilabel(app.Setup);
            app.TestquestionLabel.Text = 'Test question';
            app.Pregunta = uieditfield(app.Setup, 'text');
            app.Pregunta.ValueChangedFcn = createCallbackFcn(app, @PreguntaValueChanged, true);
            app.Pregunta.ValueChangingFcn = createCallbackFcn(app, @PreguntaValueChanging, true);
            app.Tree = uitree(app.Setup);
            app.Tree.Multiselect = 'on';
            app.Nodo1 = uitreenode(app.Tree);
            app.Nodo1.Text = 'Browse to select auditory stimuli';
            app.ProtocolspecificsPanel = uipanel(app.Setup);
            app.ProtocolspecificsPanel.Title = 'Protocol specifics';
            app.StimulitestLabel = uilabel(app.ProtocolspecificsPanel);
            app.StimulitestLabel.Text = 'Stimuli / test';
            app.Estimulostest = uieditfield(app.ProtocolspecificsPanel, 'text');
            app.Estimulostest.Editable = 'off';
            app.CombinationsLabel = uilabel(app.ProtocolspecificsPanel);
            app.CombinationsLabel.Text = 'Combinations';
            app.Combposibles = uieditfield(app.ProtocolspecificsPanel, 'text');
            app.Combposibles.Editable = 'off';
            app.StimulireplicationLabel = uilabel(app.ProtocolspecificsPanel);
            app.StimulireplicationLabel.Text = 'Stimuli / replication';
            app.Estimulosvuelta = uieditfield(app.ProtocolspecificsPanel, 'text');
            app.Estimulosvuelta.Editable = 'off';
            app.ReplicationsLabel = uilabel(app.ProtocolspecificsPanel);
            app.ReplicationsLabel.Text = 'Replications';
            app.Vueltasposibles = uieditfield(app.ProtocolspecificsPanel, 'text');
            app.Vueltasposibles.Value = 'Choose total';
            app.StimTot = uieditfield(app.ProtocolspecificsPanel, 'text');
            app.StimTot.ValueChangedFcn = createCallbackFcn(app, @StimTotValueChanged, true);
            app.BalancingLabel = uilabel(app.ProtocolspecificsPanel);
            app.BalancingLabel.Text = 'Balancing';
            app.Balanceado = uiswitch(app.ProtocolspecificsPanel, 'slider');
            app.Balanceado.Items = {'CR', 'Balanced'};
            app.Balanceado.Value = 'CR';
            app.TotalstimuliLabel = uilabel(app.ProtocolspecificsPanel);
            app.TotalstimuliLabel.Text = 'Total stimuli';
            app.ProtocolfamilyLabel = uilabel(app.ProtocolspecificsPanel);
            app.ProtocolfamilyLabel.Text = 'Protocol family';
            app.Family = uiswitch(app.ProtocolspecificsPanel, 'slider');
            app.Family.Items = {'Overall', 'Attribute'};
            app.Family.Value = 'Overall';
            app.sequencesSwitchLabel = uilabel(app.ProtocolspecificsPanel);
            app.sequencesSwitchLabel.Text = 'sequences';
            app.sequencesSwitch = uiswitch(app.ProtocolspecificsPanel, 'slider');
            app.sequencesSwitch.Items = {'Random', 'All'};
            app.sequencesSwitch.ValueChangedFcn = createCallbackFcn(app, @sequencesSwitchValueChanged, true);
            app.sequencesSwitch.Value = 'Random';
            app.PerformButton = uibutton(app.Setup, 'push');
            app.PerformButton.ButtonPushedFcn = createCallbackFcn(app, @PerformButtonPushed, true);
            app.PerformButton.Text = 'Perform';
            app.Browse = uibutton(app.Setup, 'push');
            app.Browse.ButtonPushedFcn = createCallbackFcn(app, @BrowsePushed, true);
            app.Browse.Text = '...';
            app.Lamp = uilamp(app.Setup);
            app.ExtrastimulifornumequalizationLabel = uilabel(app.Setup);
            app.ExtrastimulifornumequalizationLabel.Text = {'Extra stimuli'; '(for num. equalization)'};
            app.StimExtra = uieditfield(app.Setup, 'text');
            app.Doble = uitab(app.Pestana);
            app.Title2 = uilabel(app.Doble);
            app.Title2.Text = 'Text for two stimuli';
            app.PlaybackPanel = uipanel(app.Doble);
            app.PlaybackPanel.Title = 'Playback';
            app.B2 = uibutton(app.PlaybackPanel, 'push');
            app.B2.ButtonPushedFcn = createCallbackFcn(app, @B2ButtonPushed, true);
            app.A2 = uibutton(app.PlaybackPanel, 'push');
            app.A2.ButtonPushedFcn = createCallbackFcn(app, @A2ButtonPushed, true);
            app.AnswerButtonGroup = uibuttongroup(app.Doble);
            app.AnswerButtonGroup.SelectionChangedFcn = createCallbackFcn(app, @AnswerButtonGroupSelectionChanged, true);
            app.AnswerButtonGroup.Title = 'Answer';
            app.B2_A = uitogglebutton(app.AnswerButtonGroup);
            app.B2_A.Text = 'Button';
            app.B2_B = uitogglebutton(app.AnswerButtonGroup);
            app.B2_B.Text = 'Button2';
            app.Button3 = uitogglebutton(app.AnswerButtonGroup);
            app.Button3.Visible = 'off';
            app.Button3.Text = 'Button3';
            app.Button3.Value = true;
            app.NextButton2 = uibutton(app.Doble, 'push');
            app.NextButton2.ButtonPushedFcn = createCallbackFcn(app, @NextButton2Pushed, true);
            app.NextButton2.Text = 'Next';
            app.Triple = uitab(app.Pestana);
            app.PlaybackPanel_4 = uipanel(app.Triple);
            app.PlaybackPanel_4.Title = 'Playback';
            app.B3 = uibutton(app.PlaybackPanel_4, 'push');
            app.B3.ButtonPushedFcn = createCallbackFcn(app, @B3ButtonPushed, true);
            app.A3 = uibutton(app.PlaybackPanel_4, 'push');
            app.A3.ButtonPushedFcn = createCallbackFcn(app, @A3ButtonPushed, true);
            app.C3 = uibutton(app.PlaybackPanel_4, 'push');
            app.C3.ButtonPushedFcn = createCallbackFcn(app, @C3ButtonPushed, true);
            app.AnswerButtonGroup_4 = uibuttongroup(app.Triple);
            app.AnswerButtonGroup_4.SelectionChangedFcn = createCallbackFcn(app, @AnswerButtonGroup_4SelectionChanged, true);
            app.AnswerButtonGroup_4.Title = 'Answer';
            app.B3_A = uitogglebutton(app.AnswerButtonGroup_4);
            app.B3_A.Text = 'Button';
            app.B3_B = uitogglebutton(app.AnswerButtonGroup_4);
            app.B3_B.Text = 'Button3';
            app.B3_C = uitogglebutton(app.AnswerButtonGroup_4);
            app.B3_C.Text = 'Button2';
            app.Button4 = uitogglebutton(app.AnswerButtonGroup_4);
            app.Button4.Visible = 'off';
            app.Button4.Text = 'Button4';
            app.Button4.Value = true;
            app.Title3 = uilabel(app.Triple);
            app.Title3.Text = 'Text for three stimuli';
            app.NextButton3 = uibutton(app.Triple, 'push');
            app.NextButton3.ButtonPushedFcn = createCallbackFcn(app, @NextButton3ButtonPushed, true);
            app.NextButton3.Text = 'Next';
            app.Quad = uitab(app.Pestana);
            app.PlaybackPanel_3 = uipanel(app.Quad);
            app.PlaybackPanel_3.Title = 'Playback';
            app.C4 = uibutton(app.PlaybackPanel_3, 'push');
            app.C4.ButtonPushedFcn = createCallbackFcn(app, @C4ButtonPushed, true);
            app.A4 = uibutton(app.PlaybackPanel_3, 'push');
            app.A4.ButtonPushedFcn = createCallbackFcn(app, @A4ButtonPushed, true);
            app.B4 = uibutton(app.PlaybackPanel_3, 'push');
            app.B4.ButtonPushedFcn = createCallbackFcn(app, @B4ButtonPushed, true);
            app.D4 = uibutton(app.PlaybackPanel_3, 'push');
            app.D4.ButtonPushedFcn = createCallbackFcn(app, @D4ButtonPushed, true);
            app.AnswerButtonGroup_3 = uibuttongroup(app.Quad);
            app.AnswerButtonGroup_3.SelectionChangedFcn = createCallbackFcn(app, @AnswerButtonGroup_3SelectionChanged, true);
            app.AnswerButtonGroup_3.Title = 'Answer';
            app.B4_A = uitogglebutton(app.AnswerButtonGroup_3);
            app.B4_A.Text = 'Button';
            app.B4_B = uitogglebutton(app.AnswerButtonGroup_3);
            app.B4_B.Text = 'Button2';
            app.Button3_2 = uitogglebutton(app.AnswerButtonGroup_3);
            app.Button3_2.Visible = 'off';
            app.Button3_2.Text = 'Button3';
            app.Button3_2.Value = true;
            app.Title4 = uilabel(app.Quad);
            app.Title4.Text = 'Text for four stimuli';
            app.NextButton4 = uibutton(app.Quad, 'push');
            app.NextButton4.ButtonPushedFcn = createCallbackFcn(app, @NextButton4Pushed, true);
            app.NextButton4.Text = 'Next';
            % Show the figure after all components are created
            app.DiTAAToolboxv10UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = AppTest

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.DiTAAToolboxv10UIFigure)

            % Execute the startup function
            runStartupFcn(app, @startupFcn)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.DiTAAToolboxv10UIFigure)
        end
    end
end