classdef DiTAA_v2 < matlab.apps.AppBase
    %DITAA_V2 App made to perform listening tests

    % Properties that correspond to app components
    properties (Access = public)
        DiTAAToolboxv20UIFigure        matlab.ui.Figure
        FileMenu                       matlab.ui.container.Menu
        SaveDesignMenu                 matlab.ui.container.Menu
        OpenDesignMenu                 matlab.ui.container.Menu
        CloseMenu                      matlab.ui.container.Menu
        ViewMenu                       matlab.ui.container.Menu
        TestPerformMenu                matlab.ui.container.Menu
        AnalyzerMenu                   matlab.ui.container.Menu
        LanguagesMenu                  matlab.ui.container.Menu
        EnglishMenu                    matlab.ui.container.Menu
        SpanishMenu                    matlab.ui.container.Menu
        ItalianMenu                    matlab.ui.container.Menu
        FrenchMenu                     matlab.ui.container.Menu
        HelpMenu                       matlab.ui.container.Menu
        GettingStartedMenu             matlab.ui.container.Menu
        RecommendedConfigurationsMenu  matlab.ui.container.Menu
        Window                         matlab.ui.container.TabGroup
        Setup                          matlab.ui.container.Tab
        TestProtocolListBox            matlab.ui.control.ListBox
        StimBrowser                    matlab.ui.container.Tree
        BrowseStimsButton              matlab.ui.control.Button
        AddStimsButton                 matlab.ui.control.Button
        RemoveStimsButton              matlab.ui.control.Button
        ClearStimsButton               matlab.ui.control.Button
        TestQuestion                   matlab.ui.control.EditField
        StimExtraLabel                 matlab.ui.control.Label
        TestquestionLabel              matlab.ui.control.Label
        TestprotocolLabel              matlab.ui.control.Label
        StimExtra                      matlab.ui.control.EditField
        WaitingDialogCheckBox          matlab.ui.control.CheckBox
        Lamp                           matlab.ui.control.Lamp
        PerformButton                  matlab.ui.control.Button
        DiscriminationProtocolPanel    matlab.ui.container.Panel
        StimTot                        matlab.ui.control.EditField
        TotalstimuliEditFieldLabel     matlab.ui.control.Label
        SequencesSwitch                matlab.ui.control.Switch
        SequencesSwitchLabel           matlab.ui.control.Label
        FamilySwitch                   matlab.ui.control.Switch
        FamilyLabel                    matlab.ui.control.Label
        BalanceSwitch                  matlab.ui.control.Switch
        BalancedLabel                  matlab.ui.control.Label
        Replications                   matlab.ui.control.EditField
        ReplicationsLabel              matlab.ui.control.Label
        StimReplication                matlab.ui.control.EditField
        StimuliReplicationLabel        matlab.ui.control.Label
        Combinations                   matlab.ui.control.EditField
        CombinationsLabel              matlab.ui.control.Label
        StimTest                       matlab.ui.control.EditField
        StimuliTestLabel               matlab.ui.control.Label
        ScaleProtocolPanel             matlab.ui.container.Panel
        AnswerTypeDropDown             matlab.ui.control.DropDown
        AnswerTypeDropDownLabel        matlab.ui.control.Label
        ScalePolaritySwitch            matlab.ui.control.Switch
        ScalePolaritySwitchLabel       matlab.ui.control.Label
        AddAttributesButton            matlab.ui.control.Button
        RemoveAttributeButton          matlab.ui.control.Button
        ClearAttributesButton          matlab.ui.control.Button
        AttributesTable                matlab.ui.control.Table
        ScaleTypeDropDown              matlab.ui.control.DropDown
        ScaleTypeDropDownLabel         matlab.ui.control.Label
        ScalePointsSwitch              matlab.ui.control.Switch
        ScalePointsSwitchLabel         matlab.ui.control.Label
        StimTot_2                      matlab.ui.control.EditField
        TotalStimuliLabel_2            matlab.ui.control.Label
        Replications_2                 matlab.ui.control.EditField
        ReplicationsLabel_2            matlab.ui.control.Label
        Analyzer                       matlab.ui.container.Tab
        AnalyzeProtocolListBox         matlab.ui.control.ListBox
        ReviewBrowser                  matlab.ui.container.Tree
        BrowseReviewsButton            matlab.ui.control.Button
        ResultsVisualizationPanel      matlab.ui.container.Panel
        TabGroup                       matlab.ui.container.TabGroup
        ReplicationTab                 matlab.ui.container.Tab
        ResultsTableCombinationsR      matlab.ui.control.Table
        GlobalTab                      matlab.ui.container.Tab
        ResultsTableCombinationsG      matlab.ui.control.Table
        ResultsTableGeneral            matlab.ui.control.Table
        ResultsExportButton            matlab.ui.control.Button
        ResultsAxes                    matlab.ui.control.UIAxes
        TestprotocolLabel_2            matlab.ui.control.Label
        Double                         matlab.ui.container.Tab
        NextButton2                    matlab.ui.control.Button
        Title2                         matlab.ui.control.Label
        AnswerButtonGroup_2            matlab.ui.container.ButtonGroup
        ButtonHidden_2                 matlab.ui.control.ToggleButton
        BG2_B2                         matlab.ui.control.ToggleButton
        BG2_B1                         matlab.ui.control.ToggleButton
        PlaybackPanel_2                matlab.ui.container.Panel
        B2                             matlab.ui.control.Button
        A2                             matlab.ui.control.Button
        Triple                         matlab.ui.container.Tab
        NextButton3                    matlab.ui.control.Button
        Title3                         matlab.ui.control.Label
        AnswerButtonGroup_3            matlab.ui.container.ButtonGroup
        ButtonHidden_3                 matlab.ui.control.ToggleButton
        BG3_B2                         matlab.ui.control.ToggleButton
        BG3_B3                         matlab.ui.control.ToggleButton
        BG3_B1                         matlab.ui.control.ToggleButton
        PlaybackPanel_3                matlab.ui.container.Panel
        C3                             matlab.ui.control.Button
        B3                             matlab.ui.control.Button
        A3                             matlab.ui.control.Button
        Quad                           matlab.ui.container.Tab
        NextButton4                    matlab.ui.control.Button
        Title4                         matlab.ui.control.Label
        AnswerButtonGroup_4            matlab.ui.container.ButtonGroup
        ButtonHidden_4                 matlab.ui.control.ToggleButton
        BG4_B2                         matlab.ui.control.ToggleButton
        BG4_B1                         matlab.ui.control.ToggleButton
        PlaybackPanel_4                matlab.ui.container.Panel
        D4                             matlab.ui.control.Button
        C4                             matlab.ui.control.Button
        B4                             matlab.ui.control.Button
        A4                             matlab.ui.control.Button
        Scale                          matlab.ui.container.Tab
        TitleScale                     matlab.ui.control.Label
        NextButtonScale                matlab.ui.control.Button
        PlaybackPanel_Scale            matlab.ui.container.Panel
        ScalePlaybackGridLayout        matlab.ui.container.GridLayout
        AScale                         matlab.ui.control.Button
        AnswerScale7                   matlab.ui.container.Panel
        ScaleAns7GridLayout            matlab.ui.container.GridLayout
        NumLabelScale_4                matlab.ui.control.Label
        NumLabelScale_3                matlab.ui.control.Label
        Slider7                        matlab.ui.control.Slider
        AnswerScale5                   matlab.ui.container.Panel
        ScaleAns5GridLayout            matlab.ui.container.GridLayout
        NumLabelScale_2                matlab.ui.control.Label
        NumLabelScale_1                matlab.ui.control.Label
        Slider5                        matlab.ui.control.Slider
        Choices                        matlab.ui.container.Tab
        AnswerToggleChoice             matlab.ui.container.ButtonGroup
        ButtonHidden_Choices           matlab.ui.control.ToggleButton
        Button_10                      matlab.ui.control.ToggleButton
        Button_9                       matlab.ui.control.ToggleButton
        Button_8                       matlab.ui.control.ToggleButton
        Button_7                       matlab.ui.control.ToggleButton
        Button_6                       matlab.ui.control.ToggleButton
        Button_5                       matlab.ui.control.ToggleButton
        Button_4                       matlab.ui.control.ToggleButton
        Button_3                       matlab.ui.control.ToggleButton
        Button_2                       matlab.ui.control.ToggleButton
        Button_1                       matlab.ui.control.ToggleButton
        NextButtonChoice               matlab.ui.control.Button
        TitleChoice                    matlab.ui.control.Label
        PlaybackPanel_Choice           matlab.ui.container.Panel
        ChoicePlaybackGridLayout       matlab.ui.container.GridLayout
        AChoice                        matlab.ui.control.Button
        AnswerCheckboxChoice           matlab.ui.container.Panel
        ChoiceAnswerGridLayout         matlab.ui.container.GridLayout
        CheckBox_10                    matlab.ui.control.CheckBox
        CheckBox_9                     matlab.ui.control.CheckBox
        CheckBox_8                     matlab.ui.control.CheckBox
        CheckBox_7                     matlab.ui.control.CheckBox
        CheckBox_6                     matlab.ui.control.CheckBox
        CheckBox_5                     matlab.ui.control.CheckBox
        CheckBox_4                     matlab.ui.control.CheckBox
        CheckBox_3                     matlab.ui.control.CheckBox
        CheckBox_2                     matlab.ui.control.CheckBox
        CheckBox_1                     matlab.ui.control.CheckBox
    end

    % Properties that correspond to app operation
    properties (Access = public)
        Answer
        bi5_labels
        comb_TRIANG
        comb_ABX
        comb_DTF
        comb_DTFM
        comb_ANOTAR
        comb_SAMEDIFF
        comb_CRSAMEDIFF
        comb_2AC
        comb_SCALE
        comb_CHOICE
        comb_random
        comb_random_rep
        counter
        Dictionary
        fs
        input_att
        languages
        mats
        min_wavs
        num_labels
        num_wavs
        review_index
        review_nWavs
        review_path
        review_stimName
        review_test
        stim_names
        stimuli
        Subnodes_stim
        Subnodes_review
        temp_start
        temp_tot
        test
        test_index
        uni5_labels
        wait_screens
        wait_seconds
        % workspace
    end


    % Functions needed for app execution
    methods (Access = public)

        % Function that setup the app
        function [] = setup(app)
            % Manage app language
            try
                app.Dictionary=readtable("Dictionary.xlsx");
                change_language(app,'English');
            catch e
                warndlg(string(e.message),'Dictionary issues');
            end
            % Variable predefine
            app.review_test='Triang';
            app.test_index=1;
            app.wait_seconds='30';
            app.wait_screens='5';
            % Window elements setup
            app.TestPerformMenu.Checked=1;
            StimBrowserInfo=uitreenode(app.StimBrowser,'NodeData',[],'Text','');
            StimBrowserInfo.Tag='StimBrowserInfo';
            ReviewBrowserInfo=uitreenode(app.ReviewBrowser,'NodeData',[],'Text','');
            ReviewBrowserInfo.Tag='ReviewBrowserInfo1';
            change_language(app,'English',{StimBrowserInfo.Tag,ReviewBrowserInfo.Tag});
            app.AddStimsButton.Enable='off';
            app.ReviewBrowser.Enable='off';
            app.BrowseReviewsButton.Enable='off';
            app.ResultsExportButton.Enable='off';
            app.DiscriminationProtocolPanel.Visible='on';
            app.ScaleProtocolPanel.Visible='off';
            app.ScalePointsSwitch.Enable='off';
            app.PerformButton.Enable='off';
            % % Workspace configuration --> Not implemented because
            % % recursively search for 'Workspace.mat' in all computer
            % % storage is going to last unkown time
            % if(isfile('Workspace.mat'))
            %     % Load workspace
            %     app.workspace=load('Workspace.mat').workspace;
            % else
            %     pause(1); % Avoid using app before selecting workspace
            %     % Ask user for workspace
            %     app.workspace=uigetdir(pwd,'Select a workspace');
            %     if(app.workspace==0)
            %         app.workspace=pwd;
            %     end
            % end
            % Folders creation (in case that does not already exist)
            if(~isfolder(strcat(pwd,'\Reviews')))
                mkdir(strcat(pwd,'\Reviews'));
            end
            if(~isfolder(strcat(pwd,'\Templates')))
                mkdir(strcat(pwd,'\Templates'));
            end
            if(~isfolder(strcat(pwd,'\Analysis')))
                mkdir(strcat(pwd,'\Analysis'));
            end
        end
        
        % Function that setup the protocol specifics
        function [] = configurator(app)
            % Each case identifies one different protocol
            switch app.test_index
                case 1 % TRIANG
                    % Default protocol values
                    app.test='Triang';
                    if(~isempty(app.Dictionary))
                        app.TestQuestion.Tag='TriangQuestion';
                    else
                        app.TestQuestion.Value="Which of the three sounds is different?";
                    end
                    app.StimTest.Value='3';
                    app.min_wavs=2;
                    app.BalanceSwitch.Value=app.BalanceSwitch.Items(2); % Balanced
                    app.FamilySwitch.Value=app.FamilySwitch.Items(1);% Overall
                case 2 % ABX
                    % Default protocol values
                    app.test='ABX';
                    if(~isempty(app.Dictionary))
                        app.TestQuestion.Tag='ABXQuestion';
                    else
                        app.TestQuestion.Value="It is sound X the same as sound A or sound B?";
                    end
                    app.StimTest.Value='3';
                    app.min_wavs=2;
                    app.BalanceSwitch.Value=app.BalanceSwitch.Items(2); % Balanced
                    app.FamilySwitch.Value=app.FamilySwitch.Items(1);% Overall
                case 3 % CR DTF
                    % Default values
                    app.test='CR_DTF';
                    if(~isempty(app.Dictionary))
                        app.TestQuestion.Tag='CR_DTFQuestion';
                    else
                        app.TestQuestion.Value="Which sound is the same as the reference, A or B?";
                    end
                    app.StimTest.Value='3';
                    app.min_wavs=2;
                    app.BalanceSwitch.Value=app.BalanceSwitch.Items(1); % CR
                    app.FamilySwitch.Value=app.FamilySwitch.Items(1);% Overall
                case 4 % CR DTFM
                    % Default values
                    app.test='CR_DTFM';
                    if(~isempty(app.Dictionary))
                        app.TestQuestion.Tag='CR_DTFMQuestion';
                    else
                        app.TestQuestion.Value="Which sound is the same as the references, A o B?";
                    end
                    app.StimTest.Value='4';
                    app.min_wavs=2;
                    app.BalanceSwitch.Value=app.BalanceSwitch.Items(1); % CR
                    app.FamilySwitch.Value=app.FamilySwitch.Items(1);% Overall
                case 5 % ANOTAR
                    % Default values
                    app.test='ANotA_R';
                    if(~isempty(app.Dictionary))
                        app.TestQuestion.Tag='ANOTARQuestion';
                    else
                        app.TestQuestion.Value="Is sound B the same as the reference (Ref.)?";
                    end
                    app.StimTest.Value='2';
                    app.min_wavs=2;
                    app.BalanceSwitch.Value=app.BalanceSwitch.Items(1); % CR
                    app.FamilySwitch.Value=app.FamilySwitch.Items(1);% Overall
                case 6 % SAME DIFFERENCE
                    % Default values
                    app.test='SD';
                    if(~isempty(app.Dictionary))
                        app.TestQuestion.Tag='SDQuestion';
                    else
                        app.TestQuestion.Value="Are the two sounds the same or different?";
                    end
                    app.StimTest.Value='2';
                    app.min_wavs=2;
                    app.BalanceSwitch.Value=app.BalanceSwitch.Items(2); % Balanced
                    app.FamilySwitch.Value=app.FamilySwitch.Items(1);% Overall
                case 7 % CR SD
                    % Default values
                    app.test='CR_SD';
                    if(~isempty(app.Dictionary))
                        app.TestQuestion.Tag='CR_SDQuestion';
                    else
                        app.TestQuestion.Value="Are the two sounds the same or different?";
                    end
                    app.StimTest.Value='2';
                    app.min_wavs=2;
                    app.BalanceSwitch.Value=app.BalanceSwitch.Items(1); % CR
                    app.FamilySwitch.Value=app.FamilySwitch.Items(1);% Overall
                case 8 % 2AC
                    % Default values
                    app.test='2AC';
                    if(~isempty(app.Dictionary))
                        app.TestQuestion.Tag='2ACQuestion';
                    else
                        app.TestQuestion.Value="Which of the two sounds is more annoying?";
                    end
                    app.StimTest.Value='2';
                    app.min_wavs=2;
                    app.BalanceSwitch.Value=app.BalanceSwitch.Items(2); % Balanced
                    app.FamilySwitch.Value=app.FamilySwitch.Items(2);% Attribute
                case 9 % Sem. Scale
                    % Default values
                    app.test='Sem_Scale';
                    if(~isempty(app.Dictionary))
                        app.TestQuestion.Tag='ScaleQuestion';
                    else
                        app.TestQuestion.Value="Rate this sound according to: (attribute name)";
                    end
                    app.StimTest.Value='1';
                    app.min_wavs=1;
                    app.ScalePointsSwitch.Value='5';
                    app.ScalePointsSwitch.Enable='off';
                    app.ScalePointsSwitch.Visible='on';
                    app.ScalePointsSwitchLabel.Visible='on';
                    app.ScalePolaritySwitch.Enable='off';
                    app.ScalePolaritySwitch.Visible='on';
                    app.ScalePolaritySwitchLabel.Visible='on';
                    app.ScaleTypeDropDown.Enable='on';
                    app.ScaleTypeDropDown.Visible='on';
                    app.ScaleTypeDropDownLabel.Visible='on';
                    app.ScaleTypeDropDown.Value=app.ScaleTypeDropDown.Items(1); % Verbal
                    app.AnswerTypeDropDown.Enable='off';
                    app.AnswerTypeDropDown.Visible='off';
                    app.AnswerTypeDropDownLabel.Visible='off';
                    app.StimTot_2.Placeholder='Select stimuli';
                case 10 % 1A/nA choice
                    % Default values
                    app.test='1A_nA';
                    if(~isempty(app.Dictionary))
                        app.TestQuestion.Tag='ChoiceQuestion';
                    else
                        app.TestQuestion.Value="Which attributes are percieved in the sound?";
                    end
                    app.StimTest.Value='1';
                    app.min_wavs=1;
                    app.ScalePointsSwitch.Enable='off';
                    app.ScalePointsSwitch.Visible='off';
                    app.ScalePointsSwitchLabel.Visible='off';
                    app.ScalePolaritySwitch.Enable='off';
                    app.ScalePolaritySwitch.Visible='off';
                    app.ScalePolaritySwitchLabel.Visible='off';
                    app.ScaleTypeDropDown.Enable='off';
                    app.ScaleTypeDropDown.Visible='off';
                    app.ScaleTypeDropDownLabel.Visible='off';
                    app.AnswerTypeDropDown.Enable='on';
                    app.AnswerTypeDropDown.Visible='on';
                    app.AnswerTypeDropDownLabel.Visible='on';
                    app.AnswerTypeDropDown.Value=app.AnswerTypeDropDown.Items(1); % Checkbox
                    app.StimTot_2.Placeholder='Select stimuli';
            end
            if(app.test_index~=9 && app.test_index~=10)
                % Change protocol panel visibility
                app.DiscriminationProtocolPanel.Visible='on';
                app.ScaleProtocolPanel.Visible='off';
                % Next screen info
                if(~isempty(app.Subnodes_stim))
                    app.PerformButton.Enable='on';
                    app.Lamp.Color=[0.00,1.00,0.00]; % Green
                end
            else % Semantic scale and 1A/nA choice
                % Change protocol panel visibility
                app.DiscriminationProtocolPanel.Visible='off';
                app.ScaleProtocolPanel.Visible='on';
                % Next screen info
                if(isempty(app.AttributesTable.Data))
                    app.PerformButton.Enable='off';
                    app.Lamp.Color=[1.00,0.00,0.00]; % Red
                end
            end
            % Change language for test question update
            if(~isempty(app.Dictionary))
                change_language(app,app.languages.lang,app.TestQuestion.Tag);
            end
        end

        % Function that update protocol combinations and specific
        % values when a change is made in app setup process
        function [] = update_stim_values(app)
            % Make protocol combinations
            switch app.test_index
                case 1 % TRIANG
                    app.comb_TRIANG=combine(app,1);
                    if(~isempty(app.comb_TRIANG))
                        app.Combinations.Value=num2str(size(app.comb_TRIANG,1));
                    end
                case 2 % ABX
                    app.comb_ABX=combine(app,2);
                    if(~isempty(app.comb_ABX))
                        app.Combinations.Value=num2str(size(app.comb_ABX,1));
                    end
                case 3 % CR DTF
                    app.comb_DTF=combine(app,3);
                    if(~isempty(app.comb_DTF))
                        app.Combinations.Value=num2str(size(app.comb_DTF,1));
                    end
                case 4 % CR DTFM
                    app.comb_DTFM=combine(app,4);
                    if(~isempty(app.comb_DTFM))
                        app.Combinations.Value=num2str(size(app.comb_DTFM,1));
                    end
                case 5 % ANOTAR
                    app.comb_ANOTAR=combine(app,5);
                    if(~isempty(app.comb_ANOTAR))
                        app.Combinations.Value=num2str(size(app.comb_ANOTAR,1));
                    end
                case 6 % SAME DIFFERENCE
                    app.comb_SAMEDIFF=combine(app,6);
                    if(~isempty(app.comb_SAMEDIFF))
                        app.Combinations.Value=num2str(size(app.comb_SAMEDIFF,1));
                    end
                case 7 % CR SD
                    app.comb_ANOTAR=combine(app,5);
                    if(~isempty(app.comb_ANOTAR))
                        app.Combinations.Value=num2str(size(app.comb_ANOTAR,1));
                    end
                case 8 % 2AC
                    app.comb_2AC=combine(app,8);
                    if(~isempty(app.comb_2AC))
                        app.Combinations.Value=num2str(size(app.comb_2AC,1));
                    end
                case 9 % Semantic scale
                    % Change StimTot_2 placeholder
                    if(isempty(app.num_wavs))
                        if(~isempty(app.Dictionary))
                            app.StimTot_2.Tag='StimTot2_S';
                        else
                            app.StimTot_2.Placeholder='Select stimuli';
                        end
                    elseif(isempty(app.AttributesTable.Data))
                        if(~isempty(app.Dictionary))
                            app.StimTot_2.Tag='StimTot2_A';
                        else
                            app.StimTot_2.Placeholder='Add attributes';
                        end
                    end
                    % Combinations
                    app.comb_SCALE=combine(app,9);
                    if(~isempty(app.comb_SCALE))
                        app.Combinations.Value=num2str(size(app.comb_SCALE,1));
                    end
                case 10 % 1A/nA choice
                    % Change StimTot_2 placeholder
                    if(isempty(app.num_wavs))
                        if(~isempty(app.Dictionary))
                            app.StimTot_2.Tag='StimTot2_S';
                        else
                            app.StimTot_2.Placeholder='Select stimuli';
                        end
                    elseif(isempty(app.AttributesTable.Data))
                        if(~isempty(app.Dictionary))
                            app.StimTot_2.Tag='StimTot2_A';
                        else
                            app.StimTot_2.Placeholder='Add attributes';
                        end
                    end
                    % Combinations
                    app.comb_CHOICE=combine(app,10);
                    if(~isempty(app.comb_CHOICE))
                        app.Combinations.Value=num2str(size(app.comb_CHOICE,1));
                    end
            end
            % Calculate protocol values
            if(~isempty(app.num_wavs))
                if(app.test_index~=9 && app.test_index~=10)
                    app.StimTot.Value=num2str(str2double(app.Combinations.Value)*str2double(app.StimTest.Value)*str2double(app.Replications.Value));
                    app.StimReplication.Value=num2str(str2double(app.StimTest.Value)*str2double(app.Combinations.Value));
                    app.StimExtra.Value=num2str(str2double(app.StimTot.Value)-(str2double(app.StimReplication.Value)*str2double(app.Replications.Value)));
                else % Semantic scale & 1A/nA choice
                    if(~isempty(app.AttributesTable.Data))
                        app.StimTot_2.Value=num2str(str2double(app.Combinations.Value)*str2double(app.StimTest.Value)*str2double(app.Replications_2.Value));
                    end
                end
            else
                if(app.test_index~=9 && app.test_index~=10)
                    app.StimTot.Value='';
                    app.StimReplication.Value='';
                    app.Combinations.Value='';
                    app.StimExtra.Value='';
                else % Semantic scale & 1A/nA choice
                    app.StimTot_2.Value='';
                end
            end
            % Change language for StimTot_2.Placeholder update
            if(~isempty(app.Dictionary) && ~isempty(app.StimTot_2.Tag))
                change_language(app,app.languages.lang,app.StimTot_2.Tag);
            end
        end

        % Function that generate the stimuli combinations, which are
        % used during the listening tests
        function combs = combine(app,num)
            combs=[];
            sequencing=app.SequencesSwitch.Value;
            if(~isempty(app.num_wavs) && app.num_wavs>=app.min_wavs)
                % Each case identifies one different protocol
                switch num
                    case 1 % TRIANG
                        for i=2:app.num_wavs
                            if(strcmp(sequencing,app.SequencesSwitch.Items(2))) % Compare to "All"
                                combs=vertcat(combs, [1 1 i], [1 i 1], [1 i i], [i 1 1], [i 1 i], [i i 1]);
                            else
                                aleat_case=randi([1 6]);
                                switch aleat_case
                                    case 1
                                        combs=vertcat(combs, [1 1 i]);
                                    case 2
                                        combs=vertcat(combs, [1 i 1]);
                                    case 3
                                        combs=vertcat(combs, [1 i i]);
                                    case 4
                                        combs=vertcat(combs, [i 1 1]);
                                    case 5
                                        combs=vertcat(combs, [i 1 i]);
                                    case 6
                                        combs=vertcat(combs, [i i 1]);
                                end
                            end
                        end
                    case 2 % ABX
                        for i=2:app.num_wavs
                            if(strcmp(sequencing,app.SequencesSwitch.Items(2))) % Compare to "All"
                                combs=vertcat(combs, [1 i 1], [i 1 1], [1 i i], [i 1 i]);
                            else
                                aleat_case=randi([1 4]);
                                switch aleat_case
                                    case 1
                                        combs=vertcat(combs, [1 i 1]);
                                    case 2
                                        combs=vertcat(combs, [i 1 1]);
                                    case 3
                                        combs=vertcat(combs, [1 i i]);
                                    case 4
                                        combs=vertcat(combs, [i 1 i]);
                                end
                            end
                        end
                    case 3 % CR DTF
                        for i=2:app.num_wavs
                            if(strcmp(sequencing,app.SequencesSwitch.Items(2))) % Compare to "All"
                                combs=vertcat(combs, [1 1 i], [1 i 1]);
                            else
                                aleat_case=randi([1 2]);
                                switch aleat_case
                                    case 1
                                        combs=vertcat(combs, [1 1 i]);
                                    case 2
                                        combs=vertcat(combs, [1 i 1]);
                                end
                            end
                        end
                    case 4 % CR DTFM
                        for i=2:app.num_wavs
                            if(strcmp(sequencing,app.SequencesSwitch.Items(2))) % Compare to "All"
                                combs=vertcat(combs, [1 1 1 i], [1 i 1 1]);
                            else
                                aleat_case=randi([1 2]);
                                switch aleat_case
                                    case 1
                                        combs=vertcat(combs, [1 1 1 i]);
                                    case 2
                                        combs=vertcat(combs, [1 i 1 1]);
                                end
                            end
                        end
                    case 5 % ANOTAR
                        for i=1:app.num_wavs
                            combs=vertcat(combs, [1 i]);
                        end
                    case 6 % SAMEDIF
                        for i=1:app.num_wavs
                            if(strcmp(sequencing,app.SequencesSwitch.Items(2))) % Compare to "All"
                                combs=vertcat(combs, [1 i], [i 1]);
                            else
                                aleat_case=randi([1 2]);
                                switch aleat_case
                                    case 1
                                        combs=vertcat(combs, [1 i]);
                                    case 2
                                        combs=vertcat(combs, [i 1]);
                                end
                            end
                        end
                    case 7 % --> NOT USED
                        for i=1:app.num_wavs
                            combs=vertcat(combs, [1 i]);
                        end
                    case 8 % 2AC
                        comb_tempi = nchoosek(1:app.num_wavs, 2); % ISSUES
                        if(strcmp(sequencing,app.SequencesSwitch.Items(2))) % Compare to "All"
                            comb_tempi_perm(:,1) = comb_tempi(:,2);
                            comb_tempi_perm(:,2) = comb_tempi(:,1);
                            combs=vertcat(comb_tempi,comb_tempi_perm);
                        else
                            combs = comb_tempi;
                        end
                    case 9 % Semantic scale
                        if(~isempty(app.num_wavs) && ~isempty(app.AttributesTable.Data))
                            % Create variables
                            stim=1:app.num_wavs;
                            att=1:length(app.AttributesTable.Data);
                            possibilities=[];
                            % Make all possibilities
                            for i=1:length(stim)
                                for j=1:length(att)
                                    possibilities=vertcat(possibilities, [stim(i), att(j)]);
                                end
                            end
                            % Randomize possibilities
                            combs=possibilities(randperm(size(possibilities,1)),:);
                        end
                    case 10 % 1A/nA choice
                        if(~isempty(app.num_wavs) && ~isempty(app.AttributesTable.Data))
                            % Create variables
                            stim=1:app.num_wavs;
                            att=1:length(app.AttributesTable.Data);
                            possibilities=[];
                            % Make all possibilities
                            for i=1:length(stim)
                                aux=att(randperm(length(att)));
                                possibilities=vertcat(possibilities, [stim(i), aux]);
                            end
                            % Randomize possibilities
                            combs=possibilities(randperm(size(possibilities,1)),:);
                        end
                end
            else
                combs=[];
            end
        end

        % Function used for display a progress bar and force assesors
        % to have a break
        function [] = waiting_dialog(app)
            d = uiprogressdlg(app.DiTAAToolboxv20UIFigure,'Title','Break',...
                'Message','Please, make a brake before continuing...');
            %waitfor(d);
            for i=1:str2double(app.wait_seconds)
                d.Value=i/str2double(app.wait_seconds);
                pause(1)
            end
            close(d)
        end
                
        % Function made for saving each test run in .mat files
        function [] = save_review(app)
            % Saving test variables
            duration=app.temp_tot; % Saving test timer
            num_combs=str2double(app.Combinations.Value);
            if(app.test_index~=9 && app.test_index~=10)
                num_replicates=str2double(app.Replications.Value);
                seq_value=app.SequencesSwitch.ValueIndex;
            else % Semantic scale & 1A/nA choice
                num_replicates=str2double(app.Replications_2.Value);
                seq_value=[];
            end
            num_wavs=app.num_wavs;
            test_name=app.test;
            stim_name=app.stim_names;
            comb=app.comb_random;
            answer=app.Answer;
            % Creating test review file
            date=clock();
            filename=strcat(test_name,'-',num2str(date(1)),'_',num2str(date(2)),'_',num2str(date(3)),'-',num2str(date(4)),'_',num2str(date(5)),'.mat');
            save(strcat(pwd,'\Reviews\',filename),'duration','answer','comb','test_name','stim_name','num_combs','num_replicates','num_wavs','seq_value');
        end

        % Function to change app language and update app labels and
        % values
        function [] = change_language(app,language,varargin)
            if(isempty(varargin)) % General language change
                switch language % Get language text
                    case 'English'
                        % Set app and menu
                        app.languages.lang='English';
                        app.EnglishMenu.Checked='on';
                        app.SpanishMenu.Checked='off';
                        app.ItalianMenu.Checked='off';
                        app.FrenchMenu.Checked='off';
                        % Set Scale test labels
                        app.uni5_labels=["Not at all","Slightly","Moderately","Very","Extremely"];
                        app.bi5_labels=["","","Same as standard","",""];
                        app.num_labels=["Not","Very"];
                        % Get Dictionary language column
                        app_text=app.Dictionary.ENGLISH;
                    case 'Spanish'
                        % Set app and menu
                        app.languages.lang='Spanish';
                        app.EnglishMenu.Checked='off';
                        app.SpanishMenu.Checked='on';
                        app.ItalianMenu.Checked='off';
                        app.FrenchMenu.Checked='off';
                        % Set Scale test labels
                        app.uni5_labels=["Nada","Algo","Moderadamente","Muy","Extremadamente"];
                        app.bi5_labels=["","","Igual que la referencia","",""];
                        app.num_labels=["Nada","Muy"];
                        % Get Dictionary language column
                        app_text=app.Dictionary.SPANISH;
                    case 'Italian'
                        % Set app and menu
                        app.languages.lang='Italian';
                        app.SpanishMenu.Checked='off';
                        app.EnglishMenu.Checked='off';
                        app.ItalianMenu.Checked='on';
                        app.FrenchMenu.Checked='off';
                        % Set Scale test labels
                        app.uni5_labels=["Niente affatto","Leggermente","Moderatamente","Molto","In modo estremo"];
                        app.bi5_labels=["","","Igual que la referencia","",""];
                        app.num_labels=["Niente","Molto"];
                        % Get Dictionary language column
                        app_text=app.Dictionary.ITALIAN;
                    case 'French'
                        % Set app and menu
                        app.languages.lang='French';
                        app.SpanishMenu.Checked='off';
                        app.EnglishMenu.Checked='off';
                        app.ItalianMenu.Checked='off';
                        app.FrenchMenu.Checked='on';
                        % Set Scale test labels
                        app.uni5_labels=["Pas du tout","Légèrement","Moyennement","Beaucoup","Extrémement"];
                        app.bi5_labels=["","","Igual que la referencia","",""];
                        app.num_labels=["Pas du tout","Beaucoup"];
                        % Get Dictionary language column
                        app_text=app.Dictionary.FRENCH;
                end
                % Work with Dictionary
                for i=1:height(app.Dictionary)
                    % Get all app components with the same TAG
                    temp_tags = findall(groot,'Tag',app.Dictionary.TAG{i});
                    % Change language filtering by tag and propertie
                    switch app.Dictionary.PROPERTY{i}
                        case {'Text','Value','Title','Placeholder'}
                            set(temp_tags,app.Dictionary.PROPERTY{i},app_text{i});
                        case {'Items','ColumnName'}
                            set(temp_tags,app.Dictionary.PROPERTY{i},strsplit(app_text{i},','));
                            % temp_tags.Value = temp_tags.Items{language};
                        case 'Position'
                            set(temp_tags,app.Dictionary.PROPERTY{i},str2num(app_text{i}));
                    end
                end
            elseif(length(varargin)==1) % Specific tag language change
                % Get all app components with the specific tags
                % temp_tags = cellfun(@(x) findall(groot, 'Tag', x), varargin{1});
                if(~ischar(varargin{1})) % Some specific tags
                    for i=1:length(varargin{1})
                        temp_tags{i}=findall(groot,'Tag',varargin{1}{i});
                        if(length(temp_tags{i})>1) % There's more than one component of selected tag
                            for j=1:length(temp_tags{i})
                                tag_components{i}{j}=temp_tags{i}(j);
                            end
                            % Change language for selected tags
                            for j=1:length(tag_components{i})
                                idx=find(strcmp(app.Dictionary.TAG,tag_components{i}{j}.Tag));
                                switch language
                                    case 'English'
                                        switch app.Dictionary.PROPERTY{idx}
                                            case {'Text','Value','Title','Placeholder'}
                                                set(tag_components{i}{j}, app.Dictionary.PROPERTY{idx}, app.Dictionary.ENGLISH{idx});
                                            case {'Items','ColumnName'}
                                                set(tag_components{i}{j}, app.Dictionary.PROPERTY{idx}, strsplit(app.Dictionary.ENGLISH{idx},','));
                                        end
                                    case 'Spanish'
                                        switch app.Dictionary.PROPERTY{idx}
                                            case {'Text','Value','Title','Placeholder'}
                                                set(tag_components{i}{j}, app.Dictionary.PROPERTY{idx}, app.Dictionary.SPANISH{idx});
                                            case {'Items','ColumnName'}
                                                set(tag_components{i}{j}, app.Dictionary.PROPERTY{idx}, strsplit(app.Dictionary.SPANISH{idx},','));
                                        end
                                    case 'Italian'
                                        switch app.Dictionary.PROPERTY{idx}
                                            case {'Text','Value','Title','Placeholder'}
                                                set(tag_components{i}{j}, app.Dictionary.PROPERTY{idx}, app.Dictionary.ITALIAN{idx});
                                            case {'Items','ColumnName'}
                                                set(tag_components{i}{j}, app.Dictionary.PROPERTY{idx}, strsplit(app.Dictionary.ITALIAN{idx},','));
                                        end
                                    case 'French'
                                        switch app.Dictionary.PROPERTY{idx}
                                            case {'Text','Value','Title','Placeholder'}
                                                set(tag_components{i}{j}, app.Dictionary.PROPERTY{idx}, app.Dictionary.FRENCH{idx});
                                            case {'Items','ColumnName'}
                                                set(tag_components{i}{j}, app.Dictionary.PROPERTY{idx}, strsplit(app.Dictionary.FRENCH{idx},','));
                                        end
                                end
                            end
                        else % There's only one component of selected tag
                            tag_components{i}=temp_tags{i};
                            % Change language for selected tags
                            for j=1:length(tag_components)
                                idx=find(strcmp(app.Dictionary.TAG,tag_components{i}.Tag));
                                switch language
                                    case 'English'
                                        switch app.Dictionary.PROPERTY{idx}
                                            case {'Text','Value','Title','Placeholder'}
                                                set(tag_components{i}, app.Dictionary.PROPERTY{idx}, app.Dictionary.ENGLISH{idx});
                                            case {'Items','ColumnName'}
                                                set(tag_components{i}, app.Dictionary.PROPERTY{idx}, strsplit(app.Dictionary.ENGLISH{idx},','));
                                        end
                                    case 'Spanish'
                                        switch app.Dictionary.PROPERTY{idx}
                                            case {'Text','Value','Title','Placeholder'}
                                                set(tag_components{i}, app.Dictionary.PROPERTY{idx}, app.Dictionary.SPANISH{idx});
                                            case {'Items','ColumnName'}
                                                set(tag_components{i}, app.Dictionary.PROPERTY{idx}, strsplit(app.Dictionary.SPANISH{idx},','));
                                        end
                                    case 'Italian'
                                        switch app.Dictionary.PROPERTY{idx}
                                            case {'Text','Value','Title','Placeholder'}
                                                set(tag_components{i}, app.Dictionary.PROPERTY{idx}, app.Dictionary.ITALIAN{idx});
                                            case {'Items','ColumnName'}
                                                set(tag_components{i}, app.Dictionary.PROPERTY{idx}, strsplit(app.Dictionary.ITALIAN{idx},','));
                                        end
                                    case 'French'
                                        switch app.Dictionary.PROPERTY{idx}
                                            case {'Text','Value','Title','Placeholder'}
                                                set(tag_components{i}, app.Dictionary.PROPERTY{idx}, app.Dictionary.FRENCH{idx});
                                            case {'Items','ColumnName'}
                                                set(tag_components{i}, app.Dictionary.PROPERTY{idx}, strsplit(app.Dictionary.FRENCH{idx},','));
                                        end
                                end
                            end
                        end
                    end
                else % Only one specific tag
                    for i=1:length(varargin)
                        temp_tags{i}=findall(groot,'Tag',varargin{i});
                    end
                    % Change language for selected tags
                    for i=1:length(temp_tags)
                        idx=find(strcmp(app.Dictionary.TAG,temp_tags{i}.Tag));
                        switch language
                            case 'English'
                                switch app.Dictionary.PROPERTY{idx}
                                    case {'Text','Value','Title','Placeholder'}
                                        set(temp_tags{i}, app.Dictionary.PROPERTY{idx}, app.Dictionary.ENGLISH{idx});
                                    case {'Items','ColumnName'}
                                        set(temp_tags{i}, app.Dictionary.PROPERTY{idx}, strsplit(app.Dictionary.ENGLISH{idx},','));
                                end
                            case 'Spanish'
                                switch app.Dictionary.PROPERTY{idx}
                                    case {'Text','Value','Title','Placeholder'}
                                        set(temp_tags{i}, app.Dictionary.PROPERTY{idx}, app.Dictionary.SPANISH{idx});
                                    case {'Items','ColumnName'}
                                        set(temp_tags{i}, app.Dictionary.PROPERTY{idx}, strsplit(app.Dictionary.SPANISH{idx},','));
                                end
                            case 'Italian'
                                switch app.Dictionary.PROPERTY{idx}
                                    case {'Text','Value','Title','Placeholder'}
                                        set(temp_tags{i}, app.Dictionary.PROPERTY{idx}, app.Dictionary.ITALIAN{idx});
                                    case {'Items','ColumnName'}
                                        set(temp_tags{i}, app.Dictionary.PROPERTY{idx}, strsplit(app.Dictionary.ITALIAN{idx},','));
                                end
                            case 'French'
                                switch app.Dictionary.PROPERTY{idx}
                                    case {'Text','Value','Title','Placeholder'}
                                        set(temp_tags{i}, app.Dictionary.PROPERTY{idx}, app.Dictionary.FRENCH{idx});
                                    case {'Items','ColumnName'}
                                        set(temp_tags{i}, app.Dictionary.PROPERTY{idx}, strsplit(app.Dictionary.FRENCH{idx},','));
                                end
                        end
                    end
                end
            end
            % Set AttributesTable column name correctly
            aux_str=strsplit(string(app.AttributesTable.ColumnName),' (');
            app.AttributesTable.ColumnName=strcat(aux_str(1),' (',num2str(length(app.AttributesTable.Data)),')');
        end

        % Function that verify if reviews browsed corresponds to test
        % selected for analyse. Plot the right ones in ReviewsBrowser
        % and execute the results calculation
        function [] = check_review_protocol(app)
            % Plot selected reviews in ReviewBrowser
            if(~isempty(app.ReviewBrowser.Children))
                delete(app.ReviewBrowser.Children);
            end
            ReviewBrowserInfo=uitreenode(app.ReviewBrowser,"Text",app.review_path,"NodeData",[]);
            ReviewBrowserInfo.Tag=''; % Avoid losing path in language change
            j=0;
            files=[];
            for i=1:length(app.mats)
                aux_str=strsplit(app.mats(i),'-');
                if(strcmp(aux_str(1),app.review_test)) % Is a valid file
                    % Plot mat files in ReviewBrowser
                    j=j+1;
                    app.Subnodes_review(j)=uitreenode(ReviewBrowserInfo,'Text',app.mats(i),'NodeData',[]);
                    files=[files,app.mats(i)];
                else % Not valid file for selected test
                    warndlg(join([app.mats(i),'is not a',app.review_test,'review file']),'Incorrect test');
                end
            end
            % Clear previous results
            if(~isempty(app.ResultsTableGeneral.Data))
                app.ResultsTableGeneral.ColumnName=[];
                app.ResultsTableGeneral.Data=[];
            end
            if(~isempty(app.ResultsTableCombinationsR.Data))
                app.ResultsTableCombinationsR.ColumnName=[];
                app.ResultsTableCombinationsR.Data=[];
            end
            if(~isempty(app.ResultsAxes.Children))
                app.ResultsAxes.cla;
            end
            % Compute results
            if(~isempty(app.Subnodes_review))
                switch app.review_index
                    case 1 % Triang
                        %
                    case 2 % ABX
                        % Run test script
                        calc_ABX;
                        % Show results in table:
                        % ColumnNames
                        app.ResultsTableGeneral.Tag='ResultsTableDiscriminationGeneral';
                        app.ResultsTableCombinationsR.Tag='ResultsTableDiscriminationCombinations';
                        app.ResultsTableCombinationsG.Tag='ResultsTableDiscriminationCombinations';
                        change_language(app,app.languages.lang,{app.ResultsTableGeneral.Tag,app.ResultsTableCombinationsR.Tag})
                        % General
                        app.ResultsTableGeneral.Data=[0,string(round(ABX_Samples,0)),string(ABX_nReps),string(ABX_avg_Time)]; % Replace 0 with d' value
                        app.review_nWavs=f.num_wavs;
                        app.review_stimName=f.stim_name;
                        % Replications
                        for i=1:ABX_nReps
                            tableDataR.Column1=Evaluated_RCombs(:,:,i);
                            tableDataR.Column2=Correct_Replicates(i,:)';
                            tableDataR.Column4=Total_nRComb(i,:)';
                            tableDataR.Column3=ones(size(tableDataR.Column2)).*(tableDataR.Column4-tableDataR.Column2);
                            if(isempty(app.ResultsTableCombinationsR.Data))
                                app.ResultsTableCombinationsR.Data=struct2table(tableDataR);
                            else
                                auxT=struct2table(tableDataR);
                                app.ResultsTableCombinationsR.Data=[app.ResultsTableCombinationsR.Data;auxT];
                            end
                        end
                        app.ResultsTableCombinationsR.Data=[app.ResultsTableCombinationsR.Data(:,1:2),app.ResultsTableCombinationsR.Data(:,4),app.ResultsTableCombinationsR.Data(:,3)];
                        app.ResultsTableCombinationsR.Data=renamevars(app.ResultsTableCombinationsR.Data,["Column1","Column2","Column3","Column4"],app.ResultsTableCombinationsR.ColumnName');
                        % Global
                        tableDataG.Column1=Evaluated_GCombs;
                        tableDataG.Column2=Correct';
                        tableDataG.Column4=Total_nGComb';
                        tableDataG.Column3=ones(size(tableDataG.Column2)).*(tableDataG.Column4-tableDataG.Column2);
                        app.ResultsTableCombinationsG.Data=struct2table(tableDataG);
                        app.ResultsTableCombinationsG.Data=[app.ResultsTableCombinationsG.Data(:,1:2),app.ResultsTableCombinationsG.Data(:,4),app.ResultsTableCombinationsG.Data(:,3)];
                        app.ResultsTableCombinationsG.Data=renamevars(app.ResultsTableCombinationsG.Data,["Column1","Column2","Column3","Column4"],app.ResultsTableCombinationsG.ColumnName');
                        % Show results in axes:
                        % 
                    case 3 % CR DTF
                        % Run test script
                        calc_CRDTF;
                        % Show results in table:
                        % ColumnNames
                        app.ResultsTableGeneral.Tag='ResultsTableDiscriminationGeneral';
                        app.ResultsTableCombinationsR.Tag='ResultsTableDiscriminationCombinations';
                        app.ResultsTableCombinationsG.Tag='ResultsTableDiscriminationCombinations';
                        change_language(app,app.languages.lang,{app.ResultsTableGeneral.Tag,app.ResultsTableCombinationsR.Tag})
                        % General
                        app.ResultsTableGeneral.Data=[0,string(round(DTF_Samples,0)),string(DTF_nReps),string(DTF_avg_Time)]; % Replace 0 with d' value
                        app.review_nWavs=f.num_wavs;
                        app.review_stimName=f.stim_name;
                        % Replications
                        for i=1:DTF_nReps
                            tableDataR.Column1=Evaluated_RCombs(:,:,i);
                            tableDataR.Column2=Correct_Replicates(i,:)';
                            tableDataR.Column4=Total_nRComb(i,:)';
                            tableDataR.Column3=ones(size(tableDataR.Column2)).*(tableDataR.Column4-tableDataR.Column2);
                            if(isempty(app.ResultsTableCombinationsR.Data))
                                app.ResultsTableCombinationsR.Data=struct2table(tableDataR);
                            else
                                auxT=struct2table(tableDataR);
                                app.ResultsTableCombinationsR.Data=[app.ResultsTableCombinationsR.Data;auxT];
                            end
                        end
                        app.ResultsTableCombinationsR.Data=[app.ResultsTableCombinationsR.Data(:,1:2),app.ResultsTableCombinationsR.Data(:,4),app.ResultsTableCombinationsR.Data(:,3)];
                        app.ResultsTableCombinationsR.Data=renamevars(app.ResultsTableCombinationsR.Data,["Column1","Column2","Column3","Column4"],app.ResultsTableCombinationsR.ColumnName');
                        % Global
                        tableDataG.Column1=Evaluated_GCombs;
                        tableDataG.Column2=Correct';
                        tableDataG.Column4=Total_nGComb';
                        tableDataG.Column3=ones(size(tableDataR.Column2)).*(tableDataG.Column4-tableDataG.Column2);
                        app.ResultsTableCombinationsG.Data=struct2table(tableDataG);
                        app.ResultsTableCombinationsG.Data=[app.ResultsTableCombinationsG.Data(:,1:2),app.ResultsTableCombinationsG.Data(:,4),app.ResultsTableCombinationsG.Data(:,3)];
                        app.ResultsTableCombinationsG.Data=renamevars(app.ResultsTableCombinationsG.Data,["Column1","Column2","Column3","Column4"],app.ResultsTableCombinationsG.ColumnName');
                        % Show results in axes:
                        % 
                    case 4 % CR DTFM
                        %
                    case 5 % ANOTAR
                        %
                    case 6 % Same Difference
                        %
                    case 7 % CR SD
                        %
                    case 8 % 2AC
                        %
                    case 9 % Semantic Scale
                        %
                    case 10 % 1A/nA choice
                        %
                end
            end
            % Allow to get results
            app.ResultsExportButton.Enable='on';
            % Continue analysis workflow
            expand(app.ReviewBrowser,'all');
            % app.ReviewBrowser.Enable='off';
            app.BrowseReviewsButton.Enable='off';
        end
    
        function [] = finish_test(app)
            % Saving Review file
            save_review(app);
            % Ending app
            disp(strcat(['----------------------------------------',newline, ...
                char(datetime('now','Format','HH:mm:ss')),' - TEST IS COMPLETED AND SAVED',newline, ...
                '----------------------------------------']));
            closereq
        end


    end


    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app)
            % Start app
            setup(app);
            % Begin operation
            configurator(app);
        end

        % Menu selected function: SaveDesignMenu
        function SaveDesignMenuSelected(app, event)
            % Common variables
            language=app.languages.lang;
            test_name=app.test;
            stim_path={};
            if(~isempty(app.num_wavs))
                for i=1:length(app.StimBrowser.Children)
                    stim_path=[stim_path;{app.StimBrowser.Children(i).Text}];
                end
            end
            stim_files=app.stim_names;
            test_question=app.TestQuestion.Value;
            stim_extra=app.StimExtra.Value;
            wait_d={app.WaitingDialogCheckBox.Value,app.wait_seconds,app.wait_screens};
            if(strcmp(app.DiscriminationProtocolPanel.Visible,'on'))
                % Define variables to save
                replications=app.Replications.Value;
                sequence=app.SequencesSwitch.ValueIndex;
                vars={'language','test_name','stim_path','stim_files','test_question','wait_d','replications','stim_extra','sequence'};
                % Save variables to .mat file
                uisave(vars,strcat(pwd, '\Templates\', test_name,'-Template1.mat'));
            elseif(strcmp(app.ScaleProtocolPanel.Visible,'on'))
                % Define variables to save
                replications=app.Replications_2.Value;
                scale_points=app.ScalePointsSwitch.ValueIndex;
                scale_polarity=app.ScalePolaritySwitch.ValueIndex;
                switch app.test_index
                    case 9 % Semantic scale
                        test_type=app.ScaleTypeDropDown.ValueIndex;
                    case 10 % 1A/nA choice
                        test_type=app.AnswerTypeDropDown.ValueIndex;
                end
                attributes=app.AttributesTable.Data;
                vars={'language','test_name','stim_path','stim_files','test_question','wait_d','replications','stim_extra','scale_points','scale_polarity','test_type','attributes'};
                % Save variables to .mat file
                uisave(vars,strcat(pwd, '\Templates\', test_name,'-Template1.mat'));
            end
            % Avoid losing window focus
            app.DiTAAToolboxv20UIFigure.Visible='off';
            app.DiTAAToolboxv20UIFigure.Visible='on';
        end

        % Menu selected function: OpenDesignMenu
        function OpenDesignMenuSelected(app, event)
            % Search for template file
            [file,path]=uigetfile(strcat(pwd,'\Templates\*.mat'),'Select template');
            % Avoid losing window focus
            app.DiTAAToolboxv20UIFigure.Visible='off';
            app.DiTAAToolboxv20UIFigure.Visible='on';
            % Verify user selection
            if(~isequal(file,0)) % Cancel dialog
                % Avoid invalid files
                aux_file=strsplit(file,'.');
                if(strcmp(aux_file(2),'mat'))
                    % Load template file and common variables
                    template=load(strcat(path,'\',file));
                    language=template.language;
                    app.test=template.test_name;
                    stim_path=template.stim_path;
                    stim_files=template.stim_files;
                    app.StimExtra.Value=template.stim_extra;
                    app.WaitingDialogCheckBox.Value=template.wait_d{1};
                    app.wait_seconds=template.wait_d{2};
                    app.wait_screens=template.wait_d{3};
                    % Set language
                    change_language(app,language);
                    % Manage TestProtocolListBox
                    switch app.test
                        case 'Triang'
                            app.test_index=1; % 'Triangular'
                        case 'ABX'
                            app.test_index=2; % 'ABX'
                        case 'CR_DTF'
                            app.test_index=3; % 'CR DTF'
                        case 'CR_DTFM'
                            app.test_index=4; % 'CR DTFM'
                        case 'ANotA_R'
                            app.test_index=5; % 'A-Not AR'
                        case 'SD'
                            app.test_index=6; % 'Same-Diff'
                        case 'CR_SD'
                            app.test_index=7; % 'CR Same-Diff'
                        case '2AC'
                            app.test_index=8; % '2-AC'
                        case 'Sem_Scale'
                            app.test_index=9; % 'Semantic scale'
                        case '1A_nA'
                            app.test_index=10; % '1A_nA'
                    end
                    app.TestProtocolListBox.Value=app.TestProtocolListBox.Items(app.test_index);
                    % Set default values
                    configurator(app);
                    % Set template values
                    app.TestQuestion.Value=template.test_question;
                    app.TestQuestion.Tag='';
                    % Manage stimuli and StimBrowser
                    if(~isempty(stim_path))
                        delete(app.StimBrowser.Children);
                        app.Subnodes_stim=[];
                        for i=1:length(stim_path)
                            if(isfolder(stim_path{i})) % Get and plot stims in StimBrowser
                                uitreenode(app.StimBrowser,'Text',stim_path{i},'NodeData',[] );
                                not_file=[];
                                for j=1:length(stim_files)
                                    % Get only files that exists in computer stim path
                                    try
                                        a=audioread(strcat(stim_path{i},stim_files{i,j}));
                                        app.Subnodes_stim(i,j-length(not_file)) = uitreenode(app.StimBrowser.Children(i),'Text',stim_files{i,j},'NodeData',[] );
                                        app.stim_names{i,j-length(not_file)}=stim_files{i,j};
                                    catch
                                        not_file=[not_file,stim_files{i,j}];
                                        if(~isempty(stim_files{i,j}))
                                            warndlg(string([stim_files{i,j},' stimuli in ',stim_path{i},' is not possible to load']),'Missing stimuli');
                                        end
                                    end
                                end
                            elseif(~isfolder(stim_path{i}) && ~strcmp(stim_path{i},app.StimBrowser.Children(i).Text))
                                warndlg(string([stim_path,' does not exist in this computer']),'Inexistent path');
                            end
                        end
                    end
                    app.num_wavs=0;
                    for i=1:size(app.Subnodes_stim,1)
                        for j=1:size(app.Subnodes_stim,2)
                            if(app.Subnodes_stim(i,j)~=0)
                                app.num_wavs=app.num_wavs+1;
                            end
                        end
                    end
                    if(app.num_wavs==0)
                        app.num_wavs=[];
                    end
                    app.AddStimsButton.Enable='on';
                    % Apply panel specifics
                    if(app.test_index~=9 && app.test_index~=10)
                        % Set templates values in panel
                        app.Replications.Value=template.replications;
                        app.SequencesSwitch.Value=app.SequencesSwitch.Items(template.sequence);
                    else % Semantic scale & 1A_nA choice
                        % Set templates values in panel
                        app.Replications_2.Value=template.replications;
                        app.ScalePointsSwitch.Value=app.ScalePointsSwitch.Items(template.scale_points);
                        app.ScalePolaritySwitch.Value=app.ScalePolaritySwitch.Items(template.scale_polarity);
                        switch app.test_index
                            case 9 % Semantic scale
                                app.ScaleTypeDropDown.Value=app.ScaleTypeDropDown.Items(template.test_type);
                            case 10 % 1A/nA choice
                                app.AnswerTypeDropDown.Value=app.AnswerTypeDropDown.Items(template.test_type);
                        end
                        if(strcmp(app.ScaleTypeDropDown.Value,app.ScaleTypeDropDown.Items(2)))
                            app.ScalePointsSwitch.Enable='on';
                        end
                        app.AttributesTable.Data=template.attributes;
                    end
                    if(((app.test_index~=9 && app.test_index~=10) && ~isempty(app.num_wavs)) || ((app.test_index==9 || app.test_index==10) && ~isempty(app.AttributesTable.Data)))
                        app.PerformButton.Enable='on';
                        app.Lamp.Color=[0.00,1.00,0.00]; % Green
                    end
                    update_stim_values(app);
                else
                    warndlg('Only .mat files are supported','Invalid file extension');
                end
            end
        end

        % Menu selected function: CloseMenu
        function CloseMenuSelected(app, event)
            yn = uiconfirm(app.DiTAAToolboxv20UIFigure,'Do you want to close the app?', 'Close request');
            if(strcmp(yn,'OK'))
                app.DiTAAToolboxv20UIFigureCloseRequest(app);
            end
        end

        % Clicked callback: AnalyzeProtocolListBox
        function AnalyzeProtocolListBoxClicked(app, event)
            % If the user is going to select another review protocol,
            % avoid the app checking all time the files that are
            % already selected
            if(~isempty(app.ResultsTableGeneral.Data))
                app.mats=[];
                delete(app.ReviewBrowser.Children(1).Children);
            end
            % Allow to select new review files
            app.ReviewBrowser.Enable='on';
            app.BrowseReviewsButton.Enable='on';
            app.ReviewBrowser.Children(1).Tag='ReviewBrowserInfo2';
            change_language(app,app.languages.lang,app.ReviewBrowser.Children(1).Tag);
        end

        % Value changed function: AnalyzeProtocolListBox
        function AnalyzeProtocolListBoxValueChanged(app, event)
            [~, app.review_index]=find(strcmp(app.AnalyzeProtocolListBox.Items, app.AnalyzeProtocolListBox.Value));
            app.ResultsExportButton.Enable='off';
            switch app.review_index
                case 1 % Triangular
                    app.review_test='Triang';
                case 2 % ABX
                    app.review_test='ABX';
                case 3 % CR DTF
                    app.review_test='CR_DTF';
                case 4 % CR DTFM
                    app.review_test='CR_DTFM';
                case 5 % ANOTAR
                    app.review_test='ANotA_R';
                case 6 % Same Difference
                    app.review_test='SD';
                case 7 % CR SD
                    app.review_test='CR_SD';
                case 8 % 2AC
                    app.review_test='2AC';
                case 9 % Semantic scale
                    app.review_test='Sem_Scale';
                case 10 % 1A/nA choice
                    app.review_test='1A_nA';
            end
            if(~isempty(app.mats))
                check_review_protocol(app);
            end
        end

        % Button pushed function: BrowseReviewsButton
        function BrowseReviewsButtonPushed(app, event)
            % Open file finder
            app.review_path = uigetdir(strcat(pwd,'\Reviews\'),'Select reviews directory');
            % Avoid losing window focus
            app.DiTAAToolboxv20UIFigure.Visible='off';
            app.DiTAAToolboxv20UIFigure.Visible='on';
            % Verify user selection
            if(~isequal(app.review_path,0)) % Cancel dialog
                app.ResultsExportButton.Enable='off';
                files=dir(app.review_path);
                % Avoid invalid files
                app.mats=[];
                for i=1:length(files)
                    if(~isfolder(strcat(files(i).folder,'\',files(i).name)))
                        aux_str=strsplit(files(i).name,'.');
                        if(isempty(regexp(files(i).name,'^\.{1,}$')) && strcmp(aux_str(2),'mat'))
                            app.mats=[app.mats,string(files(i).name)];
                        else
                            if(isempty(regexp(files(i).name,'^\.{1,}$')))
                                warndlg(string([files(i).name,'has a not valid file extension']),'Invalid file type');
                            end
                        end
                    end
                end
                % Check files vs selected protocol
                if(~isempty(app.mats))
                    check_review_protocol(app);
                end
            end
        end

        % Button pushed function: ResultsExportButton
        function ResultsExportButtonPushed(app, event)
            % Save table data to .xlsx
            if(~isempty(app.ResultsTableGeneral.Data))
                % General
                generalT=[app.ResultsTableGeneral.ColumnName';app.ResultsTableGeneral.Data];
                stimM=[];
                for i=1:size(app.review_stimName,1)
                    aux_stim=string([app.review_stimName(i,~cellfun(@isempty,app.review_stimName(i,:)))]);
                    stimM=[stimM,aux_stim];
                end
                stimM=[stimM,['No. Stimuli: ',num2str(app.review_nWavs)]];
                % Browser
                [filename,path]=uiputfile(strcat(pwd,'\Analysis\','*.xlsx'),'Select directory and name for saving results');
                if(~isequal(path,0)) % Cancel browser
                    aux_filename=strsplit(filename,'.');
                    try
                        % Save file with different sheets
                        writematrix(generalT,string(strcat(path,aux_filename(1),'.xlsx')),'Sheet','General');
                        writematrix(stimM,string(strcat(path,aux_filename(1),'.xlsx')),'Sheet','Stimuli');
                        writetable(app.ResultsTableCombinationsR.Data,string(strcat(path,aux_filename(1),'.xlsx')),'Sheet','Combinations (Replication)');
                        writetable(app.ResultsTableCombinationsG.Data,string(strcat(path,aux_filename(1),'.xlsx')),'Sheet','Combinations (Global)');
                    catch e
                        warndlg(string([e.message]),'Saving issues')
                    end
                end
            else
                warndlg('There is no data to export','Results export');
            end
            % Save axes graph to .png
            if(~isempty(app.ResultsAxes.Children))
                if(~isequal(path,0))
                    exportgraphics(app.ResultsAxes,string(strcat(path,aux_filename(1),'.png')));
                    copygraphics(app.ResultsAxes);
                end
            else
                warndlg('There is no graphic to export','Results export');
            end
        end

        % Menu selected function: EnglishMenu
        function EnglishMenuSelected(app, event)
            % Save component values
            app.languages.balanceS=app.BalanceSwitch.ValueIndex;
            app.languages.familyS=app.FamilySwitch.ValueIndex;
            app.languages.sequencesS=app.SequencesSwitch.ValueIndex;
            app.languages.pointsS=app.ScalePointsSwitch.ValueIndex;
            app.languages.polarityS=app.ScalePolaritySwitch.ValueIndex;
            app.languages.typeDD=app.ScaleTypeDropDown.ValueIndex;
            app.languages.answerDD=app.AnswerTypeDropDown.ValueIndex;
            % Change app language
            if(~isempty(app.Dictionary))
                change_language(app,'English');
            end
            % Restore component values
            app.BalanceSwitch.Value=app.BalanceSwitch.Items(app.languages.balanceS);
            app.FamilySwitch.Value=app.FamilySwitch.Items(app.languages.familyS);
            app.SequencesSwitch.Value=app.SequencesSwitch.Items(app.languages.sequencesS);
            app.ScalePointsSwitch.Value=app.ScalePointsSwitch.Items(app.languages.pointsS);
            app.ScalePolaritySwitch.Value=app.ScalePolaritySwitch.Items(app.languages.polarityS);
            app.ScaleTypeDropDown.Value=app.ScaleTypeDropDown.Items(app.languages.typeDD);
            app.AnswerTypeDropDown.Value=app.AnswerTypeDropDown.Items(app.languages.answerDD);
        end

        % Menu selected function: SpanishMenu
        function SpanishMenuSelected(app, event)
            % Save component values
            app.languages.balanceS=app.BalanceSwitch.ValueIndex;
            app.languages.familyS=app.FamilySwitch.ValueIndex;
            app.languages.sequencesS=app.SequencesSwitch.ValueIndex;
            app.languages.pointsS=app.ScalePointsSwitch.ValueIndex;
            app.languages.polarityS=app.ScalePolaritySwitch.ValueIndex;
            app.languages.typeDD=app.ScaleTypeDropDown.ValueIndex;
            app.languages.answerDD=app.AnswerTypeDropDown.ValueIndex;
            % Change app language
            if(~isempty(app.Dictionary))
                change_language(app,'Spanish');
            end
            % Restore component values
            app.BalanceSwitch.Value=app.BalanceSwitch.Items(app.languages.balanceS);
            app.FamilySwitch.Value=app.FamilySwitch.Items(app.languages.familyS);
            app.SequencesSwitch.Value=app.SequencesSwitch.Items(app.languages.sequencesS);
            app.ScalePointsSwitch.Value=app.ScalePointsSwitch.Items(app.languages.pointsS);
            app.ScalePolaritySwitch.Value=app.ScalePolaritySwitch.Items(app.languages.polarityS);
            app.ScaleTypeDropDown.Value=app.ScaleTypeDropDown.Items(app.languages.typeDD);
            app.AnswerTypeDropDown.Value=app.AnswerTypeDropDown.Items(app.languages.answerDD);
        end

        % Menu selected function: ItalianMenu
        function ItalianMenuSelected(app, event)
            % Save component values
            app.languages.balanceS=app.BalanceSwitch.ValueIndex;
            app.languages.familyS=app.FamilySwitch.ValueIndex;
            app.languages.sequencesS=app.SequencesSwitch.ValueIndex;
            app.languages.pointsS=app.ScalePointsSwitch.ValueIndex;
            app.languages.polarityS=app.ScalePolaritySwitch.ValueIndex;
            app.languages.typeDD=app.ScaleTypeDropDown.ValueIndex;
            app.languages.answerDD=app.AnswerTypeDropDown.ValueIndex;
            % Change app language
            if(~isempty(app.Dictionary))
                change_language(app,'Italian');
            end
            % Restore component values
            app.BalanceSwitch.Value=app.BalanceSwitch.Items(app.languages.balanceS);
            app.FamilySwitch.Value=app.FamilySwitch.Items(app.languages.familyS);
            app.SequencesSwitch.Value=app.SequencesSwitch.Items(app.languages.sequencesS);
            app.ScalePointsSwitch.Value=app.ScalePointsSwitch.Items(app.languages.pointsS);
            app.ScalePolaritySwitch.Value=app.ScalePolaritySwitch.Items(app.languages.polarityS);
            app.ScaleTypeDropDown.Value=app.ScaleTypeDropDown.Items(app.languages.typeDD);
            app.AnswerTypeDropDown.Value=app.AnswerTypeDropDown.Items(app.languages.answerDD);
        end

        % Menu selected function: FrenchMenu
        function FrenchMenuSelected(app, event)
            % Save component values
            app.languages.balanceS=app.BalanceSwitch.ValueIndex;
            app.languages.familyS=app.FamilySwitch.ValueIndex;
            app.languages.sequencesS=app.SequencesSwitch.ValueIndex;
            app.languages.pointsS=app.ScalePointsSwitch.ValueIndex;
            app.languages.polarityS=app.ScalePolaritySwitch.ValueIndex;
            app.languages.typeDD=app.ScaleTypeDropDown.ValueIndex;
            app.languages.answerDD=app.AnswerTypeDropDown.ValueIndex;
            % Change app language
            if(~isempty(app.Dictionary))
                change_language(app,'French');
            end
            % Restore component values
            app.BalanceSwitch.Value=app.BalanceSwitch.Items(app.languages.balanceS);
            app.FamilySwitch.Value=app.FamilySwitch.Items(app.languages.familyS);
            app.SequencesSwitch.Value=app.SequencesSwitch.Items(app.languages.sequencesS);
            app.ScalePointsSwitch.Value=app.ScalePointsSwitch.Items(app.languages.pointsS);
            app.ScalePolaritySwitch.Value=app.ScalePolaritySwitch.Items(app.languages.polarityS);
            app.ScaleTypeDropDown.Value=app.ScaleTypeDropDown.Items(app.languages.typeDD);
            app.AnswerTypeDropDown.Value=app.AnswerTypeDropDown.Items(app.languages.answerDD);
        end

        % Menu selected function: TestPerformMenu
        function TestPerformMenuSelected(app, event)
            % Change window and menu visualization
            app.Window.SelectedTab=app.Setup;
            app.TestPerformMenu.Checked=1;
            app.AnalyzerMenu.Checked=0;
            % Change tab text
            if(~isempty(app.Dictionary))
                app.Setup.Tag='SetupTab';
                app.Analyzer.Tag='BlankTitle';
                change_language(app,app.languages.lang,{app.Setup.Tag,app.Analyzer.Tag});
            else
                app.Setup.Title='Setup';
                app.Analyzer.Title='';
            end
        end

        % Menu selected function: AnalyzerMenu
        function AnalyzerMenuSelected(app, event)
            % Change window and menu visualization
            app.Window.SelectedTab=app.Analyzer;
            app.TestPerformMenu.Checked=0;
            app.AnalyzerMenu.Checked=1;
            % Change tab text
            if(~isempty(app.Dictionary))
                app.Setup.Tag='BlankTitle';
                app.Analyzer.Tag='AnalyzerTab';
                change_language(app,app.languages.lang,{app.Setup.Tag,app.Analyzer.Tag});
            else
                app.Setup.Title='';
                app.Analyzer.Title='Analyzer';
            end
        end

        % Selection change function: Window
        function WindowSelectionChanged(app, event)
            % Block tab navegation
            if(event.OldValue==app.Setup)
                app.Window.SelectedTab=app.Setup;
            else
                app.Window.SelectedTab=event.OldValue;
            end
        end

        % Button pushed function: BrowseStimsButton
        function BrowseStimsButtonPushed(app, event)
            % Open file finder
            extensions = {...
                '*.aifc;*.aiff;*.aif;*.au;*.flac;*.ogg;*.opus;*.wav', 'All platforms';...
                '*.mp3;*.mp4', 'Windows 7 or later, Macintosh and Linux';...
                '*.wmv;*.avi', 'Windows 7 or later and Linux';...
                '*.*', 'All files'};
            [file,path] = uigetfile(extensions,'MultiSelect','on', 'Select stimuli');
            % Avoid losing window focus
            app.DiTAAToolboxv20UIFigure.Visible='off';
            app.DiTAAToolboxv20UIFigure.Visible='on';
            % Verify user selection
            wavs={};
            if(~isequal(file,0)) % Cancel dialog
                if(ischar(file)) % Only one file selected
                    file=cellstr(file);
                end
                % Avoid invalid files
                for i=1:length(file)
                    try
                        a=audioread(strcat(path,'\',file{i}));
                        wavs(i)=file(i);
                    catch e
                        warndlg(string(['Issues with ',file{i},': ',e.message]),'Invalid file extension');
                    end
                end
                % Work only with valid files
                wavs=wavs(~cellfun(@isempty,wavs));
                app.num_wavs=size(wavs,2);
            end
            if(~isempty(wavs))
                if(~isempty(app.StimBrowser.Children))
                    delete(app.StimBrowser.Children);
                end
                % Plot selected wavs in StimBrowser
                StimBrowserInfo=uitreenode(app.StimBrowser,"Text",path,"NodeData",[]);
                StimBrowserInfo.Tag=''; % Avoid losing path in language change
                for i=1:app.num_wavs
                    app.Subnodes_stim(i) = uitreenode(StimBrowserInfo,'Text',wavs{i},'NodeData',[]);
                end
                app.stim_names=wavs;
                % Manage next screen info
                if((app.test_index~=9 && app.test_index~=10) || ((app.test_index==9 || app.test_index==10) && ~isempty(app.AttributesTable.Data)))
                    app.PerformButton.Enable='on';
                    app.Lamp.Color=[0.00,1.00,0.00]; % Green
                end
                app.AddStimsButton.Enable='on';
                update_stim_values(app);
            end
        end

        % Button pushed function: AddStimsButton
        function AddStimsButtonPushed(app, event)
            % Open file finder
            extensions = {...
                '*.aifc;*.aiff;*.aif;*.au;*.flac;*.ogg;*.opus;*.wav', 'All platforms';...
                '*.mp3;*.mp4', 'Windows 7 or later, Macintosh and Linux';...
                '*.wmv;*.avi', 'Windows 7 or later and Linux';...
                '*.*', 'All files'};
            [file,path] = uigetfile(extensions,'MultiSelect','on', 'Select stimuli');
            % Avoid losing window focus
            app.DiTAAToolboxv20UIFigure.Visible='off';
            app.DiTAAToolboxv20UIFigure.Visible='on';
            % Verify user selection
            wavs={};
            if(~isequal(file,0)) % Cancel dialog
                if(ischar(file)) % Only one file selected
                    file=cellstr(file);
                end
                % Avoid invalid files
                for i=1:length(file)
                    try
                        a=audioread(strcat(path,'\',file{i}));
                        wavs(i)=file(i);
                    catch e
                        warndlg(string(['Issues with ',file{i},': ',e.message]),'Invalid file extension');
                    end
                end
                % Work only with valid files
                wavs=wavs(~cellfun(@isempty,wavs));
                app.num_wavs=app.num_wavs+size(wavs,2);
            end
            if(isempty(wavs))
                errordlg('Please, select at least one valid stimuli','Error retrieving stimuli');
            else
                % Find if new path is already a tree subnode
                same_path=0;
                for i=1:length(app.StimBrowser.Children)
                    if(strcmp(app.StimBrowser.Children(i).Text,path))
                        same_path=1;
                        n_subnode=i;
                        n_childs=length(app.StimBrowser.Children(i).Children);
                    end
                end
                % Plot selected wavs in StimBrowser and update stim
                % vector
                if(~same_path) % New stim lines
                    % Create new tree subnode
                    StimBrowserInfo=uitreenode(app.StimBrowser,"Text",path,"NodeData",[]);
                    % Append files to tree subnode
                    for i=1:size(wavs,2)
                        app.Subnodes_stim(i) = uitreenode(StimBrowserInfo,'Text',wavs{i},'NodeData',[]);
                        app.stim_names{length(app.StimBrowser.Children),i}=wavs{i};
                    end
                else % Append new stims
                    for i=1:size(wavs,2)
                        exist_file=0;
                        for j=1:n_childs
                            if(strcmp(wavs{i},app.StimBrowser.Children(n_subnode).Children(j).Text))
                                % Avoid add same file more than once
                                exist_file=1;
                                app.num_wavs=app.num_wavs-1;
                            end
                        end
                        if(~exist_file)
                            % Append files to tree subnode
                            app.Subnodes_stim(length(app.StimBrowser.Children(n_subnode).Children)+i) = ...
                                uitreenode(app.StimBrowser.Children(n_subnode),'Text',wavs{i},'NodeData',[]);
                            app.stim_names{n_subnode,n_childs+i}=wavs{i};
                        end
                    end
                end
                % app.StimBrowserInfo.Tag=''; % Avoid losing path in language change
                update_stim_values(app);
            end
        end

        % Button pushed function: RemoveStimsButton
        function RemoveStimsButtonPushed(app, event)
            % Catch selected nodes and decrement num wavs
            selected_nodes=app.StimBrowser.SelectedNodes;
            app.num_wavs=app.num_wavs-length(selected_nodes);
            if(app.num_wavs==0)
                app.num_wavs=[];
            end
            % Delete selected nodes from stimuli array and tree plot
            for k=length(selected_nodes):-1:1
                deleted=0;
                for i=1:length(app.StimBrowser.Children)
                    for j=length(app.StimBrowser.Children(i).Children):-1:1
                        if(strcmp(selected_nodes(k).Text,app.StimBrowser.Children(i).Children(j).Text))
                            app.stim_names{i,j}=[];
                            delete(app.StimBrowser.Children(i).Children(j));
                            deleted=1;
                            break;
                        end
                    end
                    if(deleted)
                        break;
                    end
                end
            end
            % Delete empty tree nodes
            for i=length(app.StimBrowser.Children):-1:1
                if(isempty(app.StimBrowser.Children(i).Children)) % Tree node childs are empty
                    if(length(app.StimBrowser.Children)==1) % Last tree node
                        if(~isempty(app.Dictionary))
                            app.StimBrowser.Children(i).Tag='StimBrowserInfo';
                            change_language(app,app.languages.lang,app.StimBrowser.Children(i).Tag);
                        else
                            app.StimBrowser.Children(i).Text='Browse to select auditory stimuli';
                        end
                    else % Not last tree node
                        delete(app.StimBrowser.Children(i));
                    end
                end
            end
            % Manage next screen info and browser buttons
            if(length(app.StimBrowser.Children)==1 && isempty(app.StimBrowser.Children(1).Children))
                app.AddStimsButton.Enable='off';
                app.PerformButton.Enable='off';
                app.Lamp.Color=[1.00,0.00,0.00]; % Red
            end
            update_stim_values(app);
        end

        % Button pushed function: ClearStimsButton
        function ClearStimsButtonPushed(app, event)
            % Delete all tree nodes
            for i=length(app.StimBrowser.Children):-1:1
                delete(app.StimBrowser.Children(i));
            end
            % Reset app values
            StimBrowserInfo=uitreenode(app.StimBrowser,"NodeData",[]);
            if(~isempty(app.Dictionary))
                StimBrowserInfo.Tag='StimBrowserInfo';
                change_language(app,app.languages.lang,StimBrowserInfo.Tag);
            else
                StimBrowserInfo.Text='Browse to select auditory stimuli';
            end
            app.stim_names={};
            app.num_wavs=[];
            app.AddStimsButton.Enable='off';
            % Next screen info
            app.PerformButton.Enable='off';
            app.Lamp.Color=[1.00,0.00,0.00]; % Red
            update_stim_values(app);
        end

        % Value changed function: TestProtocolListBox
        function TestProtocolListBoxValueChanged(app, event)
            [~, app.test_index]=find(strcmp(app.TestProtocolListBox.Items, app.TestProtocolListBox.Value));
            configurator(app);
            update_stim_values(app);
        end

        % Value changed function: TestQuestion
        function TestQuestionValueChanged(app, event)
            % Avoid question default when user changes it
            app.TestQuestion.Tag='';
        end

        % Button pushed function: AddAttributesButton
        function AddAttributesButtonPushed(app, event)
            % Ask for attributes
            app.input_att = inputdlg('Enter space-separated attributes: ','Add attributes',[1 45]);
            if(~isempty(app.input_att) && length(app.input_att{1})>1)
                % Get input data, avoiding blankspaces
                att=strsplit(regexprep(strtrim(num2str(cell2mat(app.input_att))),'\s+',' '),' ')';
                if((length(att)+length(app.AttributesTable.Data))<=10) % Not adding more than 10 attributes
                    if(all(cellfun(@isempty,num2cell(app.AttributesTable.Data)))) % If table is empty
                        % Add attributes to table
                        app.AttributesTable.Data=att;
                    else
                        % Append attributes to table
                        app.AttributesTable.Data=vertcat(app.AttributesTable.Data,att);
                    end
                    % Next screen info
                    if(~isempty(app.Subnodes_stim))
                        app.PerformButton.Enable='on';
                        app.Lamp.Color=[0.00,1.00,0.00]; % Green
                    end
                else
                    % Launching error dialog
                    errordlg('Please, do not add more than 10 attributes.','Too many attributes');
                end
                % Update display number of attributes
                update_stim_values(app);
            end
        end

        % Button pushed function: RemoveAttributeButton
        function RemoveAttributeButtonPushed(app, event)
            % Find selected cell(s)
            selected_cell=app.AttributesTable.Selection;
            % Delete cell(s) and update table data
            data=app.AttributesTable.Data;
            for i=1:size(selected_cell,1)
                data{selected_cell(i,1),selected_cell(i,2)}='';
                app.AttributesTable.Data=rmmissing(data,1);
            end
            if(isempty(app.AttributesTable.Data))
                app.StimTot_2.Value=num2str([]);
                % Next screen info
                app.PerformButton.Enable='off';
                app.Lamp.Color=[1.00,0.00,0.00]; % Red
            end
            % Update display number of attributes
            update_stim_values(app);
        end

        % Button pushed function: ClearAttributesButton
        function ClearAttributesButtonPushed(app, event)
            % Clear table and preferences
            app.AttributesTable.Data=[];
            app.input_att=[];
            app.StimTot_2.Value=num2str([]);
            % Next screen info
            app.PerformButton.Enable='off';
            app.Lamp.Color=[1.00,0.00,0.00]; % Red
            % Update display number of attributes
            update_stim_values(app);
        end

        % Value changed function: WaitingDialogCheckBox
        function WaitingDialogCheckBoxValueChanged(app, event)
            if(app.WaitingDialogCheckBox.Value==true)
                % Setup modal dlg
                prompt = {'Enter wait time (s):','Enter number of screens:'};
                dlgtitle = 'Waiting dialog parameters';
                fieldsize = [1 45; 1 45];
                definput = {app.wait_seconds,app.wait_screens};
                answer = inputdlg(prompt,dlgtitle,fieldsize,definput);
                % Verify user answer
                if(~isempty(answer))
                    app.wait_seconds=answer{1};
                    app.wait_screens=answer{2};
                    if(isempty(regexp(answer{1},'^\d{1,}$', 'once')) || ...
                       isempty(regexp(answer{2},'^\d{1,}$', 'once'))) % If answer is not only a number
                        app.WaitingDialogCheckBox.Value=false;
                        app.wait_seconds='30';
                        app.wait_screens='5';
                    end
                else % Cancel input
                    app.WaitingDialogCheckBox.Value=false;
                end
            end
        end

        % Button pushed function: PerformButton
        function PerformButtonPushed(app, event)
            if(app.num_wavs<app.min_wavs)
                % Launching error dialog
                errordlg(string(['You need al least ',num2str(app.min_wavs),' stimulis.']),'No stimuli');
            else
                % Setup up test requirements
                app.counter=1;
                app.stimuli={};
                app.fs={};
                app.comb_random=[];
                app.FileMenu.Enable='off';
                app.ViewMenu.Enable='off';
                app.LanguagesMenu.Enable='off';
                app.HelpMenu.Enable='off';
                % Audio files and sampling frecuencies
                aux_stimuli={};
                aux_fs={};
                for i=1:length(app.StimBrowser.Children)
                    for j=1:length(app.StimBrowser.Children(i).Children)
                        [aux_stimuli,aux_fs]=audioread(strcat(app.StimBrowser.Children(i).Text,app.StimBrowser.Children(i).Children(j).Text));
                        app.stimuli=[app.stimuli,aux_stimuli];
                        app.fs=[app.fs,aux_fs];
                    end
                end
                % Each case identifies one different protocol
                switch app.test_index
                    case 1 % TRIANG
                        % Setup tab
                        app.Window.SelectedTab=app.Triple;
                        app.Title3.Text=app.TestQuestion.Value;
                        % app.Title3.Tag='TriangQuestionL';
                        if(~isempty(app.Dictionary))
                            app.A3.Tag='AButton';
                            app.B3.Tag='BButton';
                            app.B3.Enable='off';
                            app.C3.Tag='CButton';
                            app.C3.Enable='off';
                            app.BG3_B1.Tag='AButton';
                            app.BG3_B1.Enable='off';
                            app.BG3_B2.Tag='BButton';
                            app.BG3_B3.Enable='off';
                            app.BG3_B3.Tag='CButton';
                            app.BG3_B2.Enable='off';
                            if(str2double(app.StimTot.Value)==str2double(app.StimTest.Value))
                                app.NextButton3.Tag='FinishButton';
                                change_language(app,app.languages.lang,app.NextButton3.Tag);
                            end
                            app.NextButton3.Enable='off';
                            change_language(app,app.languages.lang,{'AButton','BButton','CButton'});
                        else % Standard button assign
                            app.A3.Text='A';
                            app.B3.Text='B';
                            app.B3.Enable='off';
                            app.C3.Text='C';
                            app.C3.Enable='off';
                            app.BG3_B1.Text='A';
                            app.BG3_B1.Enable='off';
                            app.BG3_B2.Text='B';
                            app.BG3_B3.Enable='off';
                            app.BG3_B3.Text='C';
                            app.BG3_B2.Enable='off';
                            if(str2double(app.StimTot.Value)==str2double(app.StimTest.Value))
                                app.NextButton3.Text='Finish';
                            end
                            app.NextButton3.Enable='off';
                        end
                        try
                            % Manage bucketlist for future assesors combs
                            load('Bucket.mat');
                            tam=max(size(bucketlist));
                            sel_repetitions=randperm(tam,4);
                            app.comb_random_rep=app.comb_TRIANG(bucketlist(sel_repetitions),:);
                            bucketlist(sel_repetitions)=[];
                            if(isempty(bucketlist))
                                bucketlist=1:1:12;
                                save('Bucket.mat','bucketlist');
                            else
                                save('Bucket.mat','bucketlist');
                            end
                        catch e
                            % Show error like warning
                            warning(string(['Bucket: ',e.message]));
                        end
                        % Define replications sequence from combs,
                        % randomized
                        app.comb_TRIANG=repmat(app.comb_TRIANG,1,str2double(app.Replications.Value));
                        app.comb_TRIANG=[app.comb_TRIANG; app.comb_random_rep];
                        app.comb_random=app.comb_TRIANG(randperm(size(app.comb_TRIANG,1)),:); % size(max(...))
                    case 2 % ABX
                        % Setup tab
                        app.Window.SelectedTab=app.Triple;
                        app.Title3.Text=app.TestQuestion.Value;
                        if(~isempty(app.Dictionary))
                            app.A3.Tag='AButton';
                            app.B3.Tag='BButton';
                            app.B3.Enable='off';
                            app.C3.Tag='XButton';
                            app.C3.Enable='off';
                            app.BG3_B1.Tag='AButton';
                            app.BG3_B1.Enable='off';
                            app.BG3_B3.Tag='BButton';
                            app.BG3_B3.Enable='off';
                            app.BG3_B2.Enable='off';
                            app.BG3_B2.Visible='off';
                            if(str2double(app.StimTot.Value)==str2double(app.StimTest.Value))
                                app.NextButton3.Tag='FinishButton';
                                change_language(app,app.languages.lang,app.NextButton3.Tag);
                            end
                            app.NextButton3.Enable='off';
                            change_language(app,app.languages.lang,{'AButton','BButton','XButton'});
                        else
                            app.A3.Text='A';
                            app.B3.Text='B';
                            app.B3.Enable='off';
                            app.C3.Text='X';
                            app.C3.Enable='off';
                            app.BG3_B1.Text='A';
                            app.BG3_B1.Enable='off';
                            app.BG3_B3.Text='B';
                            app.BG3_B3.Enable='off';
                            app.BG3_B2.Enable='off';
                            app.BG3_B2.Visible='off';
                            if(str2double(app.StimTot.Value)==str2double(app.StimTest.Value))
                                app.NextButton3.Text='Finish';
                            end
                            app.NextButton3.Enable='off';
                        end
                        % Define replications sequence from combs,
                        % randomized
                        for i=1:str2double(app.Replications.Value)
                            comb_temp(:,:,i)=app.comb_ABX;
                            comb_random_temp(:,:,i)=comb_temp(randperm(size(comb_temp(:,:,i),1)),:,i); % size(max(...))
                            app.comb_random=vertcat(app.comb_random,comb_random_temp(:,:,i));
                        end
                    case 3 % CR DTF
                        % Setup tab
                        app.Window.SelectedTab=app.Triple;
                        app.Title3.Text=app.TestQuestion.Value;
                        if(~isempty(app.Dictionary))
                            app.A3.Tag='RefButton';
                            app.B3.Tag='AButton';
                            app.B3.Enable='off';
                            app.C3.Tag='BButton';
                            app.C3.Enable='off';
                            app.BG3_B1.Tag='AButton';
                            app.BG3_B1.Enable='off';
                            app.BG3_B3.Tag='BButton';
                            app.BG3_B3.Enable='off';
                            app.BG3_B2.Enable='off';
                            app.BG3_B2.Visible='off';
                            if(str2double(app.StimTot.Value)==str2double(app.StimTest.Value))
                                app.NextButton3.Tag='FinishButton';
                                change_language(app,app.languages.lang,app.NextButton3.Tag);
                            end
                            app.NextButton3.Enable='off';
                            change_language(app,app.languages.lang,{'AButton','BButton','RefButton'});
                        else
                            app.A3.Text='Ref.';
                            app.B3.Text='A';
                            app.B3.Enable='off';
                            app.C3.Text='B';
                            app.C3.Enable='off';
                            app.BG3_B1.Text='A';
                            app.BG3_B1.Enable='off';
                            app.BG3_B3.Text='B';
                            app.BG3_B3.Enable='off';
                            app.BG3_B2.Enable='off';
                            app.BG3_B2.Visible='off';
                            if(str2double(app.StimTot.Value)==str2double(app.StimTest.Value))
                                app.NextButton3.Text='Finish';
                            end
                            app.NextButton3.Enable='off';
                        end                
                        % Define replications sequence from combs,
                        % randomized
                        for i=1:str2double(app.Replications.Value)
                            comb_temp(:,:,i)=app.comb_DTF;
                            comb_random_temp(:,:,i)=comb_temp(randperm(size(comb_temp(:,:,i),1)),:,i);
                            app.comb_random=vertcat(app.comb_random,comb_random_temp(:,:,i));
                        end
                    case 4 % CR DTFM
                        % Setup tab
                        app.Window.SelectedTab=app.Quad;
                        app.Title4.Text=app.TestQuestion.Value;
                        if(~isempty(app.Dictionary))
                            app.B4.Tag='AButton';
                            app.B4.Enable='off';
                            app.C4.Tag='RefButton';
                            app.C4.Enable='off';
                            app.D4.Tag='BButton';
                            app.D4.Enable='off';
                            app.BG4_B1.Tag='AButton';
                            app.BG4_B1.Enable='off';
                            app.BG4_B2.Tag='BButton';
                            app.BG4_B2.Enable='off';
                            if(str2double(app.StimTot.Value)==str2double(app.StimTest.Value))
                                app.NextButton4.Tag='FinishButton';
                                change_language(app,app.languages.lang,app.NextButton4.Tag);
                            end
                            app.NextButton4.Enable='off';
                            change_language(app,app.languages.lang,{'AButton','BButton','RefButton'});
                        else
                            app.B4.Text='A';
                            app.B4.Enable='off';
                            app.C4.Text='Ref.';
                            app.C4.Enable='off';
                            app.D4.Text='B';
                            app.D4.Enable='off';
                            app.BG4_B1.Text='A';
                            app.BG4_B1.Enable='off';
                            app.BG4_B2.Text='B';
                            app.BG4_B2.Enable='off';
                            if(str2double(app.StimTot.Value)==str2double(app.StimTest.Value))
                                app.NextButton4.Text='Finish';
                            end
                            app.NextButton4.Enable='off';
                        end
                        app.A4.Text='Ref.';
                        % Define replications sequence from combs,
                        % randomized
                        for i=1:str2double(app.Replications.Value)
                            comb_temp(:,:,i)=app.comb_DTFM;
                            comb_random_temp(:,:,i)=comb_temp(randperm(size(comb_temp(:,:,i),1)),:,i);
                            app.comb_random=vertcat(app.comb_random,comb_random_temp(:,:,i));
                        end
                    case 5 % ANOTAR
                        % Setup tab
                        app.Window.SelectedTab=app.Double;
                        app.Title2.Text=app.TestQuestion.Value;
                        if(~isempty(app.Dictionary))
                            app.A2.Tag='RefButton';
                            app.B2.Tag='BButton';
                            app.B2.Enable='off';
                            %app.C2.Enable='off';
                            app.BG2_B1.Tag='RefButton';
                            % app.AnswerButtonGroup_2.Buttons(1).Text='Ref.';
                            app.BG2_B1.Enable='off';
                            app.BG2_B2.Tag='NRefButton';
                            % findobj(app.AnswerButtonGroup_2,'Type','uibutton','Text','Button2').Text='No ref.';
                            app.BG2_B2.Enable='off';
                            if(str2double(app.StimTot.Value)==str2double(app.StimTest.Value))
                                app.NextButton2.Tag='FinishButton';
                                change_language(app,app.languages.lang,app.NextButton2.Tag);
                            end
                            app.NextButton2.Enable='off';
                            change_language(app,app.languages.lang,{'BButton','RefButton','NRefButton'});
                        else
                            app.A2.Text='Ref.';
                            app.B2.Text='B';
                            app.B2.Enable='off';
                            %app.C2.Enable='off';
                            app.BG2_B1.Text='Ref.';
                            % app.AnswerButtonGroup_2.Buttons(1).Text='Ref.';
                            app.BG2_B1.Enable='off';
                            app.BG2_B2.Text='No Ref.';
                            % findobj(app.AnswerButtonGroup_2,'Type','uibutton','Text','Button2').Text='No ref.';
                            app.BG2_B2.Enable='off';
                            if(str2double(app.StimTot.Value)==str2double(app.StimTest.Value))
                                app.NextButton2.Text='Finish';
                            end
                            app.NextButton2.Enable='off';
                        end
                        % Define replications sequence from combs,
                        % randomized
                        for i=1:str2double(app.Replications.Value)
                            comb_temp(:,:,i)=app.comb_ANOTAR;
                            comb_random_temp(:,:,i)=comb_temp(randperm(size(comb_temp(:,:,i),1)),:,i);
                            app.comb_random=vertcat(app.comb_random,comb_random_temp(:,:,i));
                        end
                    case 6 % SD
                        % Setup tab
                        app.Window.SelectedTab=app.Double;
                        app.Title2.Text=app.TestQuestion.Value;
                        if(~isempty(app.Dictionary))
                            app.A2.Tag='AButton';
                            app.B2.Tag='BButton';
                            app.B2.Enable='off';
                            %app.C2.Enable='off';
                            app.BG2_B1.Tag='SameButton';
                            app.BG2_B1.Enable='off';
                            app.BG2_B2.Tag='DifButton';
                            app.BG2_B2.Enable='off';
                            if(str2double(app.StimTot.Value)==str2double(app.StimTest.Value))
                                app.NextButton2.Tag='FinishButton';
                                change_language(app,app.languages.lang,app.NextButton2.Tag);
                            end
                            app.NextButton2.Enable='off';
                            change_language(app,app.languages.lang,{'AButton','BButton','SameButton','DifButton'});
                        else
                            app.A2.Text='A';
                            app.B2.Text='B';
                            app.B2.Enable='off';
                            %app.C2.Enable='off';
                            app.BG2_B1.Text='Same';
                            app.BG2_B1.Enable='off';
                            app.BG2_B2.Text='Different';
                            app.BG2_B2.Enable='off';
                            if(str2double(app.StimTot.Value)==str2double(app.StimTest.Value))
                                app.NextButton2.Text='Finish';
                            end
                            app.NextButton2.Enable='off';
                        end
                        % Define replications sequence from combs,
                        % randomize the final output
                        for i=1:str2double(app.Replications.Value)
                            comb_temp(:,:,i)=app.comb_SAMEDIFF;
                            comb_random_temp(:,:,i)=comb_temp(randperm(size(comb_temp(:,:,i),1)),:,i);
                            app.comb_random=vertcat(app.comb_random,comb_random_temp(:,:,i));
                        end
                    case 7 % CR SD
                        % Setup tab
                        app.Window.SelectedTab=app.Double;
                        app.Title2.Text=app.TestQuestion.Value;
                        if(~isempty(app.Dictionary))
                            app.A2.Tag='AButton';
                            app.B2.Tag='BButton';
                            app.B2.Enable='off';
                            %app.C2.Enable='off';
                            app.BG2_B1.Tag='SameButton';
                            app.BG2_B1.Enable='off';
                            app.BG2_B2.Tag='DifButton';
                            app.BG2_B2.Enable='off';
                            if(str2double(app.StimTot.Value)==str2double(app.StimTest.Value))
                                app.NextButton2.Tag='FinishButton';
                                change_language(app,app.languages.lang,app.NextButton2.Tag);
                            end
                            app.NextButton2.Enable='off';
                            change_language(app,app.languages.lang,{'AButton','BButton','SameButton','DifButton'});
                        else
                            app.A2.Text='A';
                            app.B2.Text='B';
                            app.B2.Enable='off';
                            %app.C2.Enable='off';
                            app.BG2_B1.Text='Same';
                            app.BG2_B1.Enable='off';
                            app.BG2_B2.Text='Different';
                            app.BG2_B2.Enable='off';
                            if(str2double(app.StimTot.Value)==str2double(app.StimTest.Value))
                                app.NextButton2.Text='Finish';
                            end
                            app.NextButton2.Enable='off';
                        end
                        % Define replications sequence from combs,
                        % randomized
                        for i=1:str2double(app.Replications.Value)
                            comb_temp(:,:,i)=app.comb_ANOTAR; % Same combs in CR SD and ANOTAR
                            comb_random_temp(:,:,i)=comb_temp(randperm(size(comb_temp(:,:,i),1)),:,i);
                            app.comb_random=vertcat(app.comb_random,comb_random_temp(:,:,i));
                        end
                    case 8 % 2AC
                        % Setup tab
                        app.Window.SelectedTab=app.Triple;
                        app.Title3.Text=app.TestQuestion.Value;
                        if(~isempty(app.Dictionary))
                            app.A3.Tag='AButton';
                            app.B3.Tag='BButton';
                            app.B3.Enable='off';
                            app.B3.Visible='off';
                            app.C3.Tag='BButton';
                            app.C3.Enable='off';
                            app.BG3_B1.Tag='AButton';
                            app.BG3_B1.Enable='off';
                            app.BG3_B3.Tag='NDifButton';
                            app.BG3_B3.Enable='off';
                            app.BG3_B2.Tag='BButton';
                            app.BG3_B2.Enable='off';
                            if(str2double(app.StimTot.Value)==str2double(app.StimTest.Value))
                                app.NextButton3.Tag='FinishButton';
                                change_language(app,app.languages.lang,app.NextButton3.Tag);
                            end
                            app.NextButton3.Enable='off';
                            change_language(app,app.languages.lang,{'AButton','BButton','NDifButton'});
                        else
                            app.A3.Text='A';
                            app.B3.Text='B';
                            app.B3.Enable='off';
                            app.B3.Visible='off';
                            app.C3.Text='B';
                            app.C3.Enable='off';
                            app.BG3_B1.Text='A';
                            app.BG3_B1.Enable='off';
                            app.BG3_B3.Text='No difference';
                            app.BG3_B3.Enable='off';
                            app.BG3_B2.Text='B';
                            app.BG3_B2.Enable='off';
                            if(str2double(app.StimTot.Value)==str2double(app.StimTest.Value))
                                app.NextButton3.Text='Finish';
                            end
                            app.NextButton3.Enable='off';
                        end
                        % Define replications sequence from combs,
                        % randomized
                        for i=1:str2double(app.Replications.Value)
                            comb_temp(:,:,i)=app.comb_2AC;
                            comb_random_temp(:,:,i)=comb_temp(randperm(size(comb_temp(:,:,i),1)),:,i); % size(max(...))
                            app.comb_random=vertcat(app.comb_random,comb_random_temp(:,:,i));
                        end
                    case 9 % Semantic Scale
                        if(isempty(app.AttributesTable.Data))
                            % Launching error dialog
                            errordlg('You need al least one attribute.','No attributes');
                        elseif(strcmp(app.ScaleTypeDropDown.Value,app.ScaleTypeDropDown.Items(3))) % Pictorial
                            % Launching warning dialog
                            warndlg('Pictorial scale is not developed yet.','Invalid scale type');
                        else
                            % Define replications sequence from combs,
                            % randomized
                            for i=1:str2double(app.Replications_2.Value)
                                comb_temp(:,:,i)=app.comb_SCALE;
                                comb_random_temp(:,:,i)=comb_temp(randperm(size(comb_temp(:,:,i),1)),:,i);
                                app.comb_random=vertcat(app.comb_random,comb_random_temp(:,:,i));
                            end
                            % Setup tab
                            app.Window.SelectedTab=app.Scale;
                            aux_att=num2str(cell2mat(app.AttributesTable.Data(app.comb_random(app.counter,2))));
                            if(length(app.TestQuestion.Value)==find(app.TestQuestion.Value==')')) % Att at the end
                                aux_str=strsplit(app.TestQuestion.Value,'(');
                                app.TitleScale.Text=strcat(aux_str(1),aux_att);
                            elseif(length(app.TestQuestion.Value)>find(app.TestQuestion.Value==')')) % Att in the middle
                                aux_str=strsplit(app.TestQuestion.Value,{'(',')'});
                                app.TitleScale.Text=strcat(aux_str(1),aux_att,aux_str(3));
                            else
                                app.TitleScale.Text=app.TestQuestion.Value;
                            end
                            if(~isempty(app.Dictionary))
                                app.AScale.Tag='AButton';
                                change_language(app,app.languages.lang,{'AButton'});
                            else
                                app.AScale.Text='A';
                            end
                            app.AScale.Enable='on';
                            app.Slider5.Enable='off';
                            app.Slider7.Enable='off';
                            if(app.counter==str2double(app.StimTot_2.Value))
                                app.NextButtonScale.Tag='FinishButton';
                                change_language(app,app.languages.lang,app.NextButtonScale.Tag);
                            end
                            app.NextButtonScale.Enable='off';
                            if(strcmp(app.ScalePolaritySwitch.Value,app.ScalePolaritySwitch.Items(1))) % Unipolar
                                if(strcmp(app.ScalePointsSwitch.Value,app.ScalePointsSwitch.Items(1))) % 5 points slider
                                    % Manage visibility
                                    app.AnswerScale5.Visible='on';
                                    app.AnswerScale5.Enable='on';
                                    app.AnswerScale7.Visible='off';
                                    app.AnswerScale7.Enable='off';
                                    % Manage slider ticks
                                    app.Slider5.MajorTicks=linspace(0,4,5);
                                    app.Slider5.Limits=[0,4];
                                    if(strcmp(app.ScaleTypeDropDown.Value,app.ScaleTypeDropDown.Items(1))) % Selected type of scale
                                        % Manage slider labels
                                        % app.Slider5.MajorTickLabels=string(cellfun(@(x) join([x,app.AttributesTable.Data(app.comb_random(app.counter,2))],' '),app.uni5_labels));
                                        app.NumLabelScale_1.Text='';
                                        app.NumLabelScale_2.Text='';
                                        app.NumLabelScale_3.Text='';
                                        app.NumLabelScale_4.Text='';
                                        app.Slider5.MajorTickLabels=app.uni5_labels;
                                    elseif(strcmp(app.ScaleTypeDropDown.Value,app.ScaleTypeDropDown.Items(2))==1)
                                        % Manage slider labels
                                        app.Slider5.MajorTickLabelsMode='auto';
                                        app.NumLabelScale_1.Text=join(string([app.num_labels(1),num2str(cell2mat(app.AttributesTable.Data(app.comb_random(app.counter,2))))]));
                                        app.NumLabelScale_2.Text=join(string([app.num_labels(2),num2str(cell2mat(app.AttributesTable.Data(app.comb_random(app.counter,2))))]));
                                    else(strcmp(app.ScaleTypeDropDown.Value,app.ScaleTypeDropDown.Items(3))==1)
                                        %
                                    end
                                else % 7 points slider
                                    % Manage visibility
                                    app.AnswerScale5.Visible='off';
                                    app.AnswerScale5.Enable='off';
                                    app.AnswerScale7.Visible='on';
                                    app.AnswerScale7.Enable='on';
                                    % Manage slider ticks
                                    app.Slider7.MajorTicks=linspace(0,6,7);
                                    app.Slider7.Limits=[0,6];
                                    if(strcmp(app.ScaleTypeDropDown.Value,app.ScaleTypeDropDown.Items(2))) % Selected type of scale
                                        % Manage slider labels
                                        app.Slider7.MajorTickLabelsMode='auto';
                                        app.NumLabelScale_3.Text=join(string([app.num_labels(1),num2str(cell2mat(app.AttributesTable.Data(app.comb_random(app.counter,2))))]));
                                        app.NumLabelScale_4.Text=join(string([app.num_labels(2),num2str(cell2mat(app.AttributesTable.Data(app.comb_random(app.counter,2))))]));
                                    elseif(strcmp(app.ScaleTypeDropDown.Value,app.ScaleTypeDropDown.Items(3))==1)
                                        %
                                    end
                                end
                                %{
                        %% Bipolar option is not supported yet
                                %}
                            end
                        end
                    case 10 % 1A/nA choice
                        if(isempty(app.AttributesTable.Data))
                            % Launching error dialog
                            errordlg('You need al least one attribute.','No attributes');
                        else
                            % Define replications sequence from combs,
                            % randomized
                            for i=1:str2double(app.Replications_2.Value)
                                comb_temp(:,:,i)=app.comb_CHOICE;
                                comb_random_temp(:,:,i)=comb_temp(randperm(size(comb_temp(:,:,i),1)),:,i);
                                app.comb_random=vertcat(app.comb_random,comb_random_temp(:,:,i));
                            end
                            % Setup tab
                            app.Window.SelectedTab=app.Choices;
                            app.TitleChoice.Text=app.TestQuestion.Value;
                            if(~isempty(app.Dictionary))
                                app.AChoice.Tag='AButton';
                                change_language(app,app.languages.lang,{'AButton'});
                            else
                                app.AChoice.Text='A';
                            end
                            app.AChoice.Enable='on';
                            if(app.counter==str2double(app.StimTot_2.Value))
                                app.NextButtonChoice.Tag='FinishButton';
                                change_language(app,app.languages.lang,app.NextButtonChoice.Tag);
                            end
                            app.NextButtonChoice.Enable='off';
                            % Manage attribute type and values
                            if(strcmp(app.AnswerTypeDropDown.Value,app.AnswerTypeDropDown.Items(1))) % Checkboxes
                                app.AnswerCheckboxChoice.Enable='off';
                                % Manage visibility
                                app.AnswerCheckboxChoice.Visible='on';
                                app.AnswerToggleChoice.Visible='off';
                                % Manage checkboxes
                                for i=1:length(app.ChoiceAnswerGridLayout.Children)
                                    if(i<=length(app.AttributesTable.Data))
                                        app.ChoiceAnswerGridLayout.Children(i).Text=app.AttributesTable.Data(app.comb_random(app.counter,1+i));
                                    else
                                        app.ChoiceAnswerGridLayout.Children(i).Enable='off';
                                        app.ChoiceAnswerGridLayout.Children(i).Visible='off';
                                    end
                                end
                            elseif(strcmp(app.AnswerTypeDropDown.Value,app.AnswerTypeDropDown.Items(2))) % Toggle buttons
                                app.AnswerToggleChoice.Enable='off';
                                % Manage visibility
                                app.AnswerCheckboxChoice.Visible='off';
                                app.AnswerToggleChoice.Visible='on';
                                % Manage toggle buttons
                                for i=length(app.AnswerToggleChoice.Children):-1:2 % Avoid hidden button
                                    if(length(app.AnswerToggleChoice.Children)-i+1<=length(app.AttributesTable.Data))
                                        app.AnswerToggleChoice.Children(i).Text=app.AttributesTable.Data(app.comb_random(app.counter,1+length(app.AnswerToggleChoice.Children)-i+1));
                                    else
                                        app.AnswerToggleChoice.Children(i).Enable='off';
                                        app.AnswerToggleChoice.Children(i).Visible='off';
                                    end
                                end
                            end
                        end
                end
                app.temp_start=tic(); % Timer starts
            end
        end

        % Button pushed function: NextButton2
        function NextButton2ButtonPushed(app, event)
            if(app.counter<str2double(app.StimTot.Value)/2) % All screens, except last one
                if(app.WaitingDialogCheckBox.Value==1 && mod(app.counter,str2double(app.wait_screens))==0) % Force assesors to have a break
                    waiting_dialog(app);
                end
                % Screen buttons setup
                app.A2.Enable='on';
                app.B2.Enable='off';
                app.BG2_B1.Enable='off';
                app.BG2_B2.Enable='off';
                app.NextButton2.Enable='off';
                % Ending screen actions
                app.Answer{app.counter}=app.AnswerButtonGroup_2.SelectedObject.Text; % Save selected Answer
                % Reset assesor selection
                app.ButtonHidden_2.Value=1;
                if(app.counter==str2double(app.StimTot.Value)/2-1) % If next screen is the last one
                    if(~isempty(app.Dictionary))
                        app.NextButton2.Tag='FinishButton';
                        change_language(app,app.languages.lang,{'FinishButton'});
                    else
                        app.NextButton2.Text='Finish';
                    end
                end
                app.counter=(app.counter)+1; % Next screen
            else % Last screen
                % Screen buttons setup
                app.A2.Enable='on';
                app.B2.Enable='off';
                app.BG2_B1.Enable='off';
                app.BG2_B2.Enable='off';
                app.NextButton2.Enable='off';
                % Ending screen actions
                app.Answer{app.counter}=app.AnswerButtonGroup_2.SelectedObject.Text; % Save selected Answer
                % Reset assesor selection
                app.ButtonHidden_2.Value=1;
                app.temp_tot=toc(app.temp_start); % Timer ends
                % End test and app
                finish_test(app);
            end
        end

        % Button pushed function: NextButton3
        function NextButton3ButtonPushed(app, event)
            if(app.test_index==1 || app.test_index==2 || app.test_index==3) % Calculate number of screens
                screens=str2double(app.StimTot.Value)/3;
            else
                screens=str2double(app.StimTot.Value)/2;
            end
            if(app.counter<screens) % All screens, except last one
                if(app.WaitingDialogCheckBox.Value==1 && mod(app.counter,str2double(app.wait_screens))==0) % Force assesors to have a break
                    waiting_dialog(app);
                end
                % Screen buttons setup
                app.A3.Enable='on';
                app.B3.Enable='off';
                app.C3.Enable='off';
                app.BG3_B1.Enable='off';
                app.BG3_B3.Enable='off';
                app.BG3_B2.Enable='off';
                app.NextButton3.Enable='off';
                % Ending screen actions
                app.Answer{app.counter}=app.AnswerButtonGroup_3.SelectedObject.Text; % Save selected Answer
                % Reset assesor selection
                app.ButtonHidden_3.Value=1;
                if(app.counter==screens-1) % If next screen is the last one
                    if(~isempty(app.Dictionary))
                        app.NextButton3.Tag='FinishButton';
                        change_language(app,app.languages.lang,{'FinishButton'});
                    else
                        app.NextButton3.Text='Finish';
                    end
                end
                app.counter=(app.counter)+1; % Next screen
            else % Last screen
                % Screen buttons setup
                app.A3.Enable='on';
                app.B3.Enable='off';
                app.C3.Enable='off';
                app.BG3_B1.Enable='off';
                app.BG3_B3.Enable='off';
                app.BG3_B2.Enable='off';
                app.NextButton3.Enable='off';
                % Ending screen actions
                app.Answer{app.counter}=app.AnswerButtonGroup_3.SelectedObject.Text; % Save selected Answer
                % Reset assesor selection
                app.ButtonHidden_3.Value=1;
                app.temp_tot=toc(app.temp_start); % Timer ends
                % End test and app
                finish_test(app);
            end
        end

        % Button pushed function: NextButton4
        function NextButton4ButtonPushed(app, event)
            if(app.counter<str2double(app.StimTot.Value)/4) % All screens, except last one
                if(app.WaitingDialogCheckBox.Value==1 && mod(app.counter,str2double(app.wait_screens))==0) % Force assesors to have a break
                    waiting_dialog(app);
                end
                % Screen buttons setup
                app.A4.Enable='on';
                app.B4.Enable='off';
                app.C4.Enable='off';
                app.D4.Enable='off';
                app.BG4_B1.Enable='off';
                app.BG4_B2.Enable='off';
                app.NextButton4.Enable='off';
                % Ending screen actions
                app.Answer{app.counter}=app.AnswerButtonGroup_4.SelectedObject.Text; % Save selected Answer
                % Reset assesor selection
                app.ButtonHidden_4.Value=1;
                if(app.counter==str2double(app.StimTot.Value)/4-1) % If next screen is the last one
                    if(~isempty(app.Dictionary))
                        app.NextButton4.Tag='FinishButton';
                        change_language(app,app.languages.lang,{'FinishButton'});
                    else
                        app.NextButton4.Text='Finish';
                    end
                end
                app.counter=(app.counter)+1; % Next screen
            else % Last screen
                % Screen buttons setup
                app.A4.Enable='on';
                app.B4.Enable='off';
                app.C4.Enable='off';
                app.D4.Enable='off';
                app.BG4_B1.Enable='off';
                app.BG4_B2.Enable='off';
                app.NextButton4.Enable='off';
                % Ending screen actions
                app.Answer{app.counter}=app.AnswerButtonGroup_4.SelectedObject.Text; % Save selected Answer
                % Reset assesor selection
                app.ButtonHidden_4.Value=1;
                app.temp_tot=toc(app.temp_start); % Timer ends
                % End test and app
                finish_test(app);
            end
        end

        % Button pushed function: NextButtonScale
        function NextButtonScaleButtonPushed(app, event)
            app.counter=(app.counter)+1;
            if(app.counter<=str2double(app.StimTot_2.Value)) % During test
                if(app.WaitingDialogCheckBox.Value==1 && mod(app.counter-1,str2double(app.wait_screens))==0) % Force assesors to have a break
                    waiting_dialog(app);
                end
                % Screen setup
                aux_str=strsplit(app.TestQuestion.Value,'(');
                aux_att=num2str(cell2mat(app.AttributesTable.Data(app.comb_random(app.counter,2))));
                app.TitleScale.Text=strcat(aux_str(1),aux_att);
                app.AScale.Enable='on';
                if(app.counter==str2double(app.StimTot_2.Value))
                    if(~isempty(app.Dictionary))
                        app.NextButtonScale.Tag='FinishButton';
                        change_language(app,app.languages.lang,{'FinishButton'});
                    else
                        app.NextButtonScale.Text='Finish';
                    end
                end
                if(strcmp(app.ScalePointsSwitch.Value,'5'))
                    app.Slider5.Enable='off';
                else
                    app.Slider7.Enable='off';
                end
                app.NextButtonScale.Enable='off';
                % Configuration-based actions
                if(strcmp(app.ScalePolaritySwitch.Value,app.ScalePolaritySwitch.Items(1))) % Unipolar
                    if(strcmp(app.ScalePointsSwitch.Value,app.ScalePointsSwitch.Items(1))) % 5 points slider
                        if(strcmp(app.ScaleTypeDropDown.Value,app.ScaleTypeDropDown.Items(1))) % Selected type of scale
                            % Save selected Answer
                            app.Answer{app.counter-1,1}=app.Slider5.MajorTickLabels(find(strcmp(num2str(app.Slider5.Value),string(app.Slider5.MajorTicks))));
                            app.Answer{app.counter-1,2}=app.AttributesTable.Data(app.comb_random(app.counter-1,2));
                            % Update slider labels
                            % app.Slider5.MajorTickLabels=string(cellfun(@(x) join([x,app.AttributesTable.Data(app.comb_random(app.counter,2))],' '),app.uni5_labels));
                        elseif(strcmp(app.ScaleTypeDropDown.Value,app.ScaleTypeDropDown.Items(2)))
                            % Save selected Answer
                            app.Answer{app.counter-1,1}=app.Slider5.Value;
                            stim_count=0;
                            flag_out=0;
                            for i=1:size(app.stim_names,1)
                                for j=1:size(app.stim_names,2)
                                    if(~isempty(app.stim_names{i,j}))
                                        stim_count=stim_count+1;
                                    end
                                    if(stim_count==app.counter-1)
                                        app.Answer{app.counter-1,2}=app.stim_names{i,j};
                                        flag_out=1;
                                        break;
                                    end
                                end
                                if(flag_out)
                                    break;
                                end
                            end
                            app.Answer{app.counter-1,3}=app.AttributesTable.Data(app.comb_random(app.counter-1,2));
                            % Update slider labels
                            app.NumLabelScale_1.Text=join(string([app.num_labels(1),num2str(cell2mat(app.AttributesTable.Data(app.comb_random(app.counter,2))))]));
                            app.NumLabelScale_2.Text=join(string([app.num_labels(2),num2str(cell2mat(app.AttributesTable.Data(app.comb_random(app.counter,2))))]));
                        elseif(strcmp(app.ScaleTypeDropDown.Value,app.ScaleTypeDropDown.Items(3)))
                            %
                        end
                    else  % 7 points slider
                        if(strcmp(app.ScaleTypeDropDown.Value,app.ScaleTypeDropDown.Items(2))) % Selected type of scale
                            % Save selected Answer
                            app.Answer{app.counter-1,1}=app.Slider7.Value;
                            stim_count=0;
                            flag_out=0;
                            for i=1:size(app.stim_names,1)
                                for j=1:size(app.stim_names,2)
                                    if(~isempty(app.stim_names{i,j}))
                                        stim_count=stim_count+1;
                                    end
                                    if(stim_count==app.counter-1)
                                        app.Answer{app.counter-1,2}=app.stim_names{i,j};
                                        flag_out=1;
                                        break;
                                    end
                                end
                                if(flag_out)
                                    break;
                                end
                            end
                            app.Answer{app.counter-1,3}=app.AttributesTable.Data(app.comb_random(app.counter-1,2));
                            % Update slider labels
                            app.NumLabelScale_3.Text=join(string([app.num_labels(1),num2str(cell2mat(app.AttributesTable.Data(app.comb_random(app.counter,2))))]));
                            app.NumLabelScale_4.Text=join(string([app.num_labels(2),num2str(cell2mat(app.AttributesTable.Data(app.comb_random(app.counter,2))))]));
                        elseif(strcmp(app.ScaleTypeDropDown.Value,app.ScaleTypeDropDown.Items(3)))
                            %
                        end
                    end
                    %{
                %% Bipolar option is not supported yet
                    %}
                end
            else % Ending test
                % Save selected Answer
                if(strcmp(app.ScalePolaritySwitch.Value,app.ScalePolaritySwitch.Items(1))) % Unipolar
                    if(strcmp(app.ScalePointsSwitch.Value,app.ScalePointsSwitch.Items(1))) % 5 points slider
                        if(strcmp(app.ScaleTypeDropDown.Value,app.ScaleTypeDropDown.Items(1))) % Selected type of scale
                            app.Answer{app.counter-1}=app.Slider5.MajorTickLabels(find(strcmp(num2str(app.Slider5.Value),string(app.Slider5.MajorTicks))));
                        elseif(strcmp(app.ScaleTypeDropDown.Value,app.ScaleTypeDropDown.Items(2)))
                            app.Answer{app.counter-1,1}=app.Slider5.Value;
                            stim_count=0;
                            flag_out=0;
                            for i=1:size(app.stim_names,1)
                                for j=1:size(app.stim_names,2)
                                    if(~isempty(app.stim_names{i,j}))
                                        stim_count=stim_count+1;
                                    end
                                    if(stim_count==app.counter-1)
                                        app.Answer{app.counter-1,2}=app.stim_names{i,j};
                                        flag_out=1;
                                        break;
                                    end
                                end
                                if(flag_out)
                                    break;
                                end
                            end
                            app.Answer{app.counter-1,3}=app.AttributesTable.Data(app.comb_random(app.counter-1,2));
                        elseif(strcmp(app.ScaleTypeDropDown.Value,app.ScaleTypeDropDown.Items(3)))
                            %
                        end
                    else  % 7 points slider
                        if(strcmp(app.ScaleTypeDropDown.Value,app.ScaleTypeDropDown.Items(2))) % Selected type of scale
                            app.Answer{app.counter-1,1}=app.Slider7.Value;
                            stim_count=0;
                            flag_out=0;
                            for i=1:size(app.stim_names,1)
                                for j=1:size(app.stim_names,2)
                                    if(~isempty(app.stim_names{i,j}))
                                        stim_count=stim_count+1;
                                    end
                                    if(stim_count==app.counter-1)
                                        app.Answer{app.counter-1,2}=app.stim_names{i,j};
                                        flag_out=1;
                                        break;
                                    end
                                end
                                if(flag_out)
                                    break;
                                end
                            end
                            app.Answer{app.counter-1,3}=app.AttributesTable.Data(app.comb_random(app.counter-1,2));
                        elseif(strcmp(app.ScaleTypeDropDown.Value,app.ScaleTypeDropDown.Items(3)))
                            %
                        end
                    end
                end
                app.temp_tot=toc(app.temp_start); % Timer ends
                % End test and app
                finish_test(app);
            end
        end

        % Button pushed function: NextButtonChoice
        function NextButtonChoiceButtonPushed(app, event)
            app.counter=(app.counter)+1;
            if(app.counter<=str2double(app.StimTot_2.Value)) % During test
                if(app.WaitingDialogCheckBox.Value==1 && mod(app.counter-1,str2double(app.wait_screens))==0) % Force assesors to have a break
                    waiting_dialog(app);
                end
                % Screen setup
                app.AChoice.Enable='on';
                if(app.counter==str2double(app.StimTot_2.Value))
                    if(~isempty(app.Dictionary))
                        app.NextButtonChoice.Tag='FinishButton';
                        change_language(app,app.languages.lang,{'FinishButton'});
                    else
                        app.NextButtonChoice.Text='Finish';
                    end
                end
                if(strcmp(app.AnswerTypeDropDown.Value,app.AnswerTypeDropDown.Items(1))) % Checkboxes
                    app.AnswerCheckboxChoice.Enable='off';
                    % Save selected Answer
                    for i=1:length(app.AttributesTable.Data)
                        if(i==1)
                            size_ans=size(app.Answer,1);
                        end
                        app.Answer{size_ans+1,i}=app.ChoiceAnswerGridLayout.Children(i).Value;
                        app.Answer{size_ans+2,i}=app.AttributesTable.Data(app.comb_random(app.counter-1,1+i));
                    end
                    % Update checkbox attributes
                    for i=1:length(app.AttributesTable.Data)
                        app.ChoiceAnswerGridLayout.Children(i).Text=app.AttributesTable.Data(app.comb_random(app.counter,1+i));
                        app.ChoiceAnswerGridLayout.Children(i).Value=false;
                    end
                elseif(strcmp(app.AnswerTypeDropDown.Value,app.AnswerTypeDropDown.Items(2))) % Toggle buttons
                        app.AnswerToggleChoice.Enable='off';
                        % Save selected Answer
                        app.Answer{app.counter-1}=app.AnswerToggleChoice.SelectedObject.Text;
                        % Update toogle attributes
                        for i=length(app.AnswerToggleChoice.Children):-1:2 % Avoid hidden button
                            if(length(app.AnswerToggleChoice.Children)-i+1<=length(app.AttributesTable.Data))
                                app.AnswerToggleChoice.Children(i).Text=app.AttributesTable.Data(app.comb_random(app.counter,1+length(app.AnswerToggleChoice.Children)-i+1));
                            else
                                app.AnswerToggleChoice.Children(i).Enable='off';
                                app.AnswerToggleChoice.Children(i).Visible='off';
                            end
                        end
                        app.ButtonHidden_Choices.Value=1;
                end
                app.NextButtonChoice.Enable='off';
            else % Ending test
                % Screen setup
                app.AChoice.Enable='on';
                if(strcmp(app.AnswerTypeDropDown.Value,app.AnswerTypeDropDown.Items(1))) % Checkboxes
                    app.AnswerCheckboxChoice.Enable='off';
                    % Save selected Answer
                    for i=1:length(app.AttributesTable.Data)
                        if(i==1)
                            size_ans=size(app.Answer,1);
                        end
                        app.Answer{size_ans+1,i}=app.ChoiceAnswerGridLayout.Children(i).Value;
                        app.Answer{size_ans+2,i}=app.AttributesTable.Data(app.comb_random(app.counter-1,1+i));
                    end
                elseif(strcmp(app.AnswerTypeDropDown.Value,app.AnswerTypeDropDown.Items(2))) % Toggle buttons
                    app.AnswerToggleChoice.Enable='off';
                    % Save selected Answer
                    app.Answer{app.counter-1}=app.AnswerToggleChoice.SelectedObject.Text;
                end
                app.temp_tot=toc(app.temp_start); % Timer ends
                % End test and app
                finish_test(app);
            end
        end

        % Button pushed function: A2
        function A2ButtonPushed(app, event)
            app.A2.Enable='off';
            player=audioplayer(app.stimuli{app.comb_random(app.counter,1)},app.fs{app.comb_random(app.counter,1)});
            playblocking(player);
            app.B2.Enable='on';
        end

        % Button pushed function: B2
        function B2ButtonPushed(app, event)
            app.B2.Enable='off';
            player=audioplayer(app.stimuli{app.comb_random(app.counter,2)},app.fs{app.comb_random(app.counter,2)});
            playblocking(player);
            app.BG2_B1.Enable='on';
            app.BG2_B2.Enable='on';
        end

        % Button pushed function: B3
        function B3ButtonPushed(app, event)
            app.B3.Enable='off';
            player=audioplayer(app.stimuli{app.comb_random(app.counter,2)},app.fs{app.comb_random(app.counter,2)});
            playblocking(player);
            app.C3.Enable='on';
        end

        % Button pushed function: A3
        function A3ButtonPushed(app, event)
            % [~, app.test_index]=find(strcmp(app.TestProtocolListBox.Items, app.TestProtocolListBox.Value));
            app.A3.Enable='off';
            player=audioplayer(app.stimuli{app.comb_random(app.counter,1)},app.fs{app.comb_random(app.counter,1)});
            playblocking(player);
            if(app.test_index==1 || app.test_index==2 || app.test_index==3)
                app.B3.Enable='on';
            else
                app.C3.Enable='on';
            end
        end

        % Button pushed function: C3
        function C3ButtonPushed(app, event)
            % [~, app.test_index]=find(strcmp(app.TestProtocolListBox.Items, app.TestProtocolListBox.Value));
            app.C3.Enable='off';
            if(app.test_index==1 || app.test_index==2 || app.test_index==3)
                player=audioplayer(app.stimuli{app.comb_random(app.counter,3)},app.fs{app.comb_random(app.counter,3)});
            else
                player=audioplayer(app.stimuli{app.comb_random(app.counter,2)},app.fs{app.comb_random(app.counter,2)});
            end
            playblocking(player);
            app.BG3_B1.Enable='on';
            app.BG3_B3.Enable='on';
            app.BG3_B2.Enable='on';
        end

        % Button pushed function: C4
        function C4ButtonPushed(app, event)
            app.C4.Enable='off';
            player=audioplayer(app.stimuli{app.comb_random(app.counter,3)},app.fs{app.comb_random(app.counter,3)});
            playblocking(player);
            app.D4.Enable='on';
        end

        % Button pushed function: A4
        function A4ButtonPushed(app, event)
            app.A4.Enable='off';
            player=audioplayer(app.stimuli{app.comb_random(app.counter,1)},app.fs{app.comb_random(app.counter,1)});
            playblocking(player);
            app.B4.Enable='on';
        end

        % Button pushed function: B4
        function B4ButtonPushed(app, event)
            app.B4.Enable='off';
            player=audioplayer(app.stimuli{app.comb_random(app.counter,2)},app.fs{app.comb_random(app.counter,2)});
            playblocking(player);
            app.C4.Enable='on';
        end

        % Button pushed function: D4
        function D4ButtonPushed(app, event)
            app.D4.Enable='off';
            player=audioplayer(app.stimuli{app.comb_random(app.counter,4)},app.fs{app.comb_random(app.counter,1)});
            playblocking(player);
            app.BG4_B1.Enable='on';
            app.BG4_B2.Enable='on';
        end

        % Button pushed function: AScale
        function AScaleButtonPushed(app, event)
            app.AScale.Enable='off';
            player=audioplayer(app.stimuli{app.comb_random(app.counter,1)},app.fs{app.comb_random(app.counter,1)});
            playblocking(player);
            if(strcmp(app.ScalePointsSwitch.Value,app.ScalePointsSwitch.Items(1))) % 5 points scale
                app.Slider5.Enable='on';
            else
                app.Slider7.Enable='on';
            end
        end

        % Button pushed function: AChoice
        function AChoiceButtonPushed(app, event)
            app.AChoice.Enable='off';
            player=audioplayer(app.stimuli{app.comb_random(app.counter,1)},app.fs{app.comb_random(app.counter,1)});
            playblocking(player);
            if(strcmp(app.AnswerTypeDropDown.Value,app.AnswerTypeDropDown.Items(1))) % Checkboxes
                app.AnswerCheckboxChoice.Enable='on';
                app.NextButtonChoice.Enable='on';
            elseif(strcmp(app.AnswerTypeDropDown.Value,app.AnswerTypeDropDown.Items(2))) % Toggle buttons
                app.AnswerToggleChoice.Enable='on';
            end
        end

        % Selection changed function: AnswerButtonGroup_2
        function AnswerButtonGroup_2SelectionChanged(app, event)
            %selectedButton = app.AnswerButtonGroup_2.SelectedObject;
            app.NextButton2.Enable='on';
        end

        % Selection changed function: AnswerButtonGroup_3
        function AnswerButtonGroup_3SelectionChanged(app, event)
            %selectedButton = app.AnswerButtonGroup_3.SelectedObject;
            app.NextButton3.Enable='on';
        end

        % Selection changed function: AnswerButtonGroup_4
        function AnswerButtonGroup_4SelectionChanged(app, event)
            %selectedButton = app.AnswerButtonGroup_4.SelectedObject;
            app.NextButton4.Enable='on';
        end

        % Selection changed function: AnswerToggleChoice
        function AnswerToggleChoiceSelectionChanged(app, event)
            % selectedButton = app.AnswerToggleChoice.SelectedObject;
            app.NextButtonChoice.Enable='on';
        end

        % Value changed function: Slider5
        function Slider5ValueChanged(app, event)
            % Determine discrete option which is closest to the current value
            [~, minIdx] = min(abs(app.Slider5.Value - event.Source.MajorTicks(:)));
            % Update slider position
            event.Source.Value = event.Source.MajorTicks(minIdx);
            % Test flow
            app.NextButtonScale.Enable='on';
        end

        % Value changed function: Slider7
        function Slider7ValueChanged(app, event)
            % Determine discrete option which is closest to the current value
            [~, minIdx] = min(abs(app.Slider7.Value - event.Source.MajorTicks(:)));
            % Update slider position
            event.Source.Value = event.Source.MajorTicks(minIdx);
            % Test flow
            app.NextButtonScale.Enable='on';
        end

        % Value changed function: SequencesSwitch
        function SequencesSwitchValueChanged(app, event)
            update_stim_values(app);
        end

        % Value changed function: Replications
        function ReplicationsValueChanged(app, event)
            update_stim_values(app);
        end

        % Value changed function: Replications_2
        function Replications_2ValueChanged(app, event)
            update_stim_values(app);
        end

        % Value changed function: ScaleTypeDropDown
        function ScaleTypeDropDownValueChanged(app, event)
            % labels5=findobj(app.AnswerScale5,'Type','Label');
            % for i=1:length(labels5)
            %     labels5(i).Visibility='on';
            %     disp(labels5.Text)
            % end
            % labels7=findobj(app.AnswerScale7,'Type','Label');
            % 7 point scale can only be accessed by numerical type
            if(strcmp(app.ScaleTypeDropDown.Value,app.ScaleTypeDropDown.Items(1))) % Verbal
                app.ScalePointsSwitch.Value=app.ScalePointsSwitch.Items(1);
                app.ScalePointsSwitch.Enable='off';
            % elseif(strcmp(app.ScaleTypeDropDown.Value,app.ScaleTypeDropDown.Items(2))) % Numerical
            else
                app.ScalePointsSwitch.Enable='on';
            end
        end

        % Close request function: DiTAAToolboxv20UIFigure
        function DiTAAToolboxv20UIFigureCloseRequest(app, event)
            % try
            %     % Store workspace
            %     if(~isfile('Workspace.mat'))
            %         workspace=app.workspace;
            %         filename='Workspace.mat';
            %         save(strcat(app.workspace,'\',filename),'workspace');
            %     end
            % catch
            %     % Launching warning
            %     warndlg('There was a problem saving workspace information','App closing');
            % end
            % Close app
            delete(app)
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.DiTAAToolboxv20UIFigure = uifigure('Visible', 'off');
            app.DiTAAToolboxv20UIFigure.Name = 'DiTAA Toolbox v 2.0';
            app.DiTAAToolboxv20UIFigure.CloseRequestFcn = createCallbackFcn(app, @DiTAAToolboxv20UIFigureCloseRequest, true);
            app.FileMenu = uimenu(app.DiTAAToolboxv20UIFigure);
            app.FileMenu.Text = 'File';
            app.FileMenu.Tag = 'FileMenu';
            app.SaveDesignMenu = uimenu(app.FileMenu);
            app.SaveDesignMenu.MenuSelectedFcn = createCallbackFcn(app, @SaveDesignMenuSelected, true);
            app.SaveDesignMenu.Text = 'Save design';
            app.SaveDesignMenu.Tag = 'SaveDesign';
            app.OpenDesignMenu = uimenu(app.FileMenu);
            app.OpenDesignMenu.MenuSelectedFcn = createCallbackFcn(app, @OpenDesignMenuSelected, true);
            app.OpenDesignMenu.Text = 'Open design';
            app.OpenDesignMenu.Tag = 'OpenDesign';
            app.CloseMenu = uimenu(app.FileMenu);
            app.CloseMenu.MenuSelectedFcn = createCallbackFcn(app, @CloseMenuSelected, true);
            app.CloseMenu.Text = 'Close';
            app.CloseMenu.Tag = 'CloseApp';
            app.ViewMenu = uimenu(app.DiTAAToolboxv20UIFigure);
            app.ViewMenu.Text = 'View';
            app.ViewMenu.Tag = 'ViewMenu';
            app.TestPerformMenu = uimenu(app.ViewMenu);
            app.TestPerformMenu.MenuSelectedFcn = createCallbackFcn(app, @TestPerformMenuSelected, true);
            app.TestPerformMenu.Text = 'Test perform';
            app.TestPerformMenu.Tag = 'TestPerformMenu';
            app.AnalyzerMenu = uimenu(app.ViewMenu);
            app.AnalyzerMenu.MenuSelectedFcn = createCallbackFcn(app, @AnalyzerMenuSelected, true);
            app.AnalyzerMenu.Text = 'Analyzer';
            app.AnalyzerMenu.Tag = 'AnalyzerMenu';
            app.LanguagesMenu = uimenu(app.DiTAAToolboxv20UIFigure);
            app.LanguagesMenu.Text = 'Language';
            app.LanguagesMenu.Tag = 'LanguageMenu';
            app.EnglishMenu = uimenu(app.LanguagesMenu);
            app.EnglishMenu.MenuSelectedFcn = createCallbackFcn(app, @EnglishMenuSelected, true);
            app.EnglishMenu.Text = 'English';
            app.EnglishMenu.Tag = 'EnglishLanguage';
            app.SpanishMenu = uimenu(app.LanguagesMenu);
            app.SpanishMenu.MenuSelectedFcn = createCallbackFcn(app, @SpanishMenuSelected, true);
            app.SpanishMenu.Text = 'Spanish';
            app.SpanishMenu.Tag = 'SpanishLanguage';
            app.ItalianMenu = uimenu(app.LanguagesMenu);
            app.ItalianMenu.MenuSelectedFcn = createCallbackFcn(app, @ItalianMenuSelected, true);
            app.ItalianMenu.Text = 'Italian';
            app.ItalianMenu.Tag = 'ItalianLanguage';
            app.FrenchMenu = uimenu(app.LanguagesMenu);
            app.FrenchMenu.MenuSelectedFcn = createCallbackFcn(app, @FrenchMenuSelected, true);
            app.FrenchMenu.Text = 'French';
            app.HelpMenu = uimenu(app.DiTAAToolboxv20UIFigure);
            app.HelpMenu.Text = 'Help';
            app.HelpMenu.Tag = 'HelpMenu';
            app.GettingStartedMenu = uimenu(app.HelpMenu);
            app.GettingStartedMenu.Text = 'Getting started';
            app.GettingStartedMenu.Tag = 'GettingStarted';
            app.RecommendedConfigurationsMenu = uimenu(app.HelpMenu);
            app.RecommendedConfigurationsMenu.Text = 'Recommended configurations';
            app.RecommendedConfigurationsMenu.Tag = 'RecommendedConfigurations';
            app.Window = uitabgroup(app.DiTAAToolboxv20UIFigure);
            app.Window.SelectionChangedFcn = createCallbackFcn(app, @WindowSelectionChanged, true);
            app.Setup = uitab(app.Window);
            app.Setup.Title = 'Setup';
            app.Setup.Tag = 'SetupTab';
            app.ScaleProtocolPanel = uipanel(app.Setup);
            app.ScaleProtocolPanel.Title = '  Protocol specifics';
            app.ScaleProtocolPanel.Tag = 'ProtocolPanel';
            app.ReplicationsLabel_2 = uilabel(app.ScaleProtocolPanel);
            app.ReplicationsLabel_2.Tag = 'Rep';
            app.ReplicationsLabel_2.Text = 'Replications';
            app.Replications_2 = uieditfield(app.ScaleProtocolPanel, 'text');
            app.Replications_2.ValueChangedFcn = createCallbackFcn(app, @Replications_2ValueChanged, true);
            app.Replications_2.Placeholder = 'Choose (min. 1)';
            app.Replications_2.Value = '1';
            app.TotalStimuliLabel_2 = uilabel(app.ScaleProtocolPanel);
            app.TotalStimuliLabel_2.Tag = 'StimTotLabel';
            app.TotalStimuliLabel_2.Text = 'Total stimuli';
            app.StimTot_2 = uieditfield(app.ScaleProtocolPanel, 'text');
            app.StimTot_2.Editable = 'off';
            app.ScalePointsSwitchLabel = uilabel(app.ScaleProtocolPanel);
            app.ScalePointsSwitchLabel.Tag = 'PointsLabel';
            app.ScalePointsSwitchLabel.Text = 'Scale points';
            app.ScalePointsSwitch = uiswitch(app.ScaleProtocolPanel, 'slider');
            app.ScalePointsSwitch.Items = {'5', '7'};
            app.ScalePointsSwitch.Value = '5';
            app.ScaleTypeDropDownLabel = uilabel(app.ScaleProtocolPanel);
            app.ScaleTypeDropDownLabel.Tag = 'STypeLabel';
            app.ScaleTypeDropDownLabel.Text = 'Scale type';
            app.ScaleTypeDropDown = uidropdown(app.ScaleProtocolPanel);
            app.ScaleTypeDropDown.Items = {'Verbal', 'Numerical', 'Pictorial'};
            app.ScaleTypeDropDown.ValueChangedFcn = createCallbackFcn(app, @ScaleTypeDropDownValueChanged, true);
            app.ScaleTypeDropDown.Tag = 'STypeDropDown';
            app.ScaleTypeDropDown.Value = 'Verbal';
            app.AttributesTable = uitable(app.ScaleProtocolPanel);
            app.AttributesTable.ColumnName = {'Attributes'};
            app.AttributesTable.RowName = {};
            app.AttributesTable.ColumnEditable = true;
            app.AttributesTable.Tag = 'AttTable';
            app.ClearAttributesButton = uibutton(app.ScaleProtocolPanel, 'push');
            app.ClearAttributesButton.ButtonPushedFcn = createCallbackFcn(app, @ClearAttributesButtonPushed, true);
            app.ClearAttributesButton.Tag = 'ClearAtt';
            app.ClearAttributesButton.Text = 'Clear attributes';
            app.RemoveAttributeButton = uibutton(app.ScaleProtocolPanel, 'push');
            app.RemoveAttributeButton.ButtonPushedFcn = createCallbackFcn(app, @RemoveAttributeButtonPushed, true);
            app.RemoveAttributeButton.Tag = 'RemoveAtt';
            app.RemoveAttributeButton.Text = 'Remove attribute';
            app.AddAttributesButton = uibutton(app.ScaleProtocolPanel, 'push');
            app.AddAttributesButton.ButtonPushedFcn = createCallbackFcn(app, @AddAttributesButtonPushed, true);
            app.AddAttributesButton.Tag = 'AddAtt';
            app.AddAttributesButton.Text = 'Add attributes';
            app.ScalePolaritySwitchLabel = uilabel(app.ScaleProtocolPanel);
            app.ScalePolaritySwitchLabel.Tag = 'PolarityLabel';
            app.ScalePolaritySwitchLabel.Text = 'Scale polarity';
            app.ScalePolaritySwitch = uiswitch(app.ScaleProtocolPanel, 'slider');
            app.ScalePolaritySwitch.Items = {'Unipolar', 'Bipolar'};
            app.ScalePolaritySwitch.Value = 'Unipolar';
            app.AnswerTypeDropDownLabel = uilabel(app.ScaleProtocolPanel);
            app.AnswerTypeDropDownLabel.Tag = 'ATypeLabel';
            app.AnswerTypeDropDownLabel.Text = 'Answer type';
            app.AnswerTypeDropDown = uidropdown(app.ScaleProtocolPanel);
            app.AnswerTypeDropDown.Items = {'Checkbox', 'Toggle'};
            app.AnswerTypeDropDown.Tag = 'ATypeDropDown';
            app.AnswerTypeDropDown.Value = 'Checkbox';
            app.DiscriminationProtocolPanel = uipanel(app.Setup);
            app.DiscriminationProtocolPanel.Title = ' Protocol specifics';
            app.DiscriminationProtocolPanel.Tag = 'ProtocolPanel';
            app.StimuliTestLabel = uilabel(app.DiscriminationProtocolPanel);
            app.StimuliTestLabel.Tag = 'StimTest';
            app.StimuliTestLabel.Text = 'Stimuli / test';
            app.StimTest = uieditfield(app.DiscriminationProtocolPanel, 'text');
            app.StimTest.Editable = 'off';
            app.CombinationsLabel = uilabel(app.DiscriminationProtocolPanel);
            app.CombinationsLabel.Tag = 'CombinationsLabel';
            app.CombinationsLabel.Text = 'Combinations';
            app.Combinations = uieditfield(app.DiscriminationProtocolPanel, 'text');
            app.Combinations.Tag = 'Combinations';
            app.Combinations.Editable = 'off';
            app.Combinations.Placeholder = 'Select stimuli';
            app.StimuliReplicationLabel = uilabel(app.DiscriminationProtocolPanel);
            app.StimuliReplicationLabel.Tag = 'StimRepLabel';
            app.StimuliReplicationLabel.Text = 'Stimuli / replication';
            app.StimReplication = uieditfield(app.DiscriminationProtocolPanel, 'text');
            app.StimReplication.Tag = 'StimRep';
            app.StimReplication.Editable = 'off';
            app.StimReplication.Placeholder = 'Select stimuli';
            app.ReplicationsLabel = uilabel(app.DiscriminationProtocolPanel);
            app.ReplicationsLabel.Tag = 'Rep';
            app.ReplicationsLabel.Text = 'Replications';
            app.Replications = uieditfield(app.DiscriminationProtocolPanel, 'text');
            app.Replications.ValueChangedFcn = createCallbackFcn(app, @ReplicationsValueChanged, true);
            app.Replications.Placeholder = 'Choose (min. 1)';
            app.Replications.Value = '1';
            app.BalancedLabel = uilabel(app.DiscriminationProtocolPanel);
            app.BalancedLabel.Tag = 'BalanceLabel';
            app.BalancedLabel.Text = 'Balancing';
            app.BalanceSwitch = uiswitch(app.DiscriminationProtocolPanel, 'slider');
            app.BalanceSwitch.Items = {'CR', 'Balanced'};
            app.BalanceSwitch.Tag = 'BalanceSwitch';
            app.BalanceSwitch.Enable = 'off';
            app.BalanceSwitch.Value = 'CR';
            app.FamilyLabel = uilabel(app.DiscriminationProtocolPanel);
            app.FamilyLabel.Tag = 'FamilyLabel';
            app.FamilyLabel.Text = 'Protocol family';
            app.FamilySwitch = uiswitch(app.DiscriminationProtocolPanel, 'slider');
            app.FamilySwitch.Items = {'Overall', 'Attribute'};
            app.FamilySwitch.Tag = 'FamilySwitch';
            app.FamilySwitch.Enable = 'off';
            app.FamilySwitch.Value = 'Overall';
            app.SequencesSwitchLabel = uilabel(app.DiscriminationProtocolPanel);
            app.SequencesSwitchLabel.Tag = 'SequencesLabel';
            app.SequencesSwitchLabel.Text = 'sequences';
            app.SequencesSwitch = uiswitch(app.DiscriminationProtocolPanel, 'slider');
            app.SequencesSwitch.Items = {'Random', 'All'};
            app.SequencesSwitch.ValueChangedFcn = createCallbackFcn(app, @SequencesSwitchValueChanged, true);
            app.SequencesSwitch.Tag = 'SequencesSwitch';
            app.SequencesSwitch.Value = 'Random';
            app.TotalstimuliEditFieldLabel = uilabel(app.DiscriminationProtocolPanel);
            app.TotalstimuliEditFieldLabel.Tag = 'StimTotLabel';
            app.TotalstimuliEditFieldLabel.Text = 'Total stimuli';
            app.StimTot = uieditfield(app.DiscriminationProtocolPanel, 'text');
            app.StimTot.Tag = 'StimTot';
            app.StimTot.Editable = 'off';
            app.StimTot.Placeholder = 'Select stimuli';
            app.PerformButton = uibutton(app.Setup, 'push');
            app.PerformButton.ButtonPushedFcn = createCallbackFcn(app, @PerformButtonPushed, true);
            app.PerformButton.Tag = 'PerformButton';
            app.PerformButton.Text = 'Perform';
            app.Lamp = uilamp(app.Setup);
            app.WaitingDialogCheckBox = uicheckbox(app.Setup);
            app.WaitingDialogCheckBox.ValueChangedFcn = createCallbackFcn(app, @WaitingDialogCheckBoxValueChanged, true);
            app.WaitingDialogCheckBox.Tag = 'WaitingD';
            app.WaitingDialogCheckBox.Text = 'Enable waiting dialog?';
            app.StimExtra = uieditfield(app.Setup, 'text');
            app.TestprotocolLabel = uilabel(app.Setup);
            app.TestprotocolLabel.Tag = 'TestProtocolLabel';
            app.TestprotocolLabel.Text = {'Test'; 'protocol'};
            app.TestquestionLabel = uilabel(app.Setup);
            app.TestquestionLabel.Tag = 'TestQuestion';
            app.TestquestionLabel.Text = 'Test question';
            app.StimExtraLabel = uilabel(app.Setup);
            app.StimExtraLabel.Tag = 'StimExtra';
            app.StimExtraLabel.Text = {'Extra stimuli'; '(for num. equalization)'};
            app.TestQuestion = uieditfield(app.Setup, 'text');
            app.TestQuestion.ValueChangedFcn = createCallbackFcn(app, @TestQuestionValueChanged, true);
            app.ClearStimsButton = uibutton(app.Setup, 'push');
            app.ClearStimsButton.ButtonPushedFcn = createCallbackFcn(app, @ClearStimsButtonPushed, true);
            app.ClearStimsButton.Tag = 'ClearStim';
            app.ClearStimsButton.Text = 'Clear stim.';
            app.RemoveStimsButton = uibutton(app.Setup, 'push');
            app.RemoveStimsButton.ButtonPushedFcn = createCallbackFcn(app, @RemoveStimsButtonPushed, true);
            app.RemoveStimsButton.Tag = 'RemoveStim';
            app.RemoveStimsButton.Text = 'Remove stim.';
            app.AddStimsButton = uibutton(app.Setup, 'push');
            app.AddStimsButton.ButtonPushedFcn = createCallbackFcn(app, @AddStimsButtonPushed, true);
            app.AddStimsButton.Tag = 'AddStim';
            app.AddStimsButton.Text = 'Add stim.';
            app.BrowseStimsButton = uibutton(app.Setup, 'push');
            app.BrowseStimsButton.ButtonPushedFcn = createCallbackFcn(app, @BrowseStimsButtonPushed, true);
            app.BrowseStimsButton.Tag = 'BrowseStim';
            app.BrowseStimsButton.Text = 'Browse stim.';
            app.StimBrowser = uitree(app.Setup);
            app.StimBrowser.Multiselect = 'on';
            app.TestProtocolListBox = uilistbox(app.Setup);
            app.TestProtocolListBox.Items = {'Triangular', 'ABX', 'CR DTF', 'CR DTFM', 'A-Not AR', 'Same-Diff', 'CR Same-Diff', '2-AC', 'Semantic scale', '1A/nA choice'};
            app.TestProtocolListBox.ValueChangedFcn = createCallbackFcn(app, @TestProtocolListBoxValueChanged, true);
            app.TestProtocolListBox.Value = 'Triangular';
            app.Analyzer = uitab(app.Window);
            app.TestprotocolLabel_2 = uilabel(app.Analyzer);
            app.TestprotocolLabel_2.Tag = 'TestProtocolLabel';
            app.TestprotocolLabel_2.Text = {'Test'; 'protocol'};
            app.ResultsVisualizationPanel = uipanel(app.Analyzer);
            app.ResultsVisualizationPanel.Title = 'Results visualization';
            app.ResultsVisualizationPanel.Tag = 'ResultsPanel';
            app.ResultsAxes = uiaxes(app.ResultsVisualizationPanel);
            app.ResultsAxes.XLim = [0 1];
            app.ResultsAxes.XTick = [];
            app.ResultsAxes.YTick = [];
            app.ResultsAxes.ZTick = [];
            app.ResultsExportButton = uibutton(app.ResultsVisualizationPanel, 'push');
            app.ResultsExportButton.ButtonPushedFcn = createCallbackFcn(app, @ResultsExportButtonPushed, true);
            app.ResultsExportButton.Tag = 'ResultsExport';
            app.ResultsExportButton.Text = 'Export results';
            app.ResultsTableGeneral = uitable(app.ResultsVisualizationPanel);
            app.ResultsTableGeneral.ColumnName = '';
            app.ResultsTableGeneral.RowName = {};
            app.TabGroup = uitabgroup(app.ResultsVisualizationPanel);
            app.ReplicationTab = uitab(app.TabGroup);
            app.ReplicationTab.Title = 'Replication';
            app.ReplicationTab.Tag = 'ReplicationTab';
            app.ResultsTableCombinationsR = uitable(app.ReplicationTab);
            app.ResultsTableCombinationsR.ColumnName = '';
            app.ResultsTableCombinationsR.RowName = {};
            app.GlobalTab = uitab(app.TabGroup);
            app.GlobalTab.Title = 'Global';
            app.GlobalTab.Tag = 'GlobalTab';
            app.ResultsTableCombinationsG = uitable(app.GlobalTab);
            app.ResultsTableCombinationsG.ColumnName = '';
            app.ResultsTableCombinationsG.RowName = {};
            app.BrowseReviewsButton = uibutton(app.Analyzer, 'push');
            app.BrowseReviewsButton.ButtonPushedFcn = createCallbackFcn(app, @BrowseReviewsButtonPushed, true);
            app.BrowseReviewsButton.Tag = 'BrowseReviews';
            app.BrowseReviewsButton.Text = 'Browse stim.';
            app.ReviewBrowser = uitree(app.Analyzer);
            app.ReviewBrowser.Multiselect = 'on';
            app.AnalyzeProtocolListBox = uilistbox(app.Analyzer);
            app.AnalyzeProtocolListBox.Items = {'Triangular', 'ABX', 'CR DTF', 'CR DTFM', 'A-Not AR', 'Same-Diff', 'CR Same-Diff', '2-AC', 'Semantic scale', '1A/nA choice'};
            app.AnalyzeProtocolListBox.ValueChangedFcn = createCallbackFcn(app, @AnalyzeProtocolListBoxValueChanged, true);
            app.AnalyzeProtocolListBox.ClickedFcn = createCallbackFcn(app, @AnalyzeProtocolListBoxClicked, true);
            app.AnalyzeProtocolListBox.Value = 'Triangular';
            app.Double = uitab(app.Window);
            app.PlaybackPanel_2 = uipanel(app.Double);
            app.PlaybackPanel_2.Title = 'Playback';
            app.PlaybackPanel_2.Tag = 'Playback';
            app.A2 = uibutton(app.PlaybackPanel_2, 'push');
            app.A2.ButtonPushedFcn = createCallbackFcn(app, @A2ButtonPushed, true);
            app.B2 = uibutton(app.PlaybackPanel_2, 'push');
            app.B2.ButtonPushedFcn = createCallbackFcn(app, @B2ButtonPushed, true);
            app.AnswerButtonGroup_2 = uibuttongroup(app.Double);
            app.AnswerButtonGroup_2.SelectionChangedFcn = createCallbackFcn(app, @AnswerButtonGroup_2SelectionChanged, true);
            app.AnswerButtonGroup_2.Title = 'Answer';
            app.AnswerButtonGroup_2.Tag = 'Answer';
            app.BG2_B1 = uitogglebutton(app.AnswerButtonGroup_2);
            app.BG2_B1.Text = 'Button';
            app.BG2_B2 = uitogglebutton(app.AnswerButtonGroup_2);
            app.BG2_B2.Text = 'Button2';
            app.ButtonHidden_2 = uitogglebutton(app.AnswerButtonGroup_2);
            app.ButtonHidden_2.Visible = 'off';
            app.ButtonHidden_2.Text = 'ButtonHidden';
            app.ButtonHidden_2.Value = true;
            app.Title2 = uilabel(app.Double);
            app.Title2.Text = 'Text for two stimuli';
            app.NextButton2 = uibutton(app.Double, 'push');
            app.NextButton2.ButtonPushedFcn = createCallbackFcn(app, @NextButton2ButtonPushed, true);
            app.NextButton2.Tag = 'NextButton';
            app.NextButton2.Text = 'Next';
            app.Triple = uitab(app.Window);
            app.PlaybackPanel_3 = uipanel(app.Triple);
            app.PlaybackPanel_3.Title = 'Playback';
            app.PlaybackPanel_3.Tag = 'Playback';
            app.A3 = uibutton(app.PlaybackPanel_3, 'push');
            app.A3.ButtonPushedFcn = createCallbackFcn(app, @A3ButtonPushed, true);
            app.B3 = uibutton(app.PlaybackPanel_3, 'push');
            app.B3.ButtonPushedFcn = createCallbackFcn(app, @B3ButtonPushed, true);
            app.C3 = uibutton(app.PlaybackPanel_3, 'push');
            app.C3.ButtonPushedFcn = createCallbackFcn(app, @C3ButtonPushed, true);
            app.AnswerButtonGroup_3 = uibuttongroup(app.Triple);
            app.AnswerButtonGroup_3.SelectionChangedFcn = createCallbackFcn(app, @AnswerButtonGroup_3SelectionChanged, true);
            app.AnswerButtonGroup_3.Title = 'Answer';
            app.AnswerButtonGroup_3.Tag = 'Answer';
            app.BG3_B1 = uitogglebutton(app.AnswerButtonGroup_3);
            app.BG3_B1.Text = 'Button';
            app.BG3_B3 = uitogglebutton(app.AnswerButtonGroup_3);
            app.BG3_B3.Text = 'Button3';
            app.BG3_B2 = uitogglebutton(app.AnswerButtonGroup_3);
            app.BG3_B2.Text = 'Button2';
            app.ButtonHidden_3 = uitogglebutton(app.AnswerButtonGroup_3);
            app.ButtonHidden_3.Visible = 'off';
            app.ButtonHidden_3.Text = 'ButtonHidden';
            app.ButtonHidden_3.Value = true;
            app.Title3 = uilabel(app.Triple);
            app.Title3.Text = 'Text for three stimuli';
            app.NextButton3 = uibutton(app.Triple, 'push');
            app.NextButton3.ButtonPushedFcn = createCallbackFcn(app, @NextButton3ButtonPushed, true);
            app.NextButton3.Tag = 'NextButton';
            app.NextButton3.Text = 'Next';
            app.Quad = uitab(app.Window);
            app.PlaybackPanel_4 = uipanel(app.Quad);
            app.PlaybackPanel_4.Title = 'Playback';
            app.PlaybackPanel_4.Tag = 'Playback';
            app.A4 = uibutton(app.PlaybackPanel_4, 'push');
            app.A4.ButtonPushedFcn = createCallbackFcn(app, @A4ButtonPushed, true);
            app.B4 = uibutton(app.PlaybackPanel_4, 'push');
            app.B4.ButtonPushedFcn = createCallbackFcn(app, @B4ButtonPushed, true);
            app.C4 = uibutton(app.PlaybackPanel_4, 'push');
            app.C4.ButtonPushedFcn = createCallbackFcn(app, @C4ButtonPushed, true);
            app.D4 = uibutton(app.PlaybackPanel_4, 'push');
            app.D4.ButtonPushedFcn = createCallbackFcn(app, @D4ButtonPushed, true);
            app.AnswerButtonGroup_4 = uibuttongroup(app.Quad);
            app.AnswerButtonGroup_4.SelectionChangedFcn = createCallbackFcn(app, @AnswerButtonGroup_4SelectionChanged, true);
            app.AnswerButtonGroup_4.Title = 'Answer';
            app.AnswerButtonGroup_4.Tag = 'Answer';
            app.BG4_B1 = uitogglebutton(app.AnswerButtonGroup_4);
            app.BG4_B1.Text = 'Button';
            app.BG4_B2 = uitogglebutton(app.AnswerButtonGroup_4);
            app.BG4_B2.Text = 'Button2';
            app.ButtonHidden_4 = uitogglebutton(app.AnswerButtonGroup_4);
            app.ButtonHidden_4.Visible = 'off';
            app.ButtonHidden_4.Text = 'ButtonHidden';
            app.ButtonHidden_4.Value = true;
            app.Title4 = uilabel(app.Quad);
            app.Title4.Text = 'Text for four stimuli';
            app.NextButton4 = uibutton(app.Quad, 'push');
            app.NextButton4.ButtonPushedFcn = createCallbackFcn(app, @NextButton4ButtonPushed, true);
            app.NextButton4.Tag = 'NextButton';
            app.NextButton4.Text = 'Next';
            app.Scale = uitab(app.Window);
            app.AnswerScale5 = uipanel(app.Scale);
            app.AnswerScale5.Title = 'Answer';
            app.AnswerScale5.Tag = 'Answer';
            app.ScaleAns5GridLayout = uigridlayout(app.AnswerScale5);
            app.Slider5 = uislider(app.ScaleAns5GridLayout);
            app.Slider5.Limits = [0 40];
            app.Slider5.MajorTicks = [0 10 20 30 40];
            app.Slider5.ValueChangedFcn = createCallbackFcn(app, @Slider5ValueChanged, true);
            app.Slider5.MinorTicks = [];
            app.NumLabelScale_1 = uilabel(app.ScaleAns5GridLayout);
            app.NumLabelScale_1.Text = '';
            app.NumLabelScale_2 = uilabel(app.ScaleAns5GridLayout);
            app.NumLabelScale_2.Text = '';
            app.AnswerScale7 = uipanel(app.Scale);
            app.AnswerScale7.Title = 'Answer';
            app.AnswerScale7.Tag = 'Answer';
            app.ScaleAns7GridLayout = uigridlayout(app.AnswerScale7);
            app.Slider7 = uislider(app.ScaleAns7GridLayout);
            app.Slider7.Limits = [0 60];
            app.Slider7.MajorTicks = [0 10 20 30 40 50 60];
            app.Slider7.MajorTickLabels = {'0', '10', '20', '30', '40', '50', '60'};
            app.Slider7.ValueChangedFcn = createCallbackFcn(app, @Slider7ValueChanged, true);
            app.Slider7.MinorTicks = [];
            app.NumLabelScale_3 = uilabel(app.ScaleAns7GridLayout);
            app.NumLabelScale_3.Text = '';
            app.NumLabelScale_4 = uilabel(app.ScaleAns7GridLayout);
            app.NumLabelScale_4.Text = '';
            app.PlaybackPanel_Scale = uipanel(app.Scale);
            app.PlaybackPanel_Scale.Title = 'Playback';
            app.PlaybackPanel_Scale.Tag = 'Playback';
            app.ScalePlaybackGridLayout = uigridlayout(app.PlaybackPanel_Scale);
            app.AScale = uibutton(app.ScalePlaybackGridLayout, 'push');
            app.AScale.ButtonPushedFcn = createCallbackFcn(app, @AScaleButtonPushed, true);
            app.NextButtonScale = uibutton(app.Scale, 'push');
            app.NextButtonScale.ButtonPushedFcn = createCallbackFcn(app, @NextButtonScaleButtonPushed, true);
            app.NextButtonScale.Tag = 'NextButton';
            app.NextButtonScale.Text = 'Next';
            app.TitleScale = uilabel(app.Scale);
            app.TitleScale.Text = 'Text for scale test';
            app.Choices = uitab(app.Window);
            app.AnswerCheckboxChoice = uipanel(app.Choices);
            app.AnswerCheckboxChoice.Title = 'Answer';
            app.AnswerCheckboxChoice.Tag = 'Answer';
            app.ChoiceAnswerGridLayout = uigridlayout(app.AnswerCheckboxChoice);
            app.CheckBox_1 = uicheckbox(app.ChoiceAnswerGridLayout);
            app.CheckBox_1.Text = '';
            app.CheckBox_2 = uicheckbox(app.ChoiceAnswerGridLayout);
            app.CheckBox_2.Text = '';
            app.CheckBox_3 = uicheckbox(app.ChoiceAnswerGridLayout);
            app.CheckBox_3.Text = '';
            app.CheckBox_4 = uicheckbox(app.ChoiceAnswerGridLayout);
            app.CheckBox_4.Text = '';
            app.CheckBox_5 = uicheckbox(app.ChoiceAnswerGridLayout);
            app.CheckBox_5.Text = '';
            app.CheckBox_6 = uicheckbox(app.ChoiceAnswerGridLayout);
            app.CheckBox_6.Text = '';
            app.CheckBox_7 = uicheckbox(app.ChoiceAnswerGridLayout);
            app.CheckBox_7.Text = '';
            app.CheckBox_8 = uicheckbox(app.ChoiceAnswerGridLayout);
            app.CheckBox_8.Text = '';
            app.CheckBox_9 = uicheckbox(app.ChoiceAnswerGridLayout);
            app.CheckBox_9.Text = '';
            app.CheckBox_10 = uicheckbox(app.ChoiceAnswerGridLayout);
            app.CheckBox_10.Text = '';
            app.PlaybackPanel_Choice = uipanel(app.Choices);
            app.PlaybackPanel_Choice.Title = 'Playback';
            app.PlaybackPanel_Choice.Tag = 'Playback';
            app.ChoicePlaybackGridLayout = uigridlayout(app.PlaybackPanel_Choice);
            app.AChoice = uibutton(app.ChoicePlaybackGridLayout, 'push');
            app.AChoice.ButtonPushedFcn = createCallbackFcn(app, @AChoiceButtonPushed, true);
            app.TitleChoice = uilabel(app.Choices);
            app.TitleChoice.Text = 'Text for choice test';
            app.NextButtonChoice = uibutton(app.Choices, 'push');
            app.NextButtonChoice.ButtonPushedFcn = createCallbackFcn(app, @NextButtonChoiceButtonPushed, true);
            app.NextButtonChoice.Tag = 'NextButton';
            app.NextButtonChoice.Text = 'Next';
            app.AnswerToggleChoice = uibuttongroup(app.Choices);
            app.AnswerToggleChoice.SelectionChangedFcn = createCallbackFcn(app, @AnswerToggleChoiceSelectionChanged, true);
            app.AnswerToggleChoice.Title = 'Answer';
            app.AnswerToggleChoice.Tag = 'Answer';
            app.Button_1 = uitogglebutton(app.AnswerToggleChoice);
            app.Button_1.Text = 'Button_1';
            app.Button_2 = uitogglebutton(app.AnswerToggleChoice);
            app.Button_2.Text = 'Button_2';
            app.Button_3 = uitogglebutton(app.AnswerToggleChoice);
            app.Button_3.Text = 'Button_3';
            app.Button_4 = uitogglebutton(app.AnswerToggleChoice);
            app.Button_4.Text = 'Button_4';
            app.Button_5 = uitogglebutton(app.AnswerToggleChoice);
            app.Button_5.Text = 'Button_5';
            app.Button_6 = uitogglebutton(app.AnswerToggleChoice);
            app.Button_6.Text = 'Button_6';
            app.Button_7 = uitogglebutton(app.AnswerToggleChoice);
            app.Button_7.Text = 'Button_7';
            app.Button_8 = uitogglebutton(app.AnswerToggleChoice);
            app.Button_8.Text = 'Button_8';
            app.Button_9 = uitogglebutton(app.AnswerToggleChoice);
            app.Button_9.Text = 'Button_9';
            app.Button_10 = uitogglebutton(app.AnswerToggleChoice);
            app.Button_10.Text = 'Button_10';
            app.ButtonHidden_Choices = uitogglebutton(app.AnswerToggleChoice);
            app.ButtonHidden_Choices.Visible = 'off';
            app.ButtonHidden_Choices.Text = 'ButtonHidden';
            app.ButtonHidden_Choices.Value = true;
            % Show the figure after all components are created
            app.DiTAAToolboxv20UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = DiTAA_v2

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.DiTAAToolboxv20UIFigure)

            % Execute the startup function
            runStartupFcn(app, @startupFcn)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.DiTAAToolboxv20UIFigure)
        end
    end
end